classdef sm_car_vehcfg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimscapeVehicleTemplatesUIFigure  matlab.ui.Figure
        DropDown                        matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        OpenMainModelButton             matlab.ui.control.Button
        SelectMainModelButton           matlab.ui.control.Button
        MainModelEditField              matlab.ui.control.EditField
        MainModelEditFieldLabel         matlab.ui.control.Label
        OpenModelButton                 matlab.ui.control.Button
        ListVehicleConfigurationButton  matlab.ui.control.Button
        LoadVDatabase                   matlab.ui.control.Button
        ConfigureModelButton            matlab.ui.control.Button
        SimscapeVehicleTemplateConfigurationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PresetsTab                      matlab.ui.container.Tab
        HelpLabelPresets                matlab.ui.control.Label
        SelectTrailerConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        MbLabel_3                       matlab.ui.control.Label
        ParamTank15DOFNoLoadMbodyButton  matlab.ui.control.RadioButton
        ParamTank15DOFCylNoSloshMbodyButton  matlab.ui.control.RadioButton
        ParamTank15DOFCylSloshMbodyButton  matlab.ui.control.RadioButton
        ParamTank15DOFPendNoSloshMbodyButton  matlab.ui.control.RadioButton
        ParamTank15DOFPendSloshMbodyButton  matlab.ui.control.RadioButton
        MbLabel_2                       matlab.ui.control.Label
        Kumanzi15DOFNoLoadMbodyButton   matlab.ui.control.RadioButton
        Kumanzi15DOFCylNoSloshMbodyButton  matlab.ui.control.RadioButton
        Kumanzi15DOFCylSloshMbodyButton  matlab.ui.control.RadioButton
        Kumanzi15DOFPendNoSloshMbodyButton  matlab.ui.control.RadioButton
        Kumanzi15DOFPendSloshMbodyButton  matlab.ui.control.RadioButton
        MbLabel                         matlab.ui.control.Label
        ThwalaMbody15DOFButton          matlab.ui.control.RadioButton
        ElulaMbody15DOFButton           matlab.ui.control.RadioButton
        ElulaMbodyUnstableButton        matlab.ui.control.RadioButton
        BoxMbody15DOFButton             matlab.ui.control.RadioButton
        AxleTankerLabel                 matlab.ui.control.Label
        ParamTank15DOFCylSloshSwiftButton  matlab.ui.control.RadioButton
        ParamTank15DOFCylSloshMFevalButton  matlab.ui.control.RadioButton
        ParamTank15DOFCylNoSloshSwiftButton  matlab.ui.control.RadioButton
        ParamTank15DOFCylNoSloshMFevalButton  matlab.ui.control.RadioButton
        ParamTank15DOFNoLoadSwiftButton  matlab.ui.control.RadioButton
        ParamTank15DOFNoLoadMFevalButton  matlab.ui.control.RadioButton
        AxleBoxTrailerLabel             matlab.ui.control.Label
        PendSloshLabel                  matlab.ui.control.Label
        PendNoSloshLabel                matlab.ui.control.Label
        Kumanzi15DOFPendSloshMFevalButton  matlab.ui.control.RadioButton
        Kumanzi15DOFPendNoSloshMFevalButton  matlab.ui.control.RadioButton
        Kumanzi15DOFPendSloshSwiftButton  matlab.ui.control.RadioButton
        Kumanzi15DOFPendNoSloshSwiftButton  matlab.ui.control.RadioButton
        SwiLabel_3                      matlab.ui.control.Label
        MFeLabel_3                      matlab.ui.control.Label
        Kumanzi15DOFCylSloshSwiftButton  matlab.ui.control.RadioButton
        Kumanzi15DOFCylSloshMFevalButton  matlab.ui.control.RadioButton
        CylSloshLabel                   matlab.ui.control.Label
        BoxSwift15DOFButton             matlab.ui.control.RadioButton
        BoxMFeval15DOFButton            matlab.ui.control.RadioButton
        NoLoadLabel_2                   matlab.ui.control.Label
        ParamTank15DOFPendSloshSwiftButton  matlab.ui.control.RadioButton
        ParamTank15DOFPendSloshMFevalButton  matlab.ui.control.RadioButton
        ParamTank15DOFPendNoSloshSwiftButton  matlab.ui.control.RadioButton
        ParamTank15DOFPendNoSloshMFevalButton  matlab.ui.control.RadioButton
        Kumanzi15DOFCylNoSloshSwiftButton  matlab.ui.control.RadioButton
        Kumanzi15DOFCylNoSloshMFevalButton  matlab.ui.control.RadioButton
        CylNoSloshLabel                 matlab.ui.control.Label
        AxleLabel_2                     matlab.ui.control.Label
        AxleTankerCADLabel              matlab.ui.control.Label
        SwiLabel_2                      matlab.ui.control.Label
        MFeLabel_2                      matlab.ui.control.Label
        ElulaSwiftUnstableButton        matlab.ui.control.RadioButton
        UnstableLabel                   matlab.ui.control.Label
        ParametricLabel                 matlab.ui.control.Label
        SwiLabel                        matlab.ui.control.Label
        MFeLabel                        matlab.ui.control.Label
        Kumanzi15DOFNoLoadSwiftButton   matlab.ui.control.RadioButton
        Kumanzi15DOFNoLoadMFevalButton  matlab.ui.control.RadioButton
        NoLoadLabel                     matlab.ui.control.Label
        CADLabel                        matlab.ui.control.Label
        ElulaMFevalUnstableButton       matlab.ui.control.RadioButton
        ThwalaSwift15DOFButton          matlab.ui.control.RadioButton
        ElulaSwift15DOFButton           matlab.ui.control.RadioButton
        ThwalaMFeval15DOFButton         matlab.ui.control.RadioButton
        ElulaMFeval15DOFButton          matlab.ui.control.RadioButton
        NoTrailerButton                 matlab.ui.control.RadioButton
        SelectVehicleConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        MacPhersonButton                matlab.ui.control.RadioButton
        PushrodLabel                    matlab.ui.control.Label
        HambaTwistBeamMbodyButton       matlab.ui.control.RadioButton
        HambaTwistBeamSwiftButton       matlab.ui.control.RadioButton
        HambaTwistBeamMFevalButton      matlab.ui.control.RadioButton
        TwistBeamLabel                  matlab.ui.control.Label
        Hamba15DOFMot4xCoolMbodyButton  matlab.ui.control.RadioButton
        Hamba15DOFMot4xCoolSwiftButton  matlab.ui.control.RadioButton
        Hamba15DOFMot4xCoolMFevalButton  matlab.ui.control.RadioButton
        DOFE4xCoolLabel                 matlab.ui.control.Label
        PullrodLabel                    matlab.ui.control.Label
        DWDecoupledLabel                matlab.ui.control.Label
        SedanHambaLGLabel               matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.RadioButton
        NonlinearSpringsRearButton      matlab.ui.control.RadioButton
        NonlinearSpringsFrontButton     matlab.ui.control.RadioButton
        AntiLockBrakesButton            matlab.ui.control.RadioButton
        CVsFrontandRearButton           matlab.ui.control.RadioButton
        OnePowerShaftButton             matlab.ui.control.RadioButton
        StiffSpringDamperButton         matlab.ui.control.RadioButton
        CVCVPrismaticFrontButton        matlab.ui.control.RadioButton
        NonlinearDampersFrontButton     matlab.ui.control.RadioButton
        NonlinearDampersRearButton      matlab.ui.control.RadioButton
        SpringswithNoVariantsButton     matlab.ui.control.RadioButton
        SemiTruck15DOF6x4MbodyButton    matlab.ui.control.RadioButton
        SemiTruck15DOF6x2MbodyButton    matlab.ui.control.RadioButton
        Makhulu3AxleWishbone6x2MbodyButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x4MbodyButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x2MbodyButton  matlab.ui.control.RadioButton
        MbodyLabel_3                    matlab.ui.control.Label
        MbodyLabel_2                    matlab.ui.control.Label
        MakhuluMbody6WheelsButton       matlab.ui.control.RadioButton
        MakhuluMbody4WheelsButton       matlab.ui.control.RadioButton
        MakhuluMbody6WheelsFuelCellButton  matlab.ui.control.RadioButton
        Amandla15DOF6x2MbodyButton      matlab.ui.control.RadioButton
        Amandla15DOF6x4MbodyButton      matlab.ui.control.RadioButton
        Hamba15DOFMot2xRegenMbodyButton  matlab.ui.control.RadioButton
        Hamba15DOFFuelCellMbodyButton   matlab.ui.control.RadioButton
        Hamba15DOFMot2xCoolMbodyButton  matlab.ui.control.RadioButton
        Hamba15DOFMbodyButton           matlab.ui.control.RadioButton
        HambaEMotor3xMbodyButton        matlab.ui.control.RadioButton
        HambaEMotor2xMbodyButton        matlab.ui.control.RadioButton
        HambaLiveAxleMbodyButton        matlab.ui.control.RadioButton
        HambaDefaultMbodyTireButton     matlab.ui.control.RadioButton
        MbodyLabel                      matlab.ui.control.Label
        FSAELabel                       matlab.ui.control.Label
        FSAEAchillesPullMFevalButton    matlab.ui.control.RadioButton
        FSAEAchillesPullSwiftButton     matlab.ui.control.RadioButton
        FSAEAchillesPullMbodyButton     matlab.ui.control.RadioButton
        FSAEAchillesPushMFevalButton    matlab.ui.control.RadioButton
        FSAEAchillesPushSwiftButton     matlab.ui.control.RadioButton
        FSAEAchillesPushMbodyButton     matlab.ui.control.RadioButton
        FSAEAchillesDWDecMbodyButton    matlab.ui.control.RadioButton
        FSAEAchillesDWDecMFevalButton   matlab.ui.control.RadioButton
        FSAEAchillesDWDecSwiftButton    matlab.ui.control.RadioButton
        DOFE2xRegLabel                  matlab.ui.control.Label
        Hamba15DOFMot2xRegenMFevalButton  matlab.ui.control.RadioButton
        Hamba15DOFMot2xRegenSwiftButton  matlab.ui.control.RadioButton
        SemiTruck15DOF6x2SwiftButton    matlab.ui.control.RadioButton
        SemiTruck15DOF6x2MFevalButton   matlab.ui.control.RadioButton
        SemiTruck15DOF6x4SwiftButton    matlab.ui.control.RadioButton
        SemiTruck15DOF6x4MFevalButton   matlab.ui.control.RadioButton
        DOF6x4Label_3                   matlab.ui.control.Label
        DOF6x2Label_3                   matlab.ui.control.Label
        SwiftLabel_6                    matlab.ui.control.Label
        MFevalLabel_6                   matlab.ui.control.Label
        SemiTruck3AxleLabel             matlab.ui.control.Label
        Makhulu3AxleWishbone6x2SwiftButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x4SwiftButton  matlab.ui.control.RadioButton
        DblWishb6x2Label                matlab.ui.control.Label
        DOF6x4Label_2                   matlab.ui.control.Label
        DOF6x2Label_2                   matlab.ui.control.Label
        DOF6x4Label                     matlab.ui.control.Label
        DOF6x2Label                     matlab.ui.control.Label
        FuelCell6WhlLabel               matlab.ui.control.Label
        WheelsLabel_2                   matlab.ui.control.Label
        WheelsLabel                     matlab.ui.control.Label
        SwiftLabel                      matlab.ui.control.Label
        MFevalLabel_2                   matlab.ui.control.Label
        SwiftLabel_2                    matlab.ui.control.Label
        MFevalLabel                     matlab.ui.control.Label
        DOFFuelCellLabel                matlab.ui.control.Label
        DOFE2xCoolLabel                 matlab.ui.control.Label
        DOFLabel_2                      matlab.ui.control.Label
        EMotor3xLabel                   matlab.ui.control.Label
        EMotor2xLabel                   matlab.ui.control.Label
        LiveAxleLabel                   matlab.ui.control.Label
        DefaultLabel                    matlab.ui.control.Label
        Amandla15DOF6x4SwiftButton      matlab.ui.control.RadioButton
        Amandla15DOF6x2SwiftButton      matlab.ui.control.RadioButton
        Amandla15DOF6x4MFevalButton     matlab.ui.control.RadioButton
        Amandla15DOF6x2MFevalButton     matlab.ui.control.RadioButton
        TruckAmandla3AxleLabel          matlab.ui.control.Label
        Makhulu3AxleWishbone6x2MFevalButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x4MFevalButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x2SwiftButton  matlab.ui.control.RadioButton
        Makhulu3Axle15DOF6x2MFevalButton  matlab.ui.control.RadioButton
        Bus3AxleLabel                   matlab.ui.control.Label
        MakhuluSwift6WheelsFuelCellButton  matlab.ui.control.RadioButton
        MakhuluMFeval6WheelsFuelCellButton  matlab.ui.control.RadioButton
        RealTimeConfigFullButton        matlab.ui.control.RadioButton
        RealTimeConfigBasicButton       matlab.ui.control.RadioButton
        Hamba15DOFFuelCellMFevalButton  matlab.ui.control.RadioButton
        Hamba15DOFFuelCellSwiftButton   matlab.ui.control.RadioButton
        Hamba15DOFMot2xCoolMFevalButton  matlab.ui.control.RadioButton
        Hamba15DOFMot2xCoolSwiftButton  matlab.ui.control.RadioButton
        Hamba15DOFSwiftButton           matlab.ui.control.RadioButton
        Hamba15DOFMFevalButton          matlab.ui.control.RadioButton
        HambaEMotor3xSwiftButton        matlab.ui.control.RadioButton
        HambaEMotor3xMFevalButton       matlab.ui.control.RadioButton
        HambaEMotor2xSwiftButton        matlab.ui.control.RadioButton
        HambaEMotor2xMFevalButton       matlab.ui.control.RadioButton
        HambaLiveAxleSwiftButton        matlab.ui.control.RadioButton
        HambaLiveAxleMFevalButton       matlab.ui.control.RadioButton
        MakhuluSwift4WheelsButton       matlab.ui.control.RadioButton
        MakhuluMFeval4WheelsButton      matlab.ui.control.RadioButton
        MakhuluMFeval6WheelsButton      matlab.ui.control.RadioButton
        MakhuluSwift6WheelsButton       matlab.ui.control.RadioButton
        BusMakhuluLabel                 matlab.ui.control.Label
        SedanHambaLabel                 matlab.ui.control.Label
        HambaDefaultSwiftTireButton     matlab.ui.control.RadioButton
        HambaDefaultMFevalTireButton    matlab.ui.control.RadioButton
        ConfigureTab                    matlab.ui.container.Tab
        ARBA1BushingsCheckBox           matlab.ui.control.CheckBox
        ARBA2BushingsCheckBox           matlab.ui.control.CheckBox
        SuspA3LACheckBox                matlab.ui.control.CheckBox
        SuspA3UACheckBox                matlab.ui.control.CheckBox
        SuspA2LACheckBox                matlab.ui.control.CheckBox
        SuspA2UACheckBox                matlab.ui.control.CheckBox
        SuspA1LACheckBox                matlab.ui.control.CheckBox
        SuspA1UACheckBox                matlab.ui.control.CheckBox
        InboardBushingsLabel            matlab.ui.control.Label
        AntiRollBarA3DropDown           matlab.ui.control.DropDown
        AntiRollA3Label                 matlab.ui.control.Label
        OverrideApplyButtonAntiRollA3   matlab.ui.control.Button
        SteerA3DropDown                 matlab.ui.control.DropDown
        SteerA3Label                    matlab.ui.control.Label
        OverrideApplyButtonSteerA3      matlab.ui.control.Button
        SuspA3Label                     matlab.ui.control.Label
        SuspensionA3DropDown            matlab.ui.control.DropDown
        OverrideApplyButtonSuspensionA3  matlab.ui.control.Button
        TiresTabGroup                   matlab.ui.container.TabGroup
        TiresAxle1Tab                   matlab.ui.container.Tab
        TiresA1ModelDropDown            matlab.ui.control.DropDown
        TireModelA1DropDownLabel        matlab.ui.control.Label
        TiresA1BodyDropDown             matlab.ui.control.DropDown
        BodyLabel                       matlab.ui.control.Label
        TiresA1DataDropDown             matlab.ui.control.DropDown
        SizeLabel                       matlab.ui.control.Label
        OverrideApplyButtonTiresA1      matlab.ui.control.Button
        TiresA1ContactDropDown          matlab.ui.control.DropDown
        ContactLabel                    matlab.ui.control.Label
        TiresA1SlipDropDown             matlab.ui.control.DropDown
        SlipLabel                       matlab.ui.control.Label
        TiresA1DynamicsDropDown         matlab.ui.control.DropDown
        DynamicsLabel                   matlab.ui.control.Label
        TiresAxle2Tab                   matlab.ui.container.Tab
        TiresA2ModelDropDown            matlab.ui.control.DropDown
        TireModelA1DropDownLabel_2      matlab.ui.control.Label
        TiresA2BodyDropDown             matlab.ui.control.DropDown
        BodyLabel_2                     matlab.ui.control.Label
        TiresA2DataDropDown             matlab.ui.control.DropDown
        SizeLabel_2                     matlab.ui.control.Label
        OverrideApplyButtonTiresA2      matlab.ui.control.Button
        TiresA2ContactDropDown          matlab.ui.control.DropDown
        ContactLabel_3                  matlab.ui.control.Label
        TiresA2SlipDropDown             matlab.ui.control.DropDown
        SlipLabel_3                     matlab.ui.control.Label
        TiresA2DynamicsDropDown         matlab.ui.control.DropDown
        DynamicsLabel_2                 matlab.ui.control.Label
        TiresAxle3Tab                   matlab.ui.container.Tab
        TiresA3ModelDropDown            matlab.ui.control.DropDown
        TireModelA1DropDownLabel_3      matlab.ui.control.Label
        TiresA3BodyDropDown             matlab.ui.control.DropDown
        BodyLabel_3                     matlab.ui.control.Label
        TiresA3DataDropDown             matlab.ui.control.DropDown
        SizeLabel_3                     matlab.ui.control.Label
        OverrideApplyButtonTiresA3      matlab.ui.control.Button
        TiresA3ContactDropDown          matlab.ui.control.DropDown
        ContactLabel_4                  matlab.ui.control.Label
        TiresA3SlipDropDown             matlab.ui.control.DropDown
        SlipLabel_4                     matlab.ui.control.Label
        TiresA3DynamicsDropDown         matlab.ui.control.DropDown
        DynamicsLabel_3                 matlab.ui.control.Label
        DamperAxle3DropDown             matlab.ui.control.DropDown
        DamperAxle2DropDown             matlab.ui.control.DropDown
        SpringAxle3DropDown             matlab.ui.control.DropDown
        SpringAxle2DropDown             matlab.ui.control.DropDown
        Axle3Label                      matlab.ui.control.Label
        Axle2Label                      matlab.ui.control.Label
        Axle1Label                      matlab.ui.control.Label
        PowerActiveCoolingCheckBox      matlab.ui.control.CheckBox
        HelpLabelConfigure              matlab.ui.control.Label
        OverrideApplyButtonPeople       matlab.ui.control.Button
        PeopleDriverCheckBox            matlab.ui.control.CheckBox
        PeopleRRCheckBox                matlab.ui.control.CheckBox
        PeopleRLCheckBox                matlab.ui.control.CheckBox
        PeopleFRCheckBox                matlab.ui.control.CheckBox
        PeopleFLCheckBox                matlab.ui.control.CheckBox
        PeopleLabel                     matlab.ui.control.Label
        OverrideApplyButtonBody         matlab.ui.control.Button
        OverrideApplyButtonAero         matlab.ui.control.Button
        BodyOpaqueCheckBox              matlab.ui.control.CheckBox
        OverrideApplyButtonAntiRollA2   matlab.ui.control.Button
        AntiRollBarA2DropDown           matlab.ui.control.DropDown
        AntiRollA2Label                 matlab.ui.control.Label
        OverrideApplyButtonAntiRollA1   matlab.ui.control.Button
        AntiRollBarA1DropDown           matlab.ui.control.DropDown
        AntiRollA1Label                 matlab.ui.control.Label
        OverrideApplyButtonSteerA2      matlab.ui.control.Button
        OverrideApplyButtonBrakes       matlab.ui.control.Button
        OverrideApplyButtonSuspensionA2  matlab.ui.control.Button
        OverrideApplyButtonSuspensionA1  matlab.ui.control.Button
        OverrideApplyButtonDriveline    matlab.ui.control.Button
        OverrideApplyButtonPower        matlab.ui.control.Button
        OverrideApplyButtonDampers      matlab.ui.control.Button
        OverrideApplyButtonBodyGeometry  matlab.ui.control.Button
        OverrideApplyButtonSteerA1      matlab.ui.control.Button
        OverrideApplyButtonSprings      matlab.ui.control.Button
        OverrideinWorkspaceLabel        matlab.ui.control.Label
        GeometryDropDown                matlab.ui.control.DropDown
        GeometryDropDownLabel           matlab.ui.control.Label
        ABSControlCheckBox              matlab.ui.control.CheckBox
        DefineCustomConfigurationLabel  matlab.ui.control.Label
        DrivelineDropDown               matlab.ui.control.DropDown
        DrivelineDropDownLabel          matlab.ui.control.Label
        SteerA2DropDown                 matlab.ui.control.DropDown
        SteerA2DropDownLabel            matlab.ui.control.Label
        SteerA1DropDown                 matlab.ui.control.DropDown
        SteerA1DropDownLabel            matlab.ui.control.Label
        SuspensionA2DropDown            matlab.ui.control.DropDown
        SuspA2Label                     matlab.ui.control.Label
        SuspensionA1DropDown            matlab.ui.control.DropDown
        SuspA1Label                     matlab.ui.control.Label
        DamperAxle1DropDown             matlab.ui.control.DropDown
        DampersDropDownLabel            matlab.ui.control.Label
        SpringAxle1DropDown             matlab.ui.control.DropDown
        SpringsDropDownLabel            matlab.ui.control.Label
        BrakesDropDown                  matlab.ui.control.DropDown
        BrakesDropDownLabel             matlab.ui.control.Label
        PowerDropDown                   matlab.ui.control.DropDown
        PowerDropDownLabel              matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        BodyDropDownLabel               matlab.ui.control.Label
        AeroDropDown                    matlab.ui.control.DropDown
        AeroDropDownLabel               matlab.ui.control.Label
        MatrixTab                       matlab.ui.container.Tab
        RowabbreviationindicatestireanddrivelineconfigurationLabel  matlab.ui.control.Label
        AbbreviationLabels              matlab.ui.control.Label
        SelectaradiobuttonbelowforaspecificdrivelineandtirepresetLabel  matlab.ui.control.Label
        HelpLabelMatrix                 matlab.ui.control.Label
        DOFLabel                        matlab.ui.control.Label
        CS52LAFLabel                    matlab.ui.control.Label
        S25LARLabel                     matlab.ui.control.Label
        S25LAFLabel                     matlab.ui.control.Label
        S2LARLabel                      matlab.ui.control.Label
        S2LAFLabel                      matlab.ui.control.Label
        DWaLabel                        matlab.ui.control.Label
        DWLabel                         matlab.ui.control.Label
        SLFLabel                        matlab.ui.control.Label
        SL3Label                        matlab.ui.control.Label
        SLDLabel                        matlab.ui.control.Label
        SL1Label                        matlab.ui.control.Label
        SSFLabel                        matlab.ui.control.Label
        SS3Label                        matlab.ui.control.Label
        SSDLabel                        matlab.ui.control.Label
        SS1Label                        matlab.ui.control.Label
        MLFLabel                        matlab.ui.control.Label
        ML3Label                        matlab.ui.control.Label
        MLDLabel                        matlab.ui.control.Label
        ML1Label                        matlab.ui.control.Label
        MSFLabel                        matlab.ui.control.Label
        MS3Label                        matlab.ui.control.Label
        MSDLabel                        matlab.ui.control.Label
        MS1Label                        matlab.ui.control.Label
        ConfigurationsButtonGroup       matlab.ui.container.ButtonGroup
        Button_128                      matlab.ui.control.RadioButton
        Button_127                      matlab.ui.control.RadioButton
        Button_126                      matlab.ui.control.RadioButton
        Button_125                      matlab.ui.control.RadioButton
        Button_124                      matlab.ui.control.RadioButton
        Button_123                      matlab.ui.control.RadioButton
        Button_122                      matlab.ui.control.RadioButton
        Button_121                      matlab.ui.control.RadioButton
        Button_120                      matlab.ui.control.RadioButton
        Button_119                      matlab.ui.control.RadioButton
        Button_118                      matlab.ui.control.RadioButton
        Button_117                      matlab.ui.control.RadioButton
        Button_116                      matlab.ui.control.RadioButton
        Button_115                      matlab.ui.control.RadioButton
        Button_114                      matlab.ui.control.RadioButton
        Button_113                      matlab.ui.control.RadioButton
        Button_112                      matlab.ui.control.RadioButton
        Button_111                      matlab.ui.control.RadioButton
        Button_110                      matlab.ui.control.RadioButton
        Button_109                      matlab.ui.control.RadioButton
        Button_108                      matlab.ui.control.RadioButton
        Button_107                      matlab.ui.control.RadioButton
        Button_106                      matlab.ui.control.RadioButton
        Button_105                      matlab.ui.control.RadioButton
        Button_104                      matlab.ui.control.RadioButton
        Button_103                      matlab.ui.control.RadioButton
        Button_102                      matlab.ui.control.RadioButton
        Button_101                      matlab.ui.control.RadioButton
        Button_100                      matlab.ui.control.RadioButton
        Button_99                       matlab.ui.control.RadioButton
        Button_98                       matlab.ui.control.RadioButton
        Button_97                       matlab.ui.control.RadioButton
        Button_96                       matlab.ui.control.RadioButton
        Button_95                       matlab.ui.control.RadioButton
        Button_94                       matlab.ui.control.RadioButton
        Button_93                       matlab.ui.control.RadioButton
        Button_92                       matlab.ui.control.RadioButton
        Button_91                       matlab.ui.control.RadioButton
        Button_90                       matlab.ui.control.RadioButton
        Button_89                       matlab.ui.control.RadioButton
        Button_88                       matlab.ui.control.RadioButton
        Button_87                       matlab.ui.control.RadioButton
        Button_86                       matlab.ui.control.RadioButton
        Button_85                       matlab.ui.control.RadioButton
        Button_84                       matlab.ui.control.RadioButton
        Button_83                       matlab.ui.control.RadioButton
        Button_82                       matlab.ui.control.RadioButton
        Button_81                       matlab.ui.control.RadioButton
        Button_80                       matlab.ui.control.RadioButton
        Button_79                       matlab.ui.control.RadioButton
        Button_78                       matlab.ui.control.RadioButton
        Button_77                       matlab.ui.control.RadioButton
        Button_76                       matlab.ui.control.RadioButton
        Button_75                       matlab.ui.control.RadioButton
        Button_74                       matlab.ui.control.RadioButton
        Button_73                       matlab.ui.control.RadioButton
        Button_72                       matlab.ui.control.RadioButton
        Button_71                       matlab.ui.control.RadioButton
        Button_70                       matlab.ui.control.RadioButton
        Button_69                       matlab.ui.control.RadioButton
        Button_68                       matlab.ui.control.RadioButton
        Button_67                       matlab.ui.control.RadioButton
        Button_66                       matlab.ui.control.RadioButton
        Button_65                       matlab.ui.control.RadioButton
        Button_64                       matlab.ui.control.RadioButton
        Button_63                       matlab.ui.control.RadioButton
        Button_62                       matlab.ui.control.RadioButton
        Button_61                       matlab.ui.control.RadioButton
        Button_60                       matlab.ui.control.RadioButton
        Button_59                       matlab.ui.control.RadioButton
        Button_58                       matlab.ui.control.RadioButton
        Button_57                       matlab.ui.control.RadioButton
        Button_56                       matlab.ui.control.RadioButton
        Button_55                       matlab.ui.control.RadioButton
        Button_54                       matlab.ui.control.RadioButton
        Button_53                       matlab.ui.control.RadioButton
        Button_52                       matlab.ui.control.RadioButton
        Button_51                       matlab.ui.control.RadioButton
        Button_50                       matlab.ui.control.RadioButton
        Button_49                       matlab.ui.control.RadioButton
        Button_48                       matlab.ui.control.RadioButton
        Button_47                       matlab.ui.control.RadioButton
        Button_46                       matlab.ui.control.RadioButton
        Button_45                       matlab.ui.control.RadioButton
        Button_44                       matlab.ui.control.RadioButton
        Button_43                       matlab.ui.control.RadioButton
        Button_42                       matlab.ui.control.RadioButton
        Button_41                       matlab.ui.control.RadioButton
        Button_40                       matlab.ui.control.RadioButton
        Button_39                       matlab.ui.control.RadioButton
        Button_38                       matlab.ui.control.RadioButton
        Button_37                       matlab.ui.control.RadioButton
        Button_36                       matlab.ui.control.RadioButton
        Button_35                       matlab.ui.control.RadioButton
        Button_34                       matlab.ui.control.RadioButton
        Button_33                       matlab.ui.control.RadioButton
        Button_32                       matlab.ui.control.RadioButton
        Button_31                       matlab.ui.control.RadioButton
        Button_30                       matlab.ui.control.RadioButton
        Button_29                       matlab.ui.control.RadioButton
        Button_28                       matlab.ui.control.RadioButton
        Button_27                       matlab.ui.control.RadioButton
        Button_26                       matlab.ui.control.RadioButton
        Button_25                       matlab.ui.control.RadioButton
        Button_24                       matlab.ui.control.RadioButton
        Button_23                       matlab.ui.control.RadioButton
        Button_22                       matlab.ui.control.RadioButton
        Button_21                       matlab.ui.control.RadioButton
        Button_20                       matlab.ui.control.RadioButton
        Button_19                       matlab.ui.control.RadioButton
        Button_18                       matlab.ui.control.RadioButton
        Button_17                       matlab.ui.control.RadioButton
        Button_16                       matlab.ui.control.RadioButton
        Button_15                       matlab.ui.control.RadioButton
        Button_14                       matlab.ui.control.RadioButton
        Button_13                       matlab.ui.control.RadioButton
        Button_12                       matlab.ui.control.RadioButton
        Button_11                       matlab.ui.control.RadioButton
        Button_10                       matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        EventTab                        matlab.ui.container.Tab
        UnrealEngineAnimationCheckBox   matlab.ui.control.CheckBox
        DefineNewTrajectoryButton       matlab.ui.control.Button
        SelectManeuverLabel             matlab.ui.control.Label
        WindCodeButton                  matlab.ui.control.Button
        OverrideWindButton              matlab.ui.control.Button
        WindTrailerCheckBox             matlab.ui.control.CheckBox
        WindCarCheckBox                 matlab.ui.control.CheckBox
        WindLabel                       matlab.ui.control.Label
        SolverCodeButton                matlab.ui.control.Button
        SurfaceCodeButton               matlab.ui.control.Button
        PlotManeuverCodeButton          matlab.ui.control.Button
        ManeuverCodeButton              matlab.ui.control.Button
        IndividualSettingsLabel         matlab.ui.control.Label
        ShowPaceCarInteractiveCheckBox  matlab.ui.control.CheckBox
        DriverDropDown                  matlab.ui.control.DropDown
        DriverDropDownLabel             matlab.ui.control.Label
        PointLabelIntervalEditField     matlab.ui.control.NumericEditField
        PointLabelIntervalEditFieldLabel  matlab.ui.control.Label
        PlotManeuverButton              matlab.ui.control.Button
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        SurfaceDropDown                 matlab.ui.control.DropDown
        SurfaceDropDownLabel            matlab.ui.control.Label
        ManeuverDropDown                matlab.ui.control.DropDown
        ManeuverLabel                   matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        TireForcesTorquesButton         matlab.ui.control.Button
        TiresForcesTorquesCodeButton    matlab.ui.control.Button
        PowertrainCurrentCodeButton     matlab.ui.control.Button
        PowertrainCurrentButton         matlab.ui.control.Button
        SimulationDataInspectorButton   matlab.ui.control.Button
        SimscapeResultsExplorerButton   matlab.ui.control.Button
        BodyMeasurementsCodeButton      matlab.ui.control.Button
        BodyMeasurementsButton          matlab.ui.control.Button
        ResultsManeuverCodeButton       matlab.ui.control.Button
        WheelSpeedsCodeButton           matlab.ui.control.Button
        PositionandSpeedCodeButton      matlab.ui.control.Button
        AnimationLabel                  matlab.ui.control.Label
        ReplayinUnrealButton            matlab.ui.control.Button
        ManeuverButton                  matlab.ui.control.Button
        WheelSpeedsButton               matlab.ui.control.Button
        PlotsLabel                      matlab.ui.control.Label
        PositionandSpeedButton          matlab.ui.control.Button
        MainTab                         matlab.ui.container.Tab
        ConsistentHPsFileButton         matlab.ui.control.Button
        ConsistentHPsButton             matlab.ui.control.Button
        VehicleDataCheckLabel           matlab.ui.control.Label
        FuelCellVehicle1DButton         matlab.ui.control.Button
        TestModelsLabel                 matlab.ui.control.Label
        RegenerateAllDatabasesFileButton  matlab.ui.control.Button
        RegenerateAllDatabasesButton    matlab.ui.control.Button
        HelpLabelMain                   matlab.ui.control.Label
        CameraDatabaseFileButton        matlab.ui.control.Button
        SceneDatabaseFileButton         matlab.ui.control.Button
        DriverDatabaseFileButton        matlab.ui.control.Button
        InitDatabaseFileButton          matlab.ui.control.Button
        ManeuverDatabaseFileButton      matlab.ui.control.Button
        VehiclePresetsFileButton        matlab.ui.control.Button
        CameraDatabaseLabel             matlab.ui.control.Label
        SceneDatabaseLabel              matlab.ui.control.Label
        DriverDatabaseLabel             matlab.ui.control.Label
        InitDatabaseLabel               matlab.ui.control.Label
        ManeuverDatabaseLabel           matlab.ui.control.Label
        VehiclePresetsLabel             matlab.ui.control.Label
        VehicleDatabaseLabel            matlab.ui.control.Label
        RegenerateCameraDatabaseButton  matlab.ui.control.Button
        RegenerateSceneDatabaseButton   matlab.ui.control.Button
        RegenerateDriverDatabaseButton  matlab.ui.control.Button
        RegenerateInitDatabaseButton    matlab.ui.control.Button
        RegenerateDatabasesinMATLABWorkspaceLabel  matlab.ui.control.Label
        RegenerateManueverDatabaseButton  matlab.ui.control.Button
        RegenerateVehicleDatabaseButton  matlab.ui.control.Button
        RegenerateVehiclePresetsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        highlightColor = [180 230 255]/255; % color to highlight button
        defaultColor = [0.96 0.96 0.96]; % default color for button
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if(verLessThan('MATLAB','9.11'))
                app.MbodyLabel.Visible = 'off';
                app.HambaDefaultMbodyTireButton.Visible = 'off';
                app.HambaLiveAxleMbodyButton.Visible = 'off';
                app.HambaEMotor2xMbodyButton.Visible = 'off';
                app.HambaEMotor3xMbodyButton.Visible = 'off';
                app.Hamba15DOFMbodyButton.Visible = 'off';
                app.Hamba15DOFMot2xCoolMbodyButton.Visible = 'off';
                app.Hamba15DOFMot3xCoolMbodyButton.Visible = 'off';
                app.Hamba15DOFMot4xCoolMbodyButton.Visible = 'off';
                app.Hamba15DOFFuelCellMbodyButton.Visible = 'off';
                app.Hamba15DOFMot2xRegenMbodyButton.Visible = 'off';
                app.FSAEAchillesPullMbodyButton.Visible = 'off';
                app.FSAEAchillesDWDecMbodyButton.Visible = 'off';
                app.Amandla15DOF6x4MbodyButton.Visible = 'off';
                app.Amandla15DOF6x2MbodyButton.Visible = 'off';
                app.MakhuluMbody6WheelsFuelCellButton.Visible = 'off';
                app.MakhuluMbody4WheelsButton.Visible = 'off';
                app.MakhuluMbody6WheelsButton.Visible = 'off';
                app.MbodyLabel_2.Visible = 'off';
                app.MbodyLabel_3.Visible = 'off';
                app.Makhulu3Axle15DOF6x2MbodyButton.Visible = 'off';
                app.Makhulu3Axle15DOF6x4MbodyButton.Visible = 'off';
                app.Makhulu3AxleWishbone6x2MbodyButton.Visible = 'off';
                app.SemiTruck15DOF6x2MbodyButton.Visible = 'off';
                app.SemiTruck15DOF6x4MbodyButton.Visible = 'off';
                app.BoxMbody15DOFButton.Visible = 'off';
                app.ElulaMbodyUnstableButton.Visible = 'off';
                app.ElulaMbody15DOFButton.Visible = 'off';
                app.ThwalaMbody15DOFButton.Visible = 'off';
                app.MbLabel.Visible = 'off';
                app.Kumanzi15DOFPendSloshMbodyButton.Visible = 'off';
                app.Kumanzi15DOFPendNoSloshMbodyButton.Visible = 'off';
                app.Kumanzi15DOFCylSloshMbodyButton.Visible = 'off';
                app.Kumanzi15DOFCylNoSloshMbodyButton.Visible = 'off';
                app.Kumanzi15DOFNoLoadMbodyButton.Visible = 'off';
                app.MbLabel_2.Visible = 'off';
                app.ParamTank15DOFPendSloshMbodyButton.Visible = 'off';
                app.ParamTank15DOFPendNoSloshMbodyButton.Visible = 'off';
                app.ParamTank15DOFCylSloshMbodyButton.Visible = 'off';
                app.ParamTank15DOFCylNoSloshMbodyButton.Visible = 'off';
                app.ParamTank15DOFNoLoadMbodyButton.Visible = 'off';
                app.MbLabel_3.Visible = 'off';
            end
        end

        % Selection changed function: SelectVehicleConfigurationButtonGroup
        function CustomSelectVehicleConfigurationButtonGroupSelecti(app, event)
            %selectedButton = app.SelectVehicleConfigurationButtonGroup.SelectedObject;
            switch (event.NewValue)
                case app.HambaDefaultMFevalTireButton,         preset_ind = '139';  
                case app.HambaDefaultSwiftTireButton,          preset_ind = '140';  
                case app.HambaDefaultMbodyTireButton,          preset_ind = '189';  
                case app.HambaLiveAxleMFevalButton,            preset_ind = '158';  
                case app.HambaLiveAxleSwiftButton,             preset_ind = '159';  
                case app.HambaLiveAxleMbodyButton,             preset_ind = '190';  
                case app.HambaTwistBeamMFevalButton,           preset_ind = '225';  
                case app.HambaTwistBeamSwiftButton,            preset_ind = '226';  
                case app.HambaTwistBeamMbodyButton,            preset_ind = '227';  
                case app.HambaEMotor2xMFevalButton,            preset_ind = '160';  
                case app.HambaEMotor2xSwiftButton,             preset_ind = '161';  
                case app.HambaEMotor2xMbodyButton,             preset_ind = '191';  
                case app.HambaEMotor3xMFevalButton,            preset_ind = '162';  
                case app.HambaEMotor3xSwiftButton,             preset_ind = '163';  
                case app.HambaEMotor3xMbodyButton,             preset_ind = '192';  
                case app.Hamba15DOFMFevalButton,               preset_ind = '164';  
                case app.Hamba15DOFSwiftButton,                preset_ind = '165';  
                case app.Hamba15DOFMbodyButton,                preset_ind = '193';  
                case app.Hamba15DOFMot2xCoolMFevalButton,      preset_ind = '166';  
                case app.Hamba15DOFMot2xCoolSwiftButton,       preset_ind = '167';  
                case app.Hamba15DOFMot2xCoolMbodyButton,       preset_ind = '194';  
                case app.Hamba15DOFMot4xCoolMFevalButton,      preset_ind = '210';  
                case app.Hamba15DOFMot4xCoolSwiftButton,       preset_ind = '211';  
                case app.Hamba15DOFMot4xCoolMbodyButton,       preset_ind = '212';  
                case app.Hamba15DOFFuelCellMFevalButton,       preset_ind = '174';  
                case app.Hamba15DOFFuelCellSwiftButton,        preset_ind = '173';  
                case app.Hamba15DOFFuelCellMbodyButton,        preset_ind = '196';  
                case app.Hamba15DOFMot2xRegenMFevalButton,     preset_ind = '179';  
                case app.Hamba15DOFMot2xRegenSwiftButton,      preset_ind = '180';  
                case app.Hamba15DOFMot2xRegenMbodyButton,      preset_ind = '197';  
                case app.RealTimeConfigBasicButton,            preset_ind = '175';
                case app.RealTimeConfigFullButton,             preset_ind = '176';
                case app.FSAEAchillesPullMFevalButton,         preset_ind = '183';  
                case app.FSAEAchillesPullSwiftButton,          preset_ind = '184';  
                case app.FSAEAchillesPullMbodyButton,          preset_ind = '198';  
                case app.FSAEAchillesPushMFevalButton,         preset_ind = '221';  
                case app.FSAEAchillesPushSwiftButton,          preset_ind = '222';  
                case app.FSAEAchillesPushMbodyButton,          preset_ind = '223';  
                case app.FSAEAchillesDWDecMFevalButton,        preset_ind = '204';  
                case app.FSAEAchillesDWDecSwiftButton,         preset_ind = '205';  
                case app.FSAEAchillesDWDecMbodyButton,         preset_ind = '206';  
                case app.MakhuluMFeval6WheelsButton,           preset_ind = '143';
                case app.MakhuluSwift6WheelsButton,            preset_ind = '145';
                case app.MakhuluMbody6WheelsButton,            preset_ind = '199';
                case app.MakhuluMFeval4WheelsButton,           preset_ind = '144';
                case app.MakhuluSwift4WheelsButton,            preset_ind = '146';
                case app.MakhuluMbody4WheelsButton,            preset_ind = '200';
                case app.MakhuluMFeval6WheelsFuelCellButton,   preset_ind = '178'; 
                case app.MakhuluSwift6WheelsFuelCellButton,    preset_ind = '177'; 
                case app.MakhuluMbody6WheelsFuelCellButton,    preset_ind = '201'; 
                case app.Makhulu3Axle15DOF6x2MFevalButton,     preset_ind = 'Axle3_000';
                case app.Makhulu3Axle15DOF6x4MFevalButton,     preset_ind = 'Axle3_001';
                case app.Makhulu3Axle15DOF6x2SwiftButton,      preset_ind = 'Axle3_002';
                case app.Makhulu3Axle15DOF6x4SwiftButton,      preset_ind = 'Axle3_003';
                case app.Makhulu3Axle15DOF6x2MbodyButton,      preset_ind = 'Axle3_016';
                case app.Makhulu3Axle15DOF6x4MbodyButton,      preset_ind = 'Axle3_017';
                case app.Makhulu3AxleWishbone6x2MFevalButton,  preset_ind = 'Axle3_004';
                case app.Makhulu3AxleWishbone6x2SwiftButton,   preset_ind = 'Axle3_006';
                case app.Makhulu3AxleWishbone6x2MbodyButton,   preset_ind = 'Axle3_018';
                case app.Amandla15DOF6x2MFevalButton,        preset_ind = 'Axle3_008';
                case app.Amandla15DOF6x4MFevalButton,        preset_ind = 'Axle3_009';
                case app.Amandla15DOF6x2SwiftButton,         preset_ind = 'Axle3_010';
                case app.Amandla15DOF6x4SwiftButton,         preset_ind = 'Axle3_011';
                case app.Amandla15DOF6x2MbodyButton,         preset_ind = 'Axle3_019';
                case app.Amandla15DOF6x4MbodyButton,         preset_ind = 'Axle3_020';
                case app.SemiTruck15DOF6x2MFevalButton,      preset_ind = 'Axle3_012';
                case app.SemiTruck15DOF6x2SwiftButton,       preset_ind = 'Axle3_013';
                case app.SemiTruck15DOF6x2MbodyButton,       preset_ind = 'Axle3_021';
                case app.SemiTruck15DOF6x4MFevalButton,      preset_ind = 'Axle3_014';
                case app.SemiTruck15DOF6x4SwiftButton,       preset_ind = 'Axle3_015';
                case app.SemiTruck15DOF6x4MbodyButton,       preset_ind = 'Axle3_022';
                case app.DefaultButton,                         preset_ind = '216';
                case app.OnePowerShaftButton,                   preset_ind = '128';
                case app.CVsFrontandRearButton,                 preset_ind = '129';
                case app.AntiLockBrakesButton,                  preset_ind = '130';
                case app.NonlinearSpringsFrontButton,           preset_ind = '131';
                case app.NonlinearSpringsRearButton,            preset_ind = '132';
                case app.SpringswithNoVariantsButton,           preset_ind = '133';
                case app.NonlinearDampersRearButton,            preset_ind = '134';
                case app.NonlinearDampersFrontButton,           preset_ind = '135';
                case app.CVCVPrismaticFrontButton,              preset_ind = '136';
                case app.StiffSpringDamperButton,               preset_ind = '137';
                case app.MacPhersonButton,                      preset_ind = '218';
            end
            model_name = app.MainModelEditField.Value;
            str = ['sm_car_load_vehicle_data(''' model_name ''',''' preset_ind ''');'];
            disp(str)
            evalin('base',str);
            app.ConfigureModelButton.BackgroundColor = app.defaultColor;
        end

        % Callback function
        function CreateDataStructureButtonPushed(app, event)
            OverrideApplyButtonAeroPushed(app, event);
            OverrideApplyButtonBodyPushed(app, event);
            OverrideApplyButtonBodyGeometryPushed(app, event);
            OverrideApplyButtonSuspensionA1Pushed(app, event);
            OverrideApplyButtonSteerA1Pushed(app, event);
            OverrideApplyButtonAntiRollA1Pushed(app, event);
            OverrideApplyButtonSuspensionA2Pushed(app, event);
            OverrideApplyButtonSteerA2Pushed(app, event);
            OverrideApplyButtonAntiRollA2Pushed(app, event);
            OverrideApplyButtonSpringsPushed(app, event);
            OverrideApplyButtonDampersPushed(app, event);
            OverrideApplyButtonTiresA1Pushed(app, event);
            OverrideApplyButtonRearTiresPushed(app, event);
            OverrideApplyButtonPowerPushed(app, event);
            OverrideApplyButtonDrivelinePushed(app, event);
            OverrideApplyButtonBrakesPushed(app, event);
            BodyOpaqueCheckBoxValueChanged(app, event);
            ABSControlCheckBoxValueChanged(app, event);
        end

        % Selection changed function: ConfigurationsButtonGroup
        function MatrixConfigSelectionChanged2(app, event)
            selectedButton = app.ConfigurationsButtonGroup.SelectedObject;
            model_name = app.MainModelEditField.Value;
            %if(length(selectedButton.Text)<=4)
                str = ['sm_car_load_vehicle_data(''' model_name ''',''' selectedButton.Text ''');'];
            %else
            %    switch(event.NewValue)
            %        case app.DefaultButton,                         preset_ind = '002';
            %    end
            %    str = ['sm_car_load_vehicle_data(''' model_name ''',''' preset_ind ''');'];
            %end
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: ConfigureModelButton
        function ConfigureModelButtonPushed(app, event)
            model_name = app.MainModelEditField.Value;            
            str = ['sm_car_config_vehicle(''' model_name ''',true'');'];
            disp(str);
            eval(str);
            app.ConfigureModelButton.BackgroundColor = app.defaultColor;
        end

        % Value changed function: ManeuverDropDown
        function ManeuverDropDownValueChanged(app, event)
            value = app.ManeuverDropDown.Value;
            model_name = app.MainModelEditField.Value;            
            str = ['sm_car_config_maneuver(''' model_name ''',''' value ''');'];
            disp(str);
            evalin('base',str);
            if(strcmp(value,'MCity'))
                app.DefineNewTrajectoryButton.Enable = 'on';
            else
                app.DefineNewTrajectoryButton.Enable = 'off';
            end
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
            value = app.SurfaceDropDown.Value;
            model_name = app.MainModelEditField.Value;
            str = ['sm_car_config_road(''' model_name ''',''' value ''');'];
            disp(str);
            evalin('base',str);
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;
            model_name = app.MainModelEditField.Value;
            str = ['sm_car_config_solver('''  model_name ''',''' value ''');'];
            disp(str);
            evalin('base',str);
        end

        % Value changed function: ABSControlCheckBox
        function ABSControlCheckBoxValueChanged(app, event)
            value = app.ABSControlCheckBox.Value;
            model_name = app.MainModelEditField.Value;            
            str = ['sm_car_config_control_brake(''' model_name ''',' num2str(value) ');'];
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotManeuverButton
        function PlotManeuverButtonPushed(app, event)
            %ManeuverValue = app.ManeuverDropDown.Value;
            PointLabelIntervalValue = app.PointLabelIntervalEditField.Value;
            sm_car_plot_maneuver(evalin('base','Maneuver'),PointLabelIntervalValue);
        end

        % Value changed function: DriverDropDown
        function DriverDropDownValueChanged(app, event)
            value = app.DriverDropDown.Value;
            model_name = app.MainModelEditField.Value;
            str = ['set_param(''' model_name '/Driver'',''popup_driver_type'',''' value ''');'];
            disp(str)
            evalin('base',str);
        end

        % Value changed function: ShowPaceCarInteractiveCheckBox
        function ShowPaceCarInteractiveCheckBoxValueChanged(app, event)
            value = app.ShowPaceCarInteractiveCheckBox.Value;
            model_name = app.MainModelEditField.Value;
            if(value)
                parval = 'show';
            else
                parval = 'none';
            end
            str = ['set_param(''' model_name '/Driver/Interactive'',''popup_pace_car'',''' parval ''')'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: PositionandSpeedButton
        function PositionandSpeedButtonPushed(app, event)
            str = 'sm_car_plot1speed';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: WheelSpeedsButton
        function WheelSpeedsButtonPushed(app, event)
            str = 'sm_car_plot2whlspeed';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ManeuverButton
        function ManeuverButtonPushed(app, event)
            str = 'sm_car_plot3maneuver(Maneuver,logsout_sm_car);';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: OverrideApplyButtonSteerA1
        function OverrideApplyButtonSteerA1Pushed(app, event)
            value = app.SteerA1DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''SuspA1'');'];
            disp(str)
            evalin('base',str);
            app.OverrideApplyButtonSteerA1.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSteerA2
        function OverrideApplyButtonSteerA2Pushed(app, event)
            value = app.SteerA2DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''SuspA2'');'];
            disp(str)
            evalin('base',str);            
            app.OverrideApplyButtonSteerA2.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSprings
        function OverrideApplyButtonSpringsPushed(app, event)
            valueAxle1 = app.SpringAxle1DropDown.Value;
            valueAxle2 = app.SpringAxle2DropDown.Value;
            valueAxle3 = app.SpringAxle3DropDown.Value;
            valuestr = [valueAxle1 '_' valueAxle2 '_' valueAxle3];
            str = ['Vehicle = sm_car_vehcfg_setSpring(Vehicle,''Axle2_Independent'',''' valuestr ''');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonSprings.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonBodyGeometry
        function OverrideApplyButtonBodyGeometryPushed(app, event)
            value = app.GeometryDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setBodyGeometry(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setBodyGeometry(Vehicle,''' value ''');']);            
            app.OverrideApplyButtonBodyGeometry.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonDampers
        function OverrideApplyButtonDampersPushed(app, event)
            valueAxle1 = app.DamperAxle1DropDown.Value;
            valueAxle2 = app.DamperAxle2DropDown.Value;
            valueAxle3 = app.DamperAxle3DropDown.Value;
            valuestr = [valueAxle1 '_' valueAxle2 '_' valueAxle3];
            str = ['Vehicle = sm_car_vehcfg_setDamper(Vehicle,''Axle2_Independent'',''' valuestr ''');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonDampers.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonPower
        function OverrideApplyButtonPowerPushed(app, event)
            value = app.PowerDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setPower(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setPower(Vehicle,''' value ''');']);
            PowerCoolingvalue = app.PowerActiveCoolingCheckBox.Value;
            if(PowerCoolingvalue)
                coolingSetting = 'Liquid_Loop1';
            else
                coolingSetting = 'None';
            end
            str = (['Vehicle = sm_car_vehcfg_setPowerCooling(Vehicle,''' coolingSetting ''');']);
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonPower.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonDriveline
        function OverrideApplyButtonDrivelinePushed(app, event)
            value = app.DrivelineDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setDrv(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setDrv(Vehicle,''' value ''');']);            
            app.OverrideApplyButtonDriveline.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSuspensionA1
        function OverrideApplyButtonSuspensionA1Pushed(app, event)
            value = app.SuspensionA1DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''SuspA1'');'];
            disp(str)
            evalin('base',str);

            UABushValue = app.SuspA1UACheckBox.Value;
            LABushValue = app.SuspA1LACheckBox.Value;
            [suspPr,armPre] = sm_car_vehcfg_bushing_fields(value);
            if(~strcmp(suspPr{1},'No Bushing'))
                for i = 1:length(armPre)
                    if(((UABushValue) && startsWith(armPre{i},'UA')) ||...
                            ((LABushValue) && startsWith(armPre{i},'LA')))
                        suspPrInd = 1;
                        if(length(suspPr)>1)
                            suspPrInd = i;
                        end
                        vdbFieldName = [char(suspPr{suspPrInd}) '_' armPre{i}];
                        str = ['Vehicle = sm_car_vehcfg_setSubframeConn(Vehicle,''A1'',''' armPre{i} ''',''' vdbFieldName ''');'];
                        disp(str);
                        evalin('base',str);
                    end
                end
            else
                disp(['No bushing configuration found for suspension ' value])
            end
            
            app.OverrideApplyButtonSuspensionA1.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSuspensionA2
        function OverrideApplyButtonSuspensionA2Pushed(app, event)
            value = app.SuspensionA2DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''SuspA2'');'];
            disp(str)
            evalin('base',str);                        

            UABushValue = app.SuspA2UACheckBox.Value;
            LABushValue = app.SuspA2LACheckBox.Value;
            [suspPr,armPre] = sm_car_vehcfg_bushing_fields(value);
            if(~strcmp(suspPr{1},'No Bushing'))
                for i = 1:length(armPre)
                    if(((UABushValue) && startsWith(armPre{i},'UA')) ||...
                            ((LABushValue) && startsWith(armPre{i},'LA')))
                        suspPrInd = 1;
                        if(length(suspPr)>1)
                            suspPrInd = i;
                        end
                        vdbFieldName = [char(suspPr{suspPrInd}) '_' armPre{i}];
                        str = ['Vehicle = sm_car_vehcfg_setSubframeConn(Vehicle,''A2'',''' armPre{i} ''',''' vdbFieldName ''');'];
                        disp(str);
                        evalin('base',str);
                    end
                end
            else
                disp(['No bushing configuration found for suspension ' value])
            end
            
            app.OverrideApplyButtonSuspensionA2.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSuspensionA3
        function OverrideApplyButtonSuspensionA3Pushed(app, event)
            value = app.SuspensionA3DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSusp(Vehicle,''' value ''',''SuspA3'');'];
            disp(str)
            evalin('base',str);            

            UABushValue = app.SuspA3UACheckBox.Value;
            LABushValue = app.SuspA3LACheckBox.Value;
            [suspPr,armPre] = sm_car_vehcfg_bushing_fields(value);
            if(~strcmp(suspPr{1},'No Bushing'))
                for i = 1:length(armPre)
                    if(((UABushValue) && startsWith(armPre{i},'UA')) ||...
                            ((LABushValue) && startsWith(armPre{i},'LA')))
                        suspPrInd = 1;
                        if(length(suspPr)>1)
                            suspPrInd = i;
                        end
                        vdbFieldName = [char(suspPr{suspPrInd}) '_' armPre{i}];
                        str = ['Vehicle = sm_car_vehcfg_setSubframeConn(Vehicle,''A3'',''' armPre{i} ''',''' vdbFieldName ''');'];
                        disp(str);
                        evalin('base',str);
                    end
                end
            else
                disp(['No bushing configuration found for suspension ' value])
            end

            app.OverrideApplyButtonSuspensionA3.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;            
        end

        % Button pushed function: OverrideApplyButtonBrakes
        function OverrideApplyButtonBrakesPushed(app, event)
            value = app.BrakesDropDown.Value;
            disp         (['Vehicle = sm_car_vehcfg_setBrakes(Vehicle,''' value ''');']);
            evalin('base',['Vehicle = sm_car_vehcfg_setBrakes(Vehicle,''' value ''');']);
            ABSControlCheckBoxValueChanged(app, event);
            app.OverrideApplyButtonBrakes.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonTiresA1
        function OverrideApplyButtonTiresA1Pushed(app, event)
            valueModel = app.TiresA1ModelDropDown.Value;
            valueData = app.TiresA1DataDropDown.Value;
            valueBody = app.TiresA1BodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            str = ['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''TireA1'');'];
            disp(str);
            evalin('base',str);            
            value = app.TiresA1DynamicsDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''TireA1'');'];
            disp(str);
            evalin('base',str);                        
            value = app.TiresA1SlipDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''TireA1'');'];
            disp(str);
            evalin('base',str);
            value = app.TiresA1ContactDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''TireA1'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function OverrideApplyButtonRearTiresPushed(app, event)
            valueModel = app.RearTireModelDropDown.Value;
            valueData = app.RearTireDataDropDown.Value;
            valueBody = app.RearTireBodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            str = ['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''TireA2'');'];
            disp(str);
            evalin('base',str);            
            value = app.RearTireDynamicsDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''TireA2'');'];
            disp(str);
            evalin('base',str);                        
            value = app.RearTireSlipDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''TireA2'');'];
            disp(str)
            evalin('base',str);
            value = app.RearTireContactDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''TireA2'');'];
            evalin('base',str);
            app.OverrideApplyButtonRearTires.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonAntiRollA1
        function OverrideApplyButtonAntiRollA1Pushed(app, event)
            value = app.AntiRollBarA1DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAntiRollBar(Vehicle,''' value ''',''SuspA1'');'];
            disp(str);
            evalin('base',str);

            ARBbushValue = app.ARBA1BushingsCheckBox.Value;
            if(ARBbushValue)
                [suspPr,armPre] = sm_car_vehcfg_bushing_fields(value);
                str = ['Vehicle = sm_car_vehcfg_setSubframeConn(Vehicle,''A1'',''' char(armPre) ''',''' char(suspPr) ''');'];
                disp(str);
                evalin('base',str);
            end

            app.OverrideApplyButtonAntiRollA1.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonAntiRollA2
        function OverrideApplyButtonAntiRollA2Pushed(app, event)
            value = app.AntiRollBarA2DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAntiRollBar(Vehicle,''' value ''',''SuspA2'');'];
            disp(str);
            evalin('base',str);

            ARBbushValue = app.ARBA2BushingsCheckBox.Value;
            if(ARBbushValue)
                [suspPr,armPre] = sm_car_vehcfg_bushing_fields(value);
                str = ['Vehicle = sm_car_vehcfg_setSubframeConn(Vehicle,''A2'',''' char(armPre) ''',''' char(suspPr) ''');'];
                disp(str);
                evalin('base',str);
            end            

            app.OverrideApplyButtonAntiRollA2.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: LoadVDatabase
        function LoadVDatabaseButtonPushed(app, event)
            str = 'VDatabase = sm_car_import_vehicle_data(1,1);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ListVehicleConfigurationButton
        function ListVehicleConfigurationButtonPushed(app, event)
            str = 'sm_car_vehcfg_getConfig(Vehicle)';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            model_name = app.MainModelEditField.Value;            
            str = ['open_system(''' model_name ''')'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: ReplayinUnrealButton
        function ReplayinUnrealButtonPushed(app, event)
            str = 'open_system(''sm_car_Unreal_Engine_replay_animation'')';
            disp(str);
            evalin('base',str);
        end

        % Value changed function: BodyOpaqueCheckBox
        function BodyOpaqueCheckBoxValueChanged(app, event)
            value = app.BodyOpaqueCheckBox.Value;
            if(value)
                disp('Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 1;');
                evalin('base','Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 1;');
            else
                disp('Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 0.3;');
                evalin('base','Vehicle.Chassis.Body.BodyGeometry.Opacity.Value = 0.3;');
            end
        end

        % Button pushed function: RegenerateVehiclePresetsButton
        function RegenerateVehiclePresetsButtonPushed(app, event)
            str = 'sm_car_assemble_presets';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateVehicleDatabaseButton
        function RegenerateVehicleDatabaseButtonPushed(app, event)
            str='VDatabase = sm_car_import_vehicle_data(0,1);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateManueverDatabaseButton
        function RegenerateManueverDatabaseButtonPushed(app, event)
            %str = 'sm_car_gen_upd_database(''Maneuver'',0);';
            %disp(str)
            %evalin('base',str);
            str = 'MDatabase = sm_car_import_maneuver_data;';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: RegenerateInitDatabaseButton
        function RegenerateInitDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_init_database;';
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: RegenerateDriverDatabaseButton
        function RegenerateDriverDatabaseButtonPushed(app, event)
            str = 'sm_car_gen_driver_database;';
            disp(str)
            evalin('base',str);            
        end

        % Button pushed function: RegenerateCameraDatabaseButton
        function RegenerateCameraDatabaseButtonPushed(app, event)
            str = 'CDatabase.Camera = sm_car_gen_camera_database;';
            disp(str)
            evalin('base',str);            
        end

        % Button pushed function: VehiclePresetsFileButton
        function VehiclePresetsFileButtonPushed(app, event)
            edit sm_car_assemble_presets.m
        end

        % Button pushed function: ManeuverDatabaseFileButton
        function ManeuverDatabaseFileButtonPushed(app, event)
            edit sm_car_import_maneuver_data.m
        end

        % Button pushed function: InitDatabaseFileButton
        function InitDatabaseFileButtonPushed(app, event)
            edit sm_car_gen_init_database.m;
        end

        % Button pushed function: DriverDatabaseFileButton
        function DriverDatabaseFileButtonPushed(app, event)
            edit sm_car_gen_driver_database.m;
        end

        % Button pushed function: SceneDatabaseFileButton
        function SceneDatabaseFileButtonPushed(app, event)
            edit sm_car_import_scene_data.m
        end

        % Button pushed function: CameraDatabaseFileButton
        function CameraDatabaseFileButtonPushed(app, event)
            str = 'sm_car_gen_camera_database(''edit'');';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateSceneDatabaseButton
        function RegenerateSceneDatabaseButtonPushed(app, event)
            str = 'Scene = sm_car_import_scene_data;';
            evalin('base',str)
        end

        % Button pushed function: OverrideApplyButtonAero
        function OverrideApplyButtonAeroPushed(app, event)
            value = app.AeroDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAero(Vehicle,''' value ''');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonAero.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonBody
        function OverrideApplyButtonBodyPushed(app, event)
            value = app.BodyDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setBody(Vehicle,''' value ''');'];
            disp(str);
            evalin('base',str);
            OverrideApplyButtonBodyGeometryPushed(app, event)
            app.OverrideApplyButtonBody.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: ManeuverCodeButton
        function ManeuverCodeButtonPushed(app, event)
            edit sm_car_config_maneuver.m
        end

        % Button pushed function: PlotManeuverCodeButton
        function PlotManeuverCodeButtonPushed(app, event)
            edit sm_car_plot_maneuver.m
        end

        % Button pushed function: SurfaceCodeButton
        function SurfaceCodeButtonPushed(app, event)
            edit sm_car_config_road.m
        end

        % Button pushed function: SolverCodeButton
        function SolverCodeButtonPushed(app, event)
            edit sm_car_config_solver.m
        end

        % Button pushed function: OverrideApplyButtonPeople
        function OverrideApplyButtonPeoplePushed(app, event)
            PeopleDrivervalue = app.PeopleDriverCheckBox.Value;
            PeopleFLvalue = app.PeopleFLCheckBox.Value;
            PeopleFRvalue = app.PeopleFRCheckBox.Value;
            PeopleRLvalue = app.PeopleRLCheckBox.Value;
            PeopleRRvalue = app.PeopleRRCheckBox.Value;
            str = ['Vehicle = sm_car_vehcfg_setPeopleOnOff(Vehicle,[' num2str(PeopleDrivervalue) ' ' num2str(PeopleFLvalue) ' ' num2str(PeopleFRvalue) ' ' num2str(PeopleRLvalue) ' ' num2str(PeopleRRvalue) '],''SuspA1'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonPeople.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Selection changed function: SelectTrailerConfigurationButtonGroup
        function SelectTrailerConfigurationButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SelectTrailerConfigurationButtonGroup.SelectedObject;
            switch (event.NewValue)
                case app.NoTrailerButton,                       preset_ind = 'None';
                case app.ElulaMFeval15DOFButton,                preset_ind = '01';
                case app.ThwalaMFeval15DOFButton,               preset_ind = '02';
                case app.ElulaSwift15DOFButton,                 preset_ind = '03';
                case app.ThwalaSwift15DOFButton,                preset_ind = '04';
                case app.ElulaMbody15DOFButton,                 preset_ind = '07';
                case app.ThwalaMbody15DOFButton,                preset_ind = '08';
                case app.ElulaMFevalUnstableButton,             preset_ind = '05';
                case app.ElulaSwiftUnstableButton,              preset_ind = '06';
                case app.ElulaMbodyUnstableButton,              preset_ind = '09';
                case app.Kumanzi15DOFNoLoadMFevalButton,        preset_ind = 'Axle2_000';
                case app.Kumanzi15DOFNoLoadSwiftButton,         preset_ind = 'Axle2_001';
                case app.Kumanzi15DOFNoLoadMbodyButton,         preset_ind = 'Axle2_022';
                case app.Kumanzi15DOFCylNoSloshMFevalButton,    preset_ind = 'Axle2_002';
                case app.Kumanzi15DOFCylNoSloshSwiftButton,     preset_ind = 'Axle2_003';
                case app.Kumanzi15DOFCylNoSloshMbodyButton,     preset_ind = 'Axle2_023';
                case app.Kumanzi15DOFCylSloshMFevalButton,      preset_ind = 'Axle2_004';
                case app.Kumanzi15DOFCylSloshSwiftButton,       preset_ind = 'Axle2_005';
                case app.Kumanzi15DOFCylSloshMbodyButton,       preset_ind = 'Axle2_024';
                case app.Kumanzi15DOFPendNoSloshMFevalButton,   preset_ind = 'Axle2_006';
                case app.Kumanzi15DOFPendNoSloshSwiftButton,    preset_ind = 'Axle2_007';
                case app.Kumanzi15DOFPendNoSloshMbodyButton,    preset_ind = 'Axle2_025';
                case app.Kumanzi15DOFPendSloshMFevalButton,     preset_ind = 'Axle2_008';
                case app.Kumanzi15DOFPendSloshSwiftButton,      preset_ind = 'Axle2_009';
                case app.Kumanzi15DOFPendSloshMbodyButton,      preset_ind = 'Axle2_026';
                case app.ParamTank15DOFNoLoadMFevalButton,      preset_ind = 'Axle2_010';
                case app.ParamTank15DOFNoLoadSwiftButton,       preset_ind = 'Axle2_011';
                case app.ParamTank15DOFNoLoadMbodyButton,       preset_ind = 'Axle2_027';
                case app.ParamTank15DOFCylNoSloshMFevalButton,  preset_ind = 'Axle2_012';
                case app.ParamTank15DOFCylNoSloshSwiftButton,   preset_ind = 'Axle2_013';
                case app.ParamTank15DOFCylNoSloshMbodyButton,   preset_ind = 'Axle2_028';
                case app.ParamTank15DOFCylSloshMFevalButton,    preset_ind = 'Axle2_014';
                case app.ParamTank15DOFCylSloshSwiftButton,     preset_ind = 'Axle2_015';
                case app.ParamTank15DOFCylSloshMbodyButton,     preset_ind = 'Axle2_029';
                case app.ParamTank15DOFPendNoSloshMFevalButton, preset_ind = 'Axle2_016';
                case app.ParamTank15DOFPendNoSloshSwiftButton,  preset_ind = 'Axle2_017';
                case app.ParamTank15DOFPendNoSloshMbodyButton,  preset_ind = 'Axle2_030';
                case app.ParamTank15DOFPendSloshMFevalButton,   preset_ind = 'Axle2_018';
                case app.ParamTank15DOFPendSloshSwiftButton,    preset_ind = 'Axle2_019';
                case app.ParamTank15DOFPendSloshMbodyButton,    preset_ind = 'Axle2_031';
                case app.BoxMFeval15DOFButton,                  preset_ind = 'Axle2_020';
                case app.BoxSwift15DOFButton,                   preset_ind = 'Axle2_021';
                case app.BoxMbody15DOFButton,                   preset_ind = 'Axle2_032';
            end
            model_name = app.MainModelEditField.Value;
            str = ['sm_car_load_trailer_data(''' model_name ''',''' preset_ind ''');'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: OverrideWindButton
        function OverrideWindButtonPushed(app, event)
            WindCarvalue = app.WindCarCheckBox.Value;
            WindTrlvalue = app.WindTrailerCheckBox.Value;
            model_name = app.MainModelEditField.Value;
            str = ['sm_car_config_wind(''' model_name ''',' num2str(WindCarvalue) ',' num2str(WindTrlvalue) ');'];
            disp(str);
            evalin('base',str); 
        end

        % Button pushed function: WindCodeButton
        function WindCodeButtonPushed(app, event)
            edit('sm_car_config_wind.m');
        end

        % Button pushed function: PositionandSpeedCodeButton
        function PositionandSpeedCodeButtonPushed(app, event)
            edit('sm_car_plot1speed.m');
        end

        % Button pushed function: WheelSpeedsCodeButton
        function WheelSpeedsCodeButtonPushed(app, event)
            edit('sm_car_plot2whlspeed.m');
        end

        % Button pushed function: ResultsManeuverCodeButton
        function ResultsManeuverCodeButtonPushed(app, event)
            edit('sm_car_plot3maneuver.m');
        end

        % Button pushed function: BodyMeasurementsButton
        function BodyMeasurementsButtonPushed(app, event)
            str = 'sm_car_plot5bodymeas';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: BodyMeasurementsCodeButton
        function BodyMeasurementsCodeButtonPushed(app, event)
            edit('sm_car_plot5bodymeas');
        end

        % Button pushed function: SimscapeResultsExplorerButton
        function SimscapeResultsExplorerButtonPushed(app, event)
            res_vars = evalin('base','who(''simlog_sm_car'');');
            if contains(res_vars,'simlog_sm_car')
                str = 'sscexplore(simlog_sm_car);';
                disp(str);
                evalin('base',str);
            else
                disp('Variable ''simlog_sm_car'' not in base workspace');
            end
        end

        % Button pushed function: RegenerateAllDatabasesButton
        function RegenerateAllDatabasesButtonPushed(app, event)
            str = 'sm_car_gen_all_database';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: RegenerateAllDatabasesFileButton
        function RegenerateAllDatabasesFileButtonPushed(app, event)
            edit('sm_car_gen_all_database.m');
        end

        % Button pushed function: SimulationDataInspectorButton
        function SimulationDataInspectorButtonPushed(app, event)
            str = 'Simulink.sdi.view';
            disp(str);
            evalin('base',str);
        end

        % Value changed function: PowerDropDown
        function PowerDropDownValueChanged(app, event)
            app.OverrideApplyButtonPower.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PowerActiveCoolingCheckBox
        function PowerActiveCoolingCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPower.BackgroundColor = app.highlightColor;
        end

        % Value changed function: AeroDropDown
        function AeroDropDownValueChanged(app, event)
            app.OverrideApplyButtonAero.BackgroundColor = app.highlightColor;
        end

        % Value changed function: BodyDropDown
        function BodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonBody.BackgroundColor = app.highlightColor;
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)
            app.OverrideApplyButtonBodyGeometry.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleDriverCheckBox
        function PeopleDriverCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleFLCheckBox
        function PeopleFLCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleFRCheckBox
        function PeopleFRCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleRLCheckBox
        function PeopleRLCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: PeopleRRCheckBox
        function PeopleRRCheckBoxValueChanged(app, event)
            app.OverrideApplyButtonPeople.BackgroundColor = app.highlightColor;
        end

        % Value changed function: DrivelineDropDown
        function DrivelineDropDownValueChanged(app, event)
            app.OverrideApplyButtonDriveline.BackgroundColor = app.highlightColor;
        end

        % Value changed function: BrakesDropDown
        function BrakesDropDownValueChanged(app, event)
            app.OverrideApplyButtonBrakes.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SpringAxle1DropDown
        function SpringAxle1DropDownValueChanged(app, event)
            app.OverrideApplyButtonSprings.BackgroundColor = app.highlightColor;
        end

        % Value changed function: DamperAxle1DropDown
        function DamperAxle1DropDownValueChanged(app, event)
            app.OverrideApplyButtonDampers.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SuspensionA1DropDown
        function SuspensionA1DropDownValueChanged(app, event)
            app.OverrideApplyButtonSuspensionA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SuspensionA2DropDown
        function SuspensionA2DropDownValueChanged(app, event)
            app.OverrideApplyButtonSuspensionA2.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SuspensionA3DropDown
        function SuspensionA3DropDownValueChanged(app, event)
            app.OverrideApplyButtonSuspensionA3.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: SteerA1DropDown
        function SteerA1DropDownValueChanged(app, event)
            app.OverrideApplyButtonSteerA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SteerA2DropDown
        function SteerA2DropDownValueChanged(app, event)
            app.OverrideApplyButtonSteerA2.BackgroundColor = app.highlightColor;
        end

        % Value changed function: AntiRollBarA1DropDown
        function AntiRollBarA1DropDownValueChanged(app, event)
            app.OverrideApplyButtonAntiRollA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: AntiRollBarA2DropDown
        function AntiRollBarA2DropDownValueChanged(app, event)
            app.OverrideApplyButtonAntiRollA2.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function FrontTireModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA1DataDropDown
        function TiresA1DataDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA1BodyDropDown
        function TiresA1BodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA1DynamicsDropDown
        function TiresA1DynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireDataDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireBodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireDynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireSlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Callback function
        function RearTireContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonRearTires.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA1SlipDropDown
        function TiresA1SlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA1ContactDropDown
        function TiresA1ContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Value changed function: UnrealEngineAnimationCheckBox
        function UnrealEngineAnimationCheckBoxValueChanged(app, event)
            value = app.UnrealEngineAnimationCheckBox.Value;
            model_name = app.MainModelEditField.Value;
            if(value)
                parval = 'On';
            else
                parval = 'Off';
            end
            str = ['set_param(''' model_name '/Visualization'',''unreal_onoff'',''' parval ''');'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: DefineNewTrajectoryButton
        function DefineNewTrajectoryButtonPushed(app, event)
            edit sm_car_define_maneuver_mcity
        end

        % Button pushed function: FuelCellVehicle1DButton
        function FuelCellVehicle1DButtonPushed(app, event)
            str = 'open_system(''ssc_car_FuelCell1Motor'');';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PowertrainCurrentButton
        function PowertrainCurrentButtonPushed(app, event)
            str = 'sm_car_plot7power(logsout_sm_car);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PowertrainCurrentCodeButton
        function PowertrainCurrentCodeButtonPushed(app, event)
            edit sm_car_plot7power
        end

        % Button pushed function: OpenMainModelButton
        function OpenMainModelButtonPushed(app, event)
            model_name = app.MainModelEditField.Value;            
            str = ['open_system(''' model_name ''')'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: SelectMainModelButton
        function SelectMainModelButtonPushed(app, event)
            curr_proj = simulinkproject;
            projdir = curr_proj.RootFolder;
            [model_name] = uigetfile([projdir filesep 'sm_car.slx'],'Select Simscape Vehicle Templates model');
            if(model_name)
                app.MainModelEditField.Value = model_name(1:end-4);
            end
        end

        % Value changed function: SpringAxle2DropDown
        function SpringAxle2DropDownValueChanged(app, event)
            app.OverrideApplyButtonSprings.BackgroundColor = app.highlightColor;
        end

        % Value changed function: SpringAxle3DropDown
        function SpringAxle3DropDownValueChanged(app, event)
            app.OverrideApplyButtonSprings.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: DamperAxle2DropDown
        function DamperAxle2DropDownValueChanged(app, event)
            app.OverrideApplyButtonDampers.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: DamperAxle3DropDown
        function DamperAxle3DropDownValueChanged(app, event)
            app.OverrideApplyButtonDampers.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA1ModelDropDown
        function TiresA1ModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA1.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonTiresA2
        function OverrideApplyButtonTiresA2Pushed(app, event)
            valueModel = app.TiresA2ModelDropDown.Value;
            valueData = app.TiresA2DataDropDown.Value;
            valueBody = app.TiresA2BodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            str = ['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''TireA2'');'];
            disp(str);
            evalin('base',str);            
            value = app.TiresA2DynamicsDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''TireA2'');'];
            disp(str);
            evalin('base',str);                        
            value = app.TiresA2SlipDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''TireA2'');'];
            disp(str);
            evalin('base',str);
            value = app.TiresA2ContactDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''TireA2'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA2ModelDropDown
        function TiresA2ModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA2DataDropDown
        function TiresA2DataDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA2BodyDropDown
        function TiresA2BodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA2DynamicsDropDown
        function TiresA2DynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA2SlipDropDown
        function TiresA2SlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA2ContactDropDown
        function TiresA2ContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA2.BackgroundColor = app.highlightColor;            
        end

        % Button pushed function: OverrideApplyButtonTiresA3
        function OverrideApplyButtonTiresA3Pushed(app, event)
            valueModel = app.TiresA3ModelDropDown.Value;
            valueData = app.TiresA3DataDropDown.Value;
            valueBody = app.TiresA3BodyDropDown.Value;
            tire_cfg_str = [valueModel '_' valueBody '_' valueData];
            str = ['Vehicle = sm_car_vehcfg_setTire(Vehicle,''' tire_cfg_str ''',''TireA3'');'];
            disp(str);
            evalin('base',str);            
            value = app.TiresA3DynamicsDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireDyn(Vehicle,''' value ''',''TireA3'');'];
            disp(str);
            evalin('base',str);                        
            value = app.TiresA3SlipDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireSlip(Vehicle,''' value ''',''TireA3'');'];
            disp(str);
            evalin('base',str);
            value = app.TiresA3ContactDropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setTireContact(Vehicle,''' value ''',''TireA3'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA3ModelDropDown
        function TiresA3ModelDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;                        
        end

        % Value changed function: TiresA3DataDropDown
        function TiresA3DataDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA3BodyDropDown
        function TiresA3BodyDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;
        end

        % Value changed function: TiresA3DynamicsDropDown
        function TiresA3DynamicsDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA3SlipDropDown
        function TiresA3SlipDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: TiresA3ContactDropDown
        function TiresA3ContactDropDownValueChanged(app, event)
            app.OverrideApplyButtonTiresA3.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: SteerA3DropDown
        function SteerA3DropDownValueChanged(app, event)
            app.OverrideApplyButtonSteerA3.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonSteerA3
        function OverrideApplyButtonSteerA3Pushed(app, event)
            value = app.SteerA3DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setSteer(Vehicle,''' value ''',''SuspA3'');'];
            disp(str)
            evalin('base',str);            
            app.OverrideApplyButtonSteerA2.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;
        end

        % Button pushed function: OverrideApplyButtonAntiRollA3
        function OverrideApplyButtonAntiRollA3Pushed(app, event)
            value = app.AntiRollBarA3DropDown.Value;
            str = ['Vehicle = sm_car_vehcfg_setAntiRollBar(Vehicle,''' value ''',''SuspA3'');'];
            disp(str);
            evalin('base',str);
            app.OverrideApplyButtonAntiRollA3.BackgroundColor = app.defaultColor;
            app.ConfigureModelButton.BackgroundColor = app.highlightColor;            
        end

        % Value changed function: AntiRollBarA3DropDown
        function AntiRollBarA3DropDownValueChanged(app, event)
            app.OverrideApplyButtonAntiRollA3.BackgroundColor = app.highlightColor;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Axle2', app.MainModelEditField.Value = 'sm_car';
                case 'Axle3', app.MainModelEditField.Value = 'sm_car_Axle3'; 
            end
        end

        % Button pushed function: TireForcesTorquesButton
        function TireForcesTorquesButtonPushed(app, event)
            str = 'sm_car_plot10_tire_force_torque';
            disp(str);
            evalin('base',str);            
        end

        % Button pushed function: TiresForcesTorquesCodeButton
        function TiresForcesTorquesCodeButtonPushed(app, event)
            edit sm_car_plot10_tire_force_torque
        end

        % Button pushed function: ConsistentHPsButton
        function ConsistentHPsButtonPushed(app, event)
            str = 'hp_check_sum = sm_car_vehcfg_checkHPs(Vehicle,true)';
            disp(str);
            evalin('base',str);             
        end

        % Button pushed function: ConsistentHPsFileButton
        function ConsistentHPsFileButtonPushed(app, event)
            edit sm_car_vehcfg_checkHPs
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimscapeVehicleTemplatesUIFigure = uifigure('Visible', 'off');
            app.SimscapeVehicleTemplatesUIFigure.Name = 'Simscape Vehicle Templates';
            app.TabGroup = uitabgroup(app.SimscapeVehicleTemplatesUIFigure);
            app.PresetsTab = uitab(app.TabGroup);
            app.PresetsTab.Title = 'Presets';
            app.SelectVehicleConfigurationButtonGroup = uibuttongroup(app.PresetsTab);
            app.SelectVehicleConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CustomSelectVehicleConfigurationButtonGroupSelecti, true);
            app.SelectVehicleConfigurationButtonGroup.Tooltip = {''};
            app.SelectVehicleConfigurationButtonGroup.Title = 'Select Vehicle Configuration';
            app.HambaDefaultMFevalTireButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaDefaultMFevalTireButton.Text = '';
            app.HambaDefaultMFevalTireButton.Value = true;
            app.HambaDefaultSwiftTireButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaDefaultSwiftTireButton.Text = '';
            app.SedanHambaLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SedanHambaLabel.Text = 'Sedan Hamba';
            app.BusMakhuluLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.BusMakhuluLabel.Tooltip = {'Bus with two axles.'};
            app.BusMakhuluLabel.Text = 'Bus Makhulu';
            app.MakhuluSwift6WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluSwift6WheelsButton.Tooltip = {''};
            app.MakhuluSwift6WheelsButton.Text = '';
            app.MakhuluMFeval6WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMFeval6WheelsButton.Tooltip = {''};
            app.MakhuluMFeval6WheelsButton.Text = '';
            app.MakhuluMFeval4WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMFeval4WheelsButton.Tooltip = {''};
            app.MakhuluMFeval4WheelsButton.Text = '';
            app.MakhuluSwift4WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluSwift4WheelsButton.Tooltip = {''};
            app.MakhuluSwift4WheelsButton.Text = '';
            app.HambaLiveAxleMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaLiveAxleMFevalButton.Tooltip = {''};
            app.HambaLiveAxleMFevalButton.Text = '';
            app.HambaLiveAxleSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaLiveAxleSwiftButton.Tooltip = {''};
            app.HambaLiveAxleSwiftButton.Text = '';
            app.HambaEMotor2xMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor2xMFevalButton.Tooltip = {''};
            app.HambaEMotor2xMFevalButton.Text = '';
            app.HambaEMotor2xSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor2xSwiftButton.Tooltip = {''};
            app.HambaEMotor2xSwiftButton.Text = '';
            app.HambaEMotor3xMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor3xMFevalButton.Tooltip = {''};
            app.HambaEMotor3xMFevalButton.Text = '';
            app.HambaEMotor3xSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor3xSwiftButton.Tooltip = {''};
            app.HambaEMotor3xSwiftButton.Text = '';
            app.Hamba15DOFMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMFevalButton.Tooltip = {''};
            app.Hamba15DOFMFevalButton.Text = '';
            app.Hamba15DOFSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFSwiftButton.Tooltip = {''};
            app.Hamba15DOFSwiftButton.Text = '';
            app.Hamba15DOFMot2xCoolSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xCoolSwiftButton.Tooltip = {''};
            app.Hamba15DOFMot2xCoolSwiftButton.Text = '';
            app.Hamba15DOFMot2xCoolMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xCoolMFevalButton.Tooltip = {''};
            app.Hamba15DOFMot2xCoolMFevalButton.Text = '';
            app.Hamba15DOFFuelCellSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFFuelCellSwiftButton.Tooltip = {''};
            app.Hamba15DOFFuelCellSwiftButton.Text = '';
            app.Hamba15DOFFuelCellMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFFuelCellMFevalButton.Tooltip = {''};
            app.Hamba15DOFFuelCellMFevalButton.Text = '';
            app.RealTimeConfigBasicButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.RealTimeConfigBasicButton.Tooltip = {'Simple suspension on front and rear axles (heave and roll degrees of freedom only).  Linear transient dynamics on MFeval tire model (better for fixed-step simulation).'};
            app.RealTimeConfigBasicButton.Text = 'Real-Time Config (Basic)';
            app.RealTimeConfigFullButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.RealTimeConfigFullButton.Tooltip = {'Double-wishbone suspension on front and rear axles.  Linear transient dynamics on MFeval tire model (better for fixed-step simulation).'};
            app.RealTimeConfigFullButton.Text = 'Real-Time Config (Full)';
            app.MakhuluMFeval6WheelsFuelCellButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMFeval6WheelsFuelCellButton.Tooltip = {''};
            app.MakhuluMFeval6WheelsFuelCellButton.Text = '';
            app.MakhuluSwift6WheelsFuelCellButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluSwift6WheelsFuelCellButton.Tooltip = {''};
            app.MakhuluSwift6WheelsFuelCellButton.Text = '';
            app.Bus3AxleLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.Bus3AxleLabel.Tooltip = {'Bus with three axles.'};
            app.Bus3AxleLabel.Text = 'Bus, 3 Axle';
            app.Makhulu3Axle15DOF6x2MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x2MFevalButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x2MFevalButton.Text = '';
            app.Makhulu3Axle15DOF6x2SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x2SwiftButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x2SwiftButton.Text = '';
            app.Makhulu3Axle15DOF6x4MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x4MFevalButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x4MFevalButton.Text = '';
            app.Makhulu3AxleWishbone6x2MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3AxleWishbone6x2MFevalButton.Tooltip = {''};
            app.Makhulu3AxleWishbone6x2MFevalButton.Text = '';
            app.TruckAmandla3AxleLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.TruckAmandla3AxleLabel.Text = 'Truck Amandla, 3 Axle';
            app.Amandla15DOF6x2MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x2MFevalButton.Tooltip = {''};
            app.Amandla15DOF6x2MFevalButton.Text = '';
            app.Amandla15DOF6x4MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x4MFevalButton.Tooltip = {''};
            app.Amandla15DOF6x4MFevalButton.Text = '';
            app.Amandla15DOF6x2SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x2SwiftButton.Tooltip = {''};
            app.Amandla15DOF6x2SwiftButton.Text = '';
            app.Amandla15DOF6x4SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x4SwiftButton.Tooltip = {''};
            app.Amandla15DOF6x4SwiftButton.Text = '';
            app.DefaultLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DefaultLabel.Text = 'Default';
            app.LiveAxleLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.LiveAxleLabel.Tooltip = {'Live axle with leaf springs on rear axle. '};
            app.LiveAxleLabel.Text = 'Live Axle';
            app.EMotor2xLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor2xLabel.Tooltip = {'Two electric motors '; 'Front axle'; 'Rear axle'};
            app.EMotor2xLabel.Text = 'EMotor 2x';
            app.EMotor3xLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.EMotor3xLabel.Tooltip = {'Three electric motors'; 'Front axle '; 'Left rear wheel '; 'Right rear wheel'};
            app.EMotor3xLabel.Text = 'EMotor 3x';
            app.DOFLabel_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOFLabel_2.Tooltip = {'Simple suspension on front and rear axles'; 'Heave and roll degrees of freedom only'};
            app.DOFLabel_2.Text = '15DOF';
            app.DOFE2xCoolLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOFE2xCoolLabel.Tooltip = {'Simple suspension on front and rear axles'; 'Heave and Roll degrees of freedom only'; 'Two electric motors with cooling system.'};
            app.DOFE2xCoolLabel.Text = '15DOF E2x Cool ';
            app.DOFFuelCellLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOFFuelCellLabel.Tooltip = {'Simple suspension on front and rear axles'; 'Heave and roll degrees of freedom only'; '1 electric motor powered by battery '; 'and fuel cell with cooling system.'};
            app.DOFFuelCellLabel.Text = '15DOF Fuel Cell';
            app.MFevalLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalLabel.Tooltip = {'Use MFeval tire model.'};
            app.MFevalLabel.Text = 'MFeval';
            app.SwiftLabel_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SwiftLabel_2.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiftLabel_2.Text = 'Swift';
            app.MFevalLabel_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalLabel_2.Tooltip = {'Use MFeval tire model.'};
            app.MFevalLabel_2.Text = 'MFeval';
            app.SwiftLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SwiftLabel.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiftLabel.Text = 'Swift';
            app.WheelsLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.WheelsLabel.Tooltip = {'Bus with 4 tires on rear axle. '};
            app.WheelsLabel.Text = '6 Wheels';
            app.WheelsLabel_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.WheelsLabel_2.Tooltip = {'Bus with 2 tires on rear axle.'};
            app.WheelsLabel_2.Text = '4 Wheels';
            app.FuelCell6WhlLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.FuelCell6WhlLabel.Tooltip = {'Bus with 4 tires on rear axle.  Fuel cell powertrain.'};
            app.FuelCell6WhlLabel.Text = 'Fuel Cell (6 Whl)';
            app.DOF6x2Label = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x2Label.Tooltip = {'Bus with 4 tires on each rear axle'; 'Basic suspension (heave and roll only)'; '2nd axle is powered.'};
            app.DOF6x2Label.Text = '15DOF 6x2';
            app.DOF6x4Label = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x4Label.Tooltip = {'Bus with 4 tires on each rear axle'; 'Basic suspension (heave and roll only)'; '2nd and 3rd axles mechanically connected and powered.'};
            app.DOF6x4Label.Text = '15DOF 6x4';
            app.DOF6x2Label_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x2Label_2.Tooltip = {'Truck with 3 axles'; '6x2 driveline'; '10 wheels'};
            app.DOF6x2Label_2.Text = '15DOF 6x2';
            app.DOF6x4Label_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x4Label_2.Tooltip = {'Truck with 3 axles'; '6x4 driveline'; '10 wheels'};
            app.DOF6x4Label_2.Text = '15DOF 6x4';
            app.DblWishb6x2Label = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DblWishb6x2Label.Tooltip = {'Double-wishbone suspension on all axles.'; '6x2 Driveline'};
            app.DblWishb6x2Label.Text = 'Dbl Wishb 6x2';
            app.Makhulu3Axle15DOF6x4SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x4SwiftButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x4SwiftButton.Text = '';
            app.Makhulu3AxleWishbone6x2SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3AxleWishbone6x2SwiftButton.Tooltip = {''};
            app.Makhulu3AxleWishbone6x2SwiftButton.Text = '';
            app.SemiTruck3AxleLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck3AxleLabel.Tooltip = {'Parameterized truck with 3 axles'};
            app.SemiTruck3AxleLabel.Text = 'Semi Truck, 3 Axle';
            app.MFevalLabel_6 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MFevalLabel_6.Tooltip = {'Use MFeval tire model.'};
            app.MFevalLabel_6.Text = 'MFeval';
            app.SwiftLabel_6 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SwiftLabel_6.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiftLabel_6.Text = 'Swift';
            app.DOF6x2Label_3 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x2Label_3.Tooltip = {'Truck with 3 axles'; '6x2 driveline'; '10 wheels'};
            app.DOF6x2Label_3.Text = '15DOF 6x2';
            app.DOF6x4Label_3 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOF6x4Label_3.Tooltip = {'Truck with 3 axles'; '6x4 driveline'; '10 wheels'};
            app.DOF6x4Label_3.Text = '15DOF 6x4';
            app.SemiTruck15DOF6x4MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x4MFevalButton.Text = '';
            app.SemiTruck15DOF6x4SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x4SwiftButton.Text = '';
            app.SemiTruck15DOF6x2MFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x2MFevalButton.Text = '';
            app.SemiTruck15DOF6x2SwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x2SwiftButton.Text = '';
            app.Hamba15DOFMot2xRegenSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xRegenSwiftButton.Tooltip = {''};
            app.Hamba15DOFMot2xRegenSwiftButton.Text = '';
            app.Hamba15DOFMot2xRegenMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xRegenMFevalButton.Tooltip = {''};
            app.Hamba15DOFMot2xRegenMFevalButton.Text = '';
            app.DOFE2xRegLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOFE2xRegLabel.Tooltip = {'Simple suspension on front and rear axles.'; 'Heave and roll degrees of freedom only.'; '2 electric motors powered by a battery with serial regenerative braking using brake-by-wire.'};
            app.DOFE2xRegLabel.Text = '15DOF E2x Reg';
            app.FSAEAchillesDWDecSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesDWDecSwiftButton.Tooltip = {''};
            app.FSAEAchillesDWDecSwiftButton.Text = '';
            app.FSAEAchillesDWDecMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesDWDecMFevalButton.Tooltip = {''};
            app.FSAEAchillesDWDecMFevalButton.Text = '';
            app.FSAEAchillesDWDecMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesDWDecMbodyButton.Tooltip = {''};
            app.FSAEAchillesDWDecMbodyButton.Text = '';
            app.FSAEAchillesPushMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPushMbodyButton.Tooltip = {''};
            app.FSAEAchillesPushMbodyButton.Text = '';
            app.FSAEAchillesPushSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPushSwiftButton.Tooltip = {''};
            app.FSAEAchillesPushSwiftButton.Text = '';
            app.FSAEAchillesPushMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPushMFevalButton.Tooltip = {''};
            app.FSAEAchillesPushMFevalButton.Text = '';
            app.FSAEAchillesPullMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPullMbodyButton.Tooltip = {''};
            app.FSAEAchillesPullMbodyButton.Text = '';
            app.FSAEAchillesPullSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPullSwiftButton.Tooltip = {''};
            app.FSAEAchillesPullSwiftButton.Text = '';
            app.FSAEAchillesPullMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.FSAEAchillesPullMFevalButton.Tooltip = {''};
            app.FSAEAchillesPullMFevalButton.Text = '';
            app.FSAELabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.FSAELabel.Tooltip = {'Based on Formula SAE design.'};
            app.FSAELabel.Text = 'FSAE';
            app.MbodyLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MbodyLabel.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbodyLabel.Text = 'Mbody';
            app.HambaDefaultMbodyTireButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaDefaultMbodyTireButton.Text = '';
            app.HambaLiveAxleMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaLiveAxleMbodyButton.Tooltip = {''};
            app.HambaLiveAxleMbodyButton.Text = '';
            app.HambaEMotor2xMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor2xMbodyButton.Tooltip = {''};
            app.HambaEMotor2xMbodyButton.Text = '';
            app.HambaEMotor3xMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaEMotor3xMbodyButton.Tooltip = {''};
            app.HambaEMotor3xMbodyButton.Text = '';
            app.Hamba15DOFMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMbodyButton.Tooltip = {''};
            app.Hamba15DOFMbodyButton.Text = '';
            app.Hamba15DOFMot2xCoolMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xCoolMbodyButton.Tooltip = {''};
            app.Hamba15DOFMot2xCoolMbodyButton.Text = '';
            app.Hamba15DOFFuelCellMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFFuelCellMbodyButton.Tooltip = {''};
            app.Hamba15DOFFuelCellMbodyButton.Text = '';
            app.Hamba15DOFMot2xRegenMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot2xRegenMbodyButton.Tooltip = {''};
            app.Hamba15DOFMot2xRegenMbodyButton.Text = '';
            app.Amandla15DOF6x4MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x4MbodyButton.Tooltip = {''};
            app.Amandla15DOF6x4MbodyButton.Text = '';
            app.Amandla15DOF6x2MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Amandla15DOF6x2MbodyButton.Tooltip = {''};
            app.Amandla15DOF6x2MbodyButton.Text = '';
            app.MakhuluMbody6WheelsFuelCellButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMbody6WheelsFuelCellButton.Tooltip = {''};
            app.MakhuluMbody6WheelsFuelCellButton.Text = '';
            app.MakhuluMbody4WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMbody4WheelsButton.Tooltip = {''};
            app.MakhuluMbody4WheelsButton.Text = '';
            app.MakhuluMbody6WheelsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MakhuluMbody6WheelsButton.Tooltip = {''};
            app.MakhuluMbody6WheelsButton.Text = '';
            app.MbodyLabel_2 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MbodyLabel_2.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbodyLabel_2.Text = 'Mbody';
            app.MbodyLabel_3 = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.MbodyLabel_3.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbodyLabel_3.Text = 'Mbody';
            app.Makhulu3Axle15DOF6x2MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x2MbodyButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x2MbodyButton.Text = '';
            app.Makhulu3Axle15DOF6x4MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3Axle15DOF6x4MbodyButton.Tooltip = {''};
            app.Makhulu3Axle15DOF6x4MbodyButton.Text = '';
            app.Makhulu3AxleWishbone6x2MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Makhulu3AxleWishbone6x2MbodyButton.Tooltip = {''};
            app.Makhulu3AxleWishbone6x2MbodyButton.Text = '';
            app.SemiTruck15DOF6x2MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x2MbodyButton.Text = '';
            app.SemiTruck15DOF6x4MbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SemiTruck15DOF6x4MbodyButton.Text = '';
            app.SpringswithNoVariantsButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.SpringswithNoVariantsButton.Text = 'Springs with No Variants';
            app.NonlinearDampersRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearDampersRearButton.Text = 'Nonlinear Dampers Rear';
            app.NonlinearDampersFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearDampersFrontButton.Text = 'Nonlinear Dampers Front';
            app.CVCVPrismaticFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.CVCVPrismaticFrontButton.Text = 'CV-CV Prismatic Front';
            app.StiffSpringDamperButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.StiffSpringDamperButton.Text = 'Stiff Spring Damper';
            app.OnePowerShaftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.OnePowerShaftButton.Text = 'One Power Shaft';
            app.CVsFrontandRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.CVsFrontandRearButton.Text = 'CVs Front and Rear';
            app.AntiLockBrakesButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.AntiLockBrakesButton.Text = 'Anti-Lock Brakes';
            app.NonlinearSpringsFrontButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearSpringsFrontButton.Text = 'Nonlinear Springs Front';
            app.NonlinearSpringsRearButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.NonlinearSpringsRearButton.Text = 'Nonlinear Springs Rear';
            app.DefaultButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.SedanHambaLGLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.SedanHambaLGLabel.Text = 'Sedan HambaLG';
            app.DWDecoupledLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DWDecoupledLabel.Tooltip = {'Based on Formula SAE design.'; 'Double-wishbone suspension with linkages to decouple body roll and heave stiffness.(front and rear).'};
            app.DWDecoupledLabel.Text = 'DW Decoupled';
            app.PullrodLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.PullrodLabel.Tooltip = {'Based on Formula SAE design.'; 'Double-wishbone with pullrod suspensions (front and rear).'};
            app.PullrodLabel.Text = 'Pullrod';
            app.DOFE4xCoolLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.DOFE4xCoolLabel.Tooltip = {'Simple suspension on front and rear axles'; 'Heave and roll degrees of freedom only'; '4 electric motors with cooling system.'};
            app.DOFE4xCoolLabel.Text = '15DOF E4x Cool ';
            app.Hamba15DOFMot4xCoolMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot4xCoolMFevalButton.Tooltip = {''};
            app.Hamba15DOFMot4xCoolMFevalButton.Text = '';
            app.Hamba15DOFMot4xCoolSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot4xCoolSwiftButton.Tooltip = {''};
            app.Hamba15DOFMot4xCoolSwiftButton.Text = '';
            app.Hamba15DOFMot4xCoolMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.Hamba15DOFMot4xCoolMbodyButton.Tooltip = {''};
            app.Hamba15DOFMot4xCoolMbodyButton.Text = '';
            app.TwistBeamLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.TwistBeamLabel.Tooltip = {'Live axle with leaf springs on rear axle. '};
            app.TwistBeamLabel.Text = 'Twist Beam';
            app.HambaTwistBeamMFevalButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaTwistBeamMFevalButton.Tooltip = {''};
            app.HambaTwistBeamMFevalButton.Text = '';
            app.HambaTwistBeamSwiftButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaTwistBeamSwiftButton.Tooltip = {''};
            app.HambaTwistBeamSwiftButton.Text = '';
            app.HambaTwistBeamMbodyButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.HambaTwistBeamMbodyButton.Tooltip = {''};
            app.HambaTwistBeamMbodyButton.Text = '';
            app.PushrodLabel = uilabel(app.SelectVehicleConfigurationButtonGroup);
            app.PushrodLabel.Tooltip = {'Based on Formula SAE design.'; 'Double-wishbone with pullrod suspensions (front and rear).'};
            app.PushrodLabel.Text = 'Pushrod';
            app.MacPhersonButton = uiradiobutton(app.SelectVehicleConfigurationButtonGroup);
            app.MacPhersonButton.Text = 'MacPherson';
            app.SelectTrailerConfigurationButtonGroup = uibuttongroup(app.PresetsTab);
            app.SelectTrailerConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTrailerConfigurationButtonGroupSelectionChanged, true);
            app.SelectTrailerConfigurationButtonGroup.Title = 'Select Trailer Configuration';
            app.NoTrailerButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.NoTrailerButton.Text = 'No Trailer';
            app.NoTrailerButton.Value = true;
            app.ElulaMFeval15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMFeval15DOFButton.Tooltip = {''};
            app.ElulaMFeval15DOFButton.Text = '';
            app.ThwalaMFeval15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ThwalaMFeval15DOFButton.Tooltip = {''};
            app.ThwalaMFeval15DOFButton.Text = '';
            app.ElulaSwift15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaSwift15DOFButton.Tooltip = {''};
            app.ElulaSwift15DOFButton.Text = '';
            app.ThwalaSwift15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ThwalaSwift15DOFButton.Tooltip = {''};
            app.ThwalaSwift15DOFButton.Text = '';
            app.ElulaMFevalUnstableButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMFevalUnstableButton.Tooltip = {''};
            app.ElulaMFevalUnstableButton.Text = '';
            app.CADLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.CADLabel.Tooltip = {'CAD trailer 1 Axle '};
            app.CADLabel.Text = 'CAD';
            app.NoLoadLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.NoLoadLabel.Tooltip = {'No separate load in tank'};
            app.NoLoadLabel.Text = 'No Load';
            app.Kumanzi15DOFNoLoadMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFNoLoadMFevalButton.Tooltip = {''};
            app.Kumanzi15DOFNoLoadMFevalButton.Text = '';
            app.Kumanzi15DOFNoLoadSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFNoLoadSwiftButton.Tooltip = {''};
            app.Kumanzi15DOFNoLoadSwiftButton.Text = '';
            app.MFeLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MFeLabel.Tooltip = {'Use MFeval tire model.'};
            app.MFeLabel.Text = 'MFe';
            app.SwiLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.SwiLabel.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiLabel.Text = 'Swi';
            app.ParametricLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.ParametricLabel.Tooltip = {'Generic trailer 1 Axle '};
            app.ParametricLabel.Text = 'Parametric';
            app.UnstableLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.UnstableLabel.Tooltip = {'Generic trailer 1 Axle '; 'Trailer CG is behind axle (unstable at high speeds).'};
            app.UnstableLabel.Text = 'Unstable';
            app.ElulaSwiftUnstableButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaSwiftUnstableButton.Tooltip = {''};
            app.ElulaSwiftUnstableButton.Text = '';
            app.MFeLabel_2 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MFeLabel_2.Tooltip = {'Use MFeval tire model.'};
            app.MFeLabel_2.Text = 'MFe';
            app.SwiLabel_2 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.SwiLabel_2.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiLabel_2.Text = 'Swi';
            app.AxleTankerCADLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.AxleTankerCADLabel.Tooltip = {'Two-axle tanker trailer'; 'Geometry from CAD'};
            app.AxleTankerCADLabel.Text = '2 Axle Tanker CAD';
            app.AxleLabel_2 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.AxleLabel_2.Tooltip = {'Single-axle trailers'};
            app.AxleLabel_2.Text = '1 Axle';
            app.CylNoSloshLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.CylNoSloshLabel.Tooltip = {'Inertia takes shape of tank'; 'Slosh effect inhibited'};
            app.CylNoSloshLabel.Text = 'Cyl No Slosh ';
            app.Kumanzi15DOFCylNoSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylNoSloshMFevalButton.Tooltip = {''};
            app.Kumanzi15DOFCylNoSloshMFevalButton.Text = '';
            app.Kumanzi15DOFCylNoSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylNoSloshSwiftButton.Tooltip = {''};
            app.Kumanzi15DOFCylNoSloshSwiftButton.Text = '';
            app.ParamTank15DOFPendNoSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendNoSloshMFevalButton.Tooltip = {''};
            app.ParamTank15DOFPendNoSloshMFevalButton.Text = '';
            app.ParamTank15DOFPendNoSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendNoSloshSwiftButton.Tooltip = {''};
            app.ParamTank15DOFPendNoSloshSwiftButton.Text = '';
            app.ParamTank15DOFPendSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendSloshMFevalButton.Tooltip = {''};
            app.ParamTank15DOFPendSloshMFevalButton.Text = '';
            app.ParamTank15DOFPendSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendSloshSwiftButton.Tooltip = {''};
            app.ParamTank15DOFPendSloshSwiftButton.Text = '';
            app.NoLoadLabel_2 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.NoLoadLabel_2.Tooltip = {'No additional load in trailer'};
            app.NoLoadLabel_2.Text = 'No Load';
            app.BoxMFeval15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.BoxMFeval15DOFButton.Tooltip = {''};
            app.BoxMFeval15DOFButton.Text = '';
            app.BoxSwift15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.BoxSwift15DOFButton.Tooltip = {''};
            app.BoxSwift15DOFButton.Text = '';
            app.CylSloshLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.CylSloshLabel.Tooltip = {'Inertia takes shape of tank'; 'Lateral slosh enabled'};
            app.CylSloshLabel.Text = 'Cyl Slosh ';
            app.Kumanzi15DOFCylSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylSloshMFevalButton.Tooltip = {''};
            app.Kumanzi15DOFCylSloshMFevalButton.Text = '';
            app.Kumanzi15DOFCylSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylSloshSwiftButton.Tooltip = {''};
            app.Kumanzi15DOFCylSloshSwiftButton.Text = '';
            app.MFeLabel_3 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MFeLabel_3.Tooltip = {'Use MFeval tire model.'};
            app.MFeLabel_3.Text = 'MFe';
            app.SwiLabel_3 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.SwiLabel_3.Tooltip = {'Use MF-Swift tire model.'};
            app.SwiLabel_3.Text = 'Swi';
            app.Kumanzi15DOFPendNoSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendNoSloshSwiftButton.Tooltip = {''};
            app.Kumanzi15DOFPendNoSloshSwiftButton.Text = '';
            app.Kumanzi15DOFPendSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendSloshSwiftButton.Tooltip = {''};
            app.Kumanzi15DOFPendSloshSwiftButton.Text = '';
            app.Kumanzi15DOFPendNoSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendNoSloshMFevalButton.Tooltip = {''};
            app.Kumanzi15DOFPendNoSloshMFevalButton.Text = '';
            app.Kumanzi15DOFPendSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendSloshMFevalButton.Tooltip = {''};
            app.Kumanzi15DOFPendSloshMFevalButton.Text = '';
            app.PendNoSloshLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.PendNoSloshLabel.Tooltip = {'Inertia modeled as pendula'; 'Slosh effect inhibited'};
            app.PendNoSloshLabel.Text = 'Pend No Slosh';
            app.PendSloshLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.PendSloshLabel.Tooltip = {'Inertia modeled as pendula'; 'Lateral and longitudinal slosh'};
            app.PendSloshLabel.Text = 'Pend Slosh';
            app.AxleBoxTrailerLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.AxleBoxTrailerLabel.Tooltip = {'Two-axle box trailer'; 'Parameterized'};
            app.AxleBoxTrailerLabel.Text = '2 Axle Box Trailer';
            app.ParamTank15DOFNoLoadMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFNoLoadMFevalButton.Tooltip = {''};
            app.ParamTank15DOFNoLoadMFevalButton.Text = '';
            app.ParamTank15DOFNoLoadSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFNoLoadSwiftButton.Tooltip = {''};
            app.ParamTank15DOFNoLoadSwiftButton.Text = '';
            app.ParamTank15DOFCylNoSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylNoSloshMFevalButton.Tooltip = {''};
            app.ParamTank15DOFCylNoSloshMFevalButton.Text = '';
            app.ParamTank15DOFCylNoSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylNoSloshSwiftButton.Tooltip = {''};
            app.ParamTank15DOFCylNoSloshSwiftButton.Text = '';
            app.ParamTank15DOFCylSloshMFevalButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylSloshMFevalButton.Tooltip = {''};
            app.ParamTank15DOFCylSloshMFevalButton.Text = '';
            app.ParamTank15DOFCylSloshSwiftButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylSloshSwiftButton.Tooltip = {''};
            app.ParamTank15DOFCylSloshSwiftButton.Text = '';
            app.AxleTankerLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.AxleTankerLabel.Tooltip = {'Two-axle tanker trailer'; 'Parameterized'};
            app.AxleTankerLabel.Text = '2 Axle Tanker';
            app.BoxMbody15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.BoxMbody15DOFButton.Tooltip = {''};
            app.BoxMbody15DOFButton.Text = '';
            app.ElulaMbodyUnstableButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMbodyUnstableButton.Tooltip = {''};
            app.ElulaMbodyUnstableButton.Text = '';
            app.ElulaMbody15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ElulaMbody15DOFButton.Tooltip = {''};
            app.ElulaMbody15DOFButton.Text = '';
            app.ThwalaMbody15DOFButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ThwalaMbody15DOFButton.Tooltip = {''};
            app.ThwalaMbody15DOFButton.Text = '';
            app.MbLabel = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MbLabel.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbLabel.Text = 'Mb';
            app.Kumanzi15DOFPendSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendSloshMbodyButton.Tooltip = {''};
            app.Kumanzi15DOFPendSloshMbodyButton.Text = '';
            app.Kumanzi15DOFPendNoSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFPendNoSloshMbodyButton.Tooltip = {''};
            app.Kumanzi15DOFPendNoSloshMbodyButton.Text = '';
            app.Kumanzi15DOFCylSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylSloshMbodyButton.Tooltip = {''};
            app.Kumanzi15DOFCylSloshMbodyButton.Text = '';
            app.Kumanzi15DOFCylNoSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFCylNoSloshMbodyButton.Tooltip = {''};
            app.Kumanzi15DOFCylNoSloshMbodyButton.Text = '';
            app.Kumanzi15DOFNoLoadMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.Kumanzi15DOFNoLoadMbodyButton.Tooltip = {''};
            app.Kumanzi15DOFNoLoadMbodyButton.Text = '';
            app.MbLabel_2 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MbLabel_2.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbLabel_2.Text = 'Mb';
            app.ParamTank15DOFPendSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendSloshMbodyButton.Tooltip = {''};
            app.ParamTank15DOFPendSloshMbodyButton.Text = '';
            app.ParamTank15DOFPendNoSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFPendNoSloshMbodyButton.Tooltip = {''};
            app.ParamTank15DOFPendNoSloshMbodyButton.Text = '';
            app.ParamTank15DOFCylSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylSloshMbodyButton.Tooltip = {''};
            app.ParamTank15DOFCylSloshMbodyButton.Text = '';
            app.ParamTank15DOFCylNoSloshMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFCylNoSloshMbodyButton.Tooltip = {''};
            app.ParamTank15DOFCylNoSloshMbodyButton.Text = '';
            app.ParamTank15DOFNoLoadMbodyButton = uiradiobutton(app.SelectTrailerConfigurationButtonGroup);
            app.ParamTank15DOFNoLoadMbodyButton.Tooltip = {''};
            app.ParamTank15DOFNoLoadMbodyButton.Text = '';
            app.MbLabel_3 = uilabel(app.SelectTrailerConfigurationButtonGroup);
            app.MbLabel_3.Tooltip = {'Use Magic Formula Tire block from Simscape Multibody (R21b and higher).'};
            app.MbLabel_3.Text = 'Mb';
            app.HelpLabelPresets = uilabel(app.PresetsTab);
            app.HelpLabelPresets.Tooltip = {'Selecting a preset below updates the Vehicle or Trailer data structure in the MATLAB workspace and triggers variant selection in the model.'};
            app.HelpLabelPresets.Text = '(?)';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.AeroDropDownLabel = uilabel(app.ConfigureTab);
            app.AeroDropDownLabel.Text = 'Aero';
            app.AeroDropDown = uidropdown(app.ConfigureTab);
            app.AeroDropDown.Items = {'Sedan Hamba', 'Sedan Hamba LG', 'Bus Makhulu', 'Truck Amandla'};
            app.AeroDropDown.ItemsData = {'Sedan_Hamba', 'Sedan_HambaLG', 'Bus_Makhulu', 'Truck_Amandla'};
            app.AeroDropDown.ValueChangedFcn = createCallbackFcn(app, @AeroDropDownValueChanged, true);
            app.AeroDropDown.Tooltip = {'Select option and press Apply button on same row to modify data in workspace.'};
            app.AeroDropDown.Value = 'Sedan_Hamba';
            app.BodyDropDownLabel = uilabel(app.ConfigureTab);
            app.BodyDropDownLabel.Text = 'Body';
            app.BodyDropDown = uidropdown(app.ConfigureTab);
            app.BodyDropDown.Items = {'Sedan Hamba', 'Sedan HambaLG', 'Bus Makhulu', 'Bus Makhulu (3 Axle)', 'Truck Amandla (3 Axle)', 'Semi Truck Scalable'};
            app.BodyDropDown.ItemsData = {'Sedan_Hamba', 'Sedan_HambaLG', 'Bus_Makhulu', 'Bus_Makhulu_3Axle', 'Semi_Truck_Scalable'};
            app.BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyDropDownValueChanged, true);
            app.BodyDropDown.Value = 'Sedan_Hamba';
            app.PowerDropDownLabel = uilabel(app.ConfigureTab);
            app.PowerDropDownLabel.Text = 'Power';
            app.PowerDropDown = uidropdown(app.ConfigureTab);
            app.PowerDropDown.Items = {'Ideal 1 Motor', 'Ideal 2 Motor', 'Ideal 4 Motor', 'Electric 2 Motor', 'Electric 3 Motor', 'Electric 4 Motor', 'None'};
            app.PowerDropDown.ItemsData = {'Ideal_A1_default', 'Ideal_A1_A2_default', 'Ideal_L1_R1_L2_R2_default', 'Electric_A1_A2_default', 'Electric_A1_L2_R2_default', 'Electric_L1_R1_L2_R2_default', 'FuelCell_A1_default', 'None'};
            app.PowerDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerDropDownValueChanged, true);
            app.PowerDropDown.Value = 'Ideal_A1_default';
            app.BrakesDropDownLabel = uilabel(app.ConfigureTab);
            app.BrakesDropDownLabel.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'; 'Pedal: Pedal Travel'; 'ABS: Hydraulic Valves'};
            app.BrakesDropDownLabel.Text = 'Brakes';
            app.BrakesDropDown = uidropdown(app.ConfigureTab);
            app.BrakesDropDown.Items = {'S Pedal', 'L Pedal', 'S ABS', 'L ABS', 'B Pedal'};
            app.BrakesDropDown.ItemsData = {'Axle2_PedalAbstract_DiscDisc_Sedan_Hamba', 'Axle2_PedalAbstract_DiscDisc_Sedan_HambaLG', 'Axle2_HydraulicValves_Channel4_Sedan_Hamba', 'Axle2_HydraulicValves_Channel4_Sedan_HambaLG', 'Axle2_PedalAbstract_DiscDisc_Bus_Makhulu'};
            app.BrakesDropDown.ValueChangedFcn = createCallbackFcn(app, @BrakesDropDownValueChanged, true);
            app.BrakesDropDown.Value = 'Axle2_PedalAbstract_DiscDisc_Sedan_Hamba';
            app.SpringsDropDownLabel = uilabel(app.ConfigureTab);
            app.SpringsDropDownLabel.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SpringsDropDownLabel.Text = 'Springs';
            app.SpringAxle1DropDown = uidropdown(app.ConfigureTab);
            app.SpringAxle1DropDown.Items = {'S Linear ', 'L Linear ', 'L Stiff ', 'L Nonlinear', 'B Linear', 'No Spring'};
            app.SpringAxle1DropDown.ItemsData = {'SHlinA1', 'SHLlinA1', 'SHLlinStiffA1', 'SHLnonlinA1', 'BMlinA1', 'NoSpringA1'};
            app.SpringAxle1DropDown.ValueChangedFcn = createCallbackFcn(app, @SpringAxle1DropDownValueChanged, true);
            app.SpringAxle1DropDown.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SpringAxle1DropDown.Value = 'SHlinA1';
            app.DampersDropDownLabel = uilabel(app.ConfigureTab);
            app.DampersDropDownLabel.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.DampersDropDownLabel.Text = 'Dampers';
            app.DamperAxle1DropDown = uidropdown(app.ConfigureTab);
            app.DamperAxle1DropDown.Items = {'S Linear ', 'L Linear ', 'L Stiff ', 'L Nonlinear', 'B Linear'};
            app.DamperAxle1DropDown.ItemsData = {'SHlinA1', 'SHLlinA1', 'SHLlinStiffA1', 'SHLnonlinA1', 'BMlinA1'};
            app.DamperAxle1DropDown.ValueChangedFcn = createCallbackFcn(app, @DamperAxle1DropDownValueChanged, true);
            app.DamperAxle1DropDown.Value = 'SHlinA1';
            app.SuspA1Label = uilabel(app.ConfigureTab);
            app.SuspA1Label.Tooltip = {'Suspension Axle 1 (Type and Data)'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'; 'T: Truck'};
            app.SuspA1Label.Text = 'Susp. A1';
            app.SuspensionA1DropDown = uidropdown(app.ConfigureTab);
            app.SuspensionA1DropDown.Items = {'S Double Wishbone', 'S Split Lower Arm Shock to Front', 'S Five Link Shock to Rear', 'L Double Wishbone', 'L Double Wishbone (A)', 'L Split Lower Arm Shock to Front', 'L Split Lower Arm Shock to Rear', 'L Five Link Shock to Front', 'L Five Link Shock to Rear', 'L Five Link Shock to Front (Constraints)', 'S Simple 15DOF', 'L Simple 15DOF', 'B Double Wishbone', 'T Simple 15DOF'};
            app.SuspensionA1DropDown.ItemsData = {'DoubleWishbone_Sedan_Hamba_f', 'SplitLowerArmShockFront_Sedan_Hamba_f', 'FiveLinkShockRear_Sedan_Hamba_f', 'DoubleWishbone_Sedan_HambaLG_f', 'DoubleWishboneA_Sedan_HambaLG_f', 'SplitLowerArmShockFront_Sedan_HambaLG_f', 'SplitLowerArmShockRear_Sedan_HambaLG_f', 'FiveLinkShockFront_Sedan_HambaLG_f', 'FiveLinkShockRear_Sedan_HambaLG_f', '5CS2LAF_SHL_f', 'Simple15DOF_Sedan_Hamba_f', 'Simple15DOF_Sedan_HambaLG_f', 'DoubleWishbone_Bus_Makhulu_f', 'Simple15DOF_Truck_Amandla_A1'};
            app.SuspensionA1DropDown.ValueChangedFcn = createCallbackFcn(app, @SuspensionA1DropDownValueChanged, true);
            app.SuspensionA1DropDown.Tooltip = {'Suspension Axle1 (Type and Data)'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SuspensionA1DropDown.Value = 'DoubleWishbone_Sedan_Hamba_f';
            app.SuspA2Label = uilabel(app.ConfigureTab);
            app.SuspA2Label.Tooltip = {'Suspension Axle 2 (Type and Data)'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'; 'B3: Bus (3 Axles)'; 'T: Truck'};
            app.SuspA2Label.Text = 'Susp. A2';
            app.SuspensionA2DropDown = uidropdown(app.ConfigureTab);
            app.SuspensionA2DropDown.Items = {'S Double Wishbone (A)', 'S Split Lower Arm Shock to Front', 'S Five Link Shock to Rear', 'L Double Wishbone (A)', 'L Split Lower Arm Shock to Front', 'L Split Lower Arm Shock to Rear', 'L Five Link Shock to Front', 'L Five Link Shock to Rear', 'L Five Link Shock to Front (Constraints)', 'S Simple 15DOF', 'L Simple 15DOF', 'B Double Wishbone', 'S Live Axle', 'B3 15DOF', 'T Simple 15DOF'};
            app.SuspensionA2DropDown.ItemsData = {'DoubleWishboneA_Sedan_Hamba_r', 'SplitLowerArmShockFront_Sedan_Hamba_r', 'FiveLinkShockRear_Sedan_Hamba_r', 'DoubleWishboneA_Sedan_HambaLG_r', 'SplitLowerArmShockFront_Sedan_HambaLG_r', 'SplitLowerArmShockRear_Sedan_HambaLG_r', 'FiveLinkShockFront_Sedan_HambaLG_r', 'FiveLinkShockRear_Sedan_HambaLG_r', '5CS2LAF_SHL_r', 'Simple15DOF_Sedan_Hamba_r', 'Simple15DOF_Sedan_HambaLG_r', 'DoubleWishboneA_Bus_Makhulu_r', 'LiveAxle_Sedan_Hamba_r', 'Simple15DOF_Bus_Makhulu_Axle3_A2', 'Simple15DOF_Truck_Amandla_A2'};
            app.SuspensionA2DropDown.ValueChangedFcn = createCallbackFcn(app, @SuspensionA2DropDownValueChanged, true);
            app.SuspensionA2DropDown.Value = 'DoubleWishboneA_Sedan_Hamba_r';
            app.SteerA1DropDownLabel = uilabel(app.ConfigureTab);
            app.SteerA1DropDownLabel.Tooltip = {'Steering Mechanism Axle 1'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SteerA1DropDownLabel.Text = 'Steer A1';
            app.SteerA1DropDown = uidropdown(app.ConfigureTab);
            app.SteerA1DropDown.Items = {'S Rack Wheel (Table)', 'L Rack (Table)', 'L Rack Wheel (Table)', 'L Rack Static Shafts (Table)', 'L Rack Driven Shafts (Table)', 'L Wheel Motion', 'None', 'B Rack Wheel (Table) ', 'L Ackermann', 'S Ackermann', 'T Ackermann'};
            app.SteerA1DropDown.ItemsData = {'RackWheel_Sedan_Hamba_f', 'Rack_Sedan_HambaLG_f', 'RackWheel_Sedan_HambaLG_f', 'RackStaticShafts_Sedan_HambaLG_f', 'RackDrivenShafts_Sedan_HambaLG_f', 'WheelDrivenRack_Sedan_HambaLG_f', 'None_default', 'RackWheel_Bus_Makhulu_f', 'Ackermann_HambaLG_f', 'Ackermann_Hamba_f', 'Ackermann_Amandla_A1'};
            app.SteerA1DropDown.ValueChangedFcn = createCallbackFcn(app, @SteerA1DropDownValueChanged, true);
            app.SteerA1DropDown.Value = 'RackWheel_Sedan_Hamba_f';
            app.SteerA2DropDownLabel = uilabel(app.ConfigureTab);
            app.SteerA2DropDownLabel.Tooltip = {'Steering Mechanism Axle 2'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SteerA2DropDownLabel.Text = 'Steer A2';
            app.SteerA2DropDown = uidropdown(app.ConfigureTab);
            app.SteerA2DropDown.Items = {'L Rack (Table)', 'None'};
            app.SteerA2DropDown.ItemsData = {'Rack_Sedan_HambaLG_r', 'None_default'};
            app.SteerA2DropDown.ValueChangedFcn = createCallbackFcn(app, @SteerA2DropDownValueChanged, true);
            app.SteerA2DropDown.Value = 'None_default';
            app.DrivelineDropDownLabel = uilabel(app.ConfigureTab);
            app.DrivelineDropDownLabel.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.DrivelineDropDownLabel.Text = 'Driveline';
            app.DrivelineDropDown = uidropdown(app.ConfigureTab);
            app.DrivelineDropDown.Items = {'S 4x4 (3D C-p-C | 1D       )', 'S 4x4 (1D | 1D  | 1D  | 1D )  ', 'S 4x2 (3D C-p-C |             )', 'L 4x4 (3D C-p-C | 1D | 1D)', 'L 4x4 (1D            | 1D       )', 'L 4x4 (1D3D       | 1D        )', 'L 4x4 (3D C-p-C | 1D        )', 'L 4x4 (3D C-p-C flex |  1D)', 'L 4x4 (3D C-C-p | 1D        )', 'L 4x4 (3D C-p-C + 1D       )', 'L 4x4 (3D C-p-C | 3D C-p-C)', 'B 4x4 (3D C-p-C | 1D)'};
            app.DrivelineDropDown.ItemsData = {'f1D3Dr1D_SH', 'f1D_r1D_4sh_SH', 'fCVpCV_FC1m', 'fCVpCVr1D_3sh_SH', 'f1Dr1D_SHL', 'f1D3Dr1D_SHL', 'fCVpCVr1D_SHL', 'fCVpCVflexr1D_SHL', 'fCVCVpr1D_SHL', 'fCVpCVr1D_1sh_SHL', 'fCVpCVrCVpCV_SHL', 'f1D3Dr1D_BM'};
            app.DrivelineDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivelineDropDownValueChanged, true);
            app.DrivelineDropDown.Value = 'f1D3Dr1D_SH';
            app.DefineCustomConfigurationLabel = uilabel(app.ConfigureTab);
            app.DefineCustomConfigurationLabel.Text = 'Define Custom Configuration';
            app.ABSControlCheckBox = uicheckbox(app.ConfigureTab);
            app.ABSControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @ABSControlCheckBoxValueChanged, true);
            app.ABSControlCheckBox.Tooltip = {'Enable ABS controller (valve level control per wheel)'};
            app.ABSControlCheckBox.Text = 'ABS Control';
            app.GeometryDropDownLabel = uilabel(app.ConfigureTab);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.ConfigureTab);
            app.GeometryDropDown.Items = {'Sedan Hamba', 'Sedan HambaLG (CAD)', 'Sedan (Parameterized)', 'Bus Makhulu', 'Truck Amandla (CAD)', 'Semi Truck (Parameterized)'};
            app.GeometryDropDown.ItemsData = {'CAD_Sedan_Hamba', 'CAD_Sedan_HambaLG', 'Sedan_HambaLG', 'CAD_Bus_Makhulu', 'CAD_Truck_Amandla', 'Semi_Truck_Amandla'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Value = 'CAD_Sedan_Hamba';
            app.OverrideinWorkspaceLabel = uilabel(app.ConfigureTab);
            app.OverrideinWorkspaceLabel.Text = {'Override in'; 'Workspace'};
            app.OverrideApplyButtonSprings = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSprings.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSpringsPushed, true);
            app.OverrideApplyButtonSprings.Tooltip = {'Overwrite fields within Spring portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSprings.Text = 'Apply';
            app.OverrideApplyButtonSteerA1 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSteerA1.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSteerA1Pushed, true);
            app.OverrideApplyButtonSteerA1.Tooltip = {'Overwrite SuspA1.Steer portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSteerA1.Text = 'Apply';
            app.OverrideApplyButtonBodyGeometry = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBodyGeometry.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBodyGeometryPushed, true);
            app.OverrideApplyButtonBodyGeometry.Tooltip = {'Overwrite BodyGeometry portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonBodyGeometry.Text = 'Apply';
            app.OverrideApplyButtonDampers = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonDampers.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonDampersPushed, true);
            app.OverrideApplyButtonDampers.Tooltip = {'Overwrite fields within Damper portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonDampers.Text = 'Apply';
            app.OverrideApplyButtonPower = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonPower.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonPowerPushed, true);
            app.OverrideApplyButtonPower.Tooltip = {'Overwrite Power and Power.Cooling portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonPower.Text = 'Apply';
            app.OverrideApplyButtonDriveline = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonDriveline.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonDrivelinePushed, true);
            app.OverrideApplyButtonDriveline.Tooltip = {'Overwrite several fields within Driveline portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonDriveline.Text = 'Apply';
            app.OverrideApplyButtonSuspensionA1 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSuspensionA1.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSuspensionA1Pushed, true);
            app.OverrideApplyButtonSuspensionA1.Tooltip = {'Overwrite SuspA1 portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSuspensionA1.Text = 'Apply';
            app.OverrideApplyButtonSuspensionA2 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSuspensionA2.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSuspensionA2Pushed, true);
            app.OverrideApplyButtonSuspensionA2.Tooltip = {'Overwrite SuspA2 portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSuspensionA2.Text = 'Apply';
            app.OverrideApplyButtonBrakes = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBrakes.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBrakesPushed, true);
            app.OverrideApplyButtonBrakes.Tooltip = {'Overwrite Brakes field in Vehicle data structure to match selection. Updates selected brake control to match selection.'};
            app.OverrideApplyButtonBrakes.Text = 'Apply';
            app.OverrideApplyButtonSteerA2 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSteerA2.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSteerA2Pushed, true);
            app.OverrideApplyButtonSteerA2.Tooltip = {'Overwrite SuspA2.Linkage portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSteerA2.Text = 'Apply';
            app.AntiRollA1Label = uilabel(app.ConfigureTab);
            app.AntiRollA1Label.Tooltip = {'Anti-Roll System Axle 1'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.AntiRollA1Label.Text = 'Anti Roll A1';
            app.AntiRollBarA1DropDown = uidropdown(app.ConfigureTab);
            app.AntiRollBarA1DropDown.Items = {'S Droplink', 'L Droplink', 'L Droplink Rod', 'B Droplink', 'A Droplink', 'A Droplink Rod', 'None'};
            app.AntiRollBarA1DropDown.ItemsData = {'Droplink_Sedan_Hamba_f', 'Droplink_Sedan_HambaLG_f', 'DroplinkRod_Sedan_HambaLG_f', 'Droplink_Bus_Makhulu_f', 'Droplink_FSAE_Achilles_f', 'DroplinkRod_FSAE_Achilles_f', 'None'};
            app.AntiRollBarA1DropDown.ValueChangedFcn = createCallbackFcn(app, @AntiRollBarA1DropDownValueChanged, true);
            app.AntiRollBarA1DropDown.Value = 'Droplink_Sedan_Hamba_f';
            app.OverrideApplyButtonAntiRollA1 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonAntiRollA1.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonAntiRollA1Pushed, true);
            app.OverrideApplyButtonAntiRollA1.Tooltip = {'Overwrite SuspA1.AntiRollBar portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonAntiRollA1.Text = 'Apply';
            app.AntiRollA2Label = uilabel(app.ConfigureTab);
            app.AntiRollA2Label.Tooltip = {'Anti-Roll System Axle 2'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.AntiRollA2Label.Text = 'Anti Roll A2';
            app.AntiRollBarA2DropDown = uidropdown(app.ConfigureTab);
            app.AntiRollBarA2DropDown.Items = {'S Droplink', 'L Droplink', 'L Droplink Rod', 'B Droplink', 'A Droplink', 'A Droplink Rod', 'None'};
            app.AntiRollBarA2DropDown.ItemsData = {'Droplink_Sedan_Hamba_r', 'Droplink_Sedan_HambaLG_r', 'DroplinkRod_Sedan_HambaLG_r', 'Droplink_Bus_Makhulu_r', 'Droplink_FSAE_Achilles_r', 'DroplinkRod_FSAE_Achilles_r', 'None'};
            app.AntiRollBarA2DropDown.ValueChangedFcn = createCallbackFcn(app, @AntiRollBarA2DropDownValueChanged, true);
            app.AntiRollBarA2DropDown.Value = 'Droplink_Sedan_Hamba_r';
            app.OverrideApplyButtonAntiRollA2 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonAntiRollA2.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonAntiRollA2Pushed, true);
            app.OverrideApplyButtonAntiRollA2.Tooltip = {'Overwrite SuspA2.AntiRollBar portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonAntiRollA2.Text = 'Apply';
            app.BodyOpaqueCheckBox = uicheckbox(app.ConfigureTab);
            app.BodyOpaqueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BodyOpaqueCheckBoxValueChanged, true);
            app.BodyOpaqueCheckBox.Text = 'Opaque';
            app.OverrideApplyButtonAero = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonAero.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonAeroPushed, true);
            app.OverrideApplyButtonAero.Tooltip = {'Overwrite Aero portion in Vehicle data structure to match selection.'};
            app.OverrideApplyButtonAero.Text = 'Apply';
            app.OverrideApplyButtonBody = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonBody.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonBodyPushed, true);
            app.OverrideApplyButtonBody.Tooltip = {'Overwrite Body and BodyGeometry portions of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonBody.Text = 'Apply';
            app.PeopleLabel = uilabel(app.ConfigureTab);
            app.PeopleLabel.Text = 'People';
            app.PeopleFLCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleFLCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleFLCheckBoxValueChanged, true);
            app.PeopleFLCheckBox.Text = 'FL';
            app.PeopleFRCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleFRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleFRCheckBoxValueChanged, true);
            app.PeopleFRCheckBox.Text = 'FR';
            app.PeopleRLCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleRLCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleRLCheckBoxValueChanged, true);
            app.PeopleRLCheckBox.Text = 'RL';
            app.PeopleRRCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleRRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleRRCheckBoxValueChanged, true);
            app.PeopleRRCheckBox.Text = 'RR';
            app.PeopleDriverCheckBox = uicheckbox(app.ConfigureTab);
            app.PeopleDriverCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleDriverCheckBoxValueChanged, true);
            app.PeopleDriverCheckBox.Text = 'Driver';
            app.OverrideApplyButtonPeople = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonPeople.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonPeoplePushed, true);
            app.OverrideApplyButtonPeople.Tooltip = {'Enable and disable visualization of people (data must already be in Vehicle data structure under DriverHuman and Passenger).'};
            app.OverrideApplyButtonPeople.Text = 'Apply';
            app.HelpLabelConfigure = uilabel(app.ConfigureTab);
            app.HelpLabelConfigure.Tooltip = {'These buttons let you alter individual subsystems within the vehicle model. '; ''; '1. Set dropdown menu for any part of the vehicle.'; '2. Press the "Apply" button on same row to modify data structure in workspace.  '; '3. Press "Configure Model" in the lower right corner to apply changes to model.'};
            app.HelpLabelConfigure.Text = '(?)';
            app.PowerActiveCoolingCheckBox = uicheckbox(app.ConfigureTab);
            app.PowerActiveCoolingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerActiveCoolingCheckBoxValueChanged, true);
            app.PowerActiveCoolingCheckBox.Tooltip = {'Enable or disable cooling (electric power variants only).'};
            app.PowerActiveCoolingCheckBox.Text = 'Active Cooling';
            app.Axle1Label = uilabel(app.ConfigureTab);
            app.Axle1Label.Text = 'Axle 1';
            app.Axle2Label = uilabel(app.ConfigureTab);
            app.Axle2Label.Text = 'Axle 2';
            app.Axle3Label = uilabel(app.ConfigureTab);
            app.Axle3Label.Text = 'Axle 3';
            app.SpringAxle2DropDown = uidropdown(app.ConfigureTab);
            app.SpringAxle2DropDown.Items = {'S Linear ', 'L Linear ', 'L Stiff ', 'L Nonlin', 'B Linear', 'No Spring'};
            app.SpringAxle2DropDown.ItemsData = {'SHlinA2', 'SHLlinA2', 'SHLlinStiffA2', 'SHLnonlinA2', 'BMlinA2', 'NoSpringA2'};
            app.SpringAxle2DropDown.ValueChangedFcn = createCallbackFcn(app, @SpringAxle2DropDownValueChanged, true);
            app.SpringAxle2DropDown.Tooltip = {'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SpringAxle2DropDown.Value = 'SHlinA2';
            app.SpringAxle3DropDown = uidropdown(app.ConfigureTab);
            app.SpringAxle3DropDown.Items = {'None', 'B Linear'};
            app.SpringAxle3DropDown.ItemsData = {'None', 'BMlinA2'};
            app.SpringAxle3DropDown.ValueChangedFcn = createCallbackFcn(app, @SpringAxle3DropDownValueChanged, true);
            app.SpringAxle3DropDown.Value = 'None';
            app.DamperAxle2DropDown = uidropdown(app.ConfigureTab);
            app.DamperAxle2DropDown.Items = {'S Linear ', 'L Linear ', 'L Stiff ', 'L Nonlinear', 'S Live', 'B Linear'};
            app.DamperAxle2DropDown.ItemsData = {'SHlinA2', 'SHLlinA2', 'SHLlinStiffA2', 'SHLnonlinA2', 'SHliveA2', 'BMlinA2'};
            app.DamperAxle2DropDown.ValueChangedFcn = createCallbackFcn(app, @DamperAxle2DropDownValueChanged, true);
            app.DamperAxle2DropDown.Value = 'SHlinA2';
            app.DamperAxle3DropDown = uidropdown(app.ConfigureTab);
            app.DamperAxle3DropDown.Items = {'None', 'B Linear'};
            app.DamperAxle3DropDown.ItemsData = {'None', 'BMlinA2'};
            app.DamperAxle3DropDown.ValueChangedFcn = createCallbackFcn(app, @DamperAxle3DropDownValueChanged, true);
            app.DamperAxle3DropDown.Value = 'None';
            app.TiresTabGroup = uitabgroup(app.ConfigureTab);
            app.TiresAxle1Tab = uitab(app.TiresTabGroup);
            app.TiresAxle1Tab.Title = 'Tires Axle 1';
            app.DynamicsLabel = uilabel(app.TiresAxle1Tab);
            app.DynamicsLabel.Text = 'Dynamics';
            app.TiresA1DynamicsDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1DynamicsDropDown.Items = {'Steady-State', 'Linear Transients', 'Nonlinear Transients', 'Rigid Ring'};
            app.TiresA1DynamicsDropDown.ItemsData = {'steady', 'lintra', 'nonlin', 'ring'};
            app.TiresA1DynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1DynamicsDropDownValueChanged, true);
            app.TiresA1DynamicsDropDown.Value = 'steady';
            app.SlipLabel = uilabel(app.TiresAxle1Tab);
            app.SlipLabel.Text = 'Slip';
            app.TiresA1SlipDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1SlipDropDown.Items = {'Longitudinal', 'Lateral', 'Uncombined', 'Combined', 'Combined+Turnslip', 'None'};
            app.TiresA1SlipDropDown.ItemsData = {'longitudinal', 'lateral', 'uncombined', 'combined', 'combined+turnslip', 'none'};
            app.TiresA1SlipDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1SlipDropDownValueChanged, true);
            app.TiresA1SlipDropDown.Value = 'combined';
            app.ContactLabel = uilabel(app.TiresAxle1Tab);
            app.ContactLabel.Text = 'Contact';
            app.TiresA1ContactDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1ContactDropDown.Items = {'Smooth', 'Enveloping'};
            app.TiresA1ContactDropDown.ItemsData = {'smooth', 'enveloping'};
            app.TiresA1ContactDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1ContactDropDownValueChanged, true);
            app.TiresA1ContactDropDown.Value = 'smooth';
            app.OverrideApplyButtonTiresA1 = uibutton(app.TiresAxle1Tab, 'push');
            app.OverrideApplyButtonTiresA1.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonTiresA1Pushed, true);
            app.OverrideApplyButtonTiresA1.Tooltip = {'Overwrite TireA1 portion of Vehicle data structure to match selections.'};
            app.OverrideApplyButtonTiresA1.Text = 'Apply';
            app.SizeLabel = uilabel(app.TiresAxle1Tab);
            app.SizeLabel.Text = 'Size';
            app.TiresA1DataDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1DataDropDown.Items = {'213-40R21', '235-50R24', '270-70R22', '430-50R38'};
            app.TiresA1DataDropDown.ItemsData = {'213_40R21', '235_50R24', '270_70R22', '430_50R38'};
            app.TiresA1DataDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1DataDropDownValueChanged, true);
            app.TiresA1DataDropDown.Value = '213_40R21';
            app.BodyLabel = uilabel(app.TiresAxle1Tab);
            app.BodyLabel.Text = 'Body';
            app.TiresA1BodyDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1BodyDropDown.Items = {'CAD', 'Generic'};
            app.TiresA1BodyDropDown.ItemsData = {'CAD', 'Generic'};
            app.TiresA1BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1BodyDropDownValueChanged, true);
            app.TiresA1BodyDropDown.Value = 'CAD';
            app.TireModelA1DropDownLabel = uilabel(app.TiresAxle1Tab);
            app.TireModelA1DropDownLabel.Text = 'Model';
            app.TiresA1ModelDropDown = uidropdown(app.TiresAxle1Tab);
            app.TiresA1ModelDropDown.Items = {'MFeval', 'Delft', 'MF-Swift', 'MFMbody', 'MFeval 2x', 'Delft 2x', 'MF-Swift 2x', 'MFMbody 2x'};
            app.TiresA1ModelDropDown.ItemsData = {'MFEval', 'Delft', 'MFSwift', 'MFMbody', 'MFEval_2x', 'Delft_2x', 'MFSwift_2x', 'MFMbody_2x'};
            app.TiresA1ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA1ModelDropDownValueChanged, true);
            app.TiresA1ModelDropDown.Value = 'MFEval';
            app.TiresAxle2Tab = uitab(app.TiresTabGroup);
            app.TiresAxle2Tab.Title = 'Axle 2';
            app.DynamicsLabel_2 = uilabel(app.TiresAxle2Tab);
            app.DynamicsLabel_2.Text = 'Dynamics';
            app.TiresA2DynamicsDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2DynamicsDropDown.Items = {'Steady-State', 'Linear Transients', 'Nonlinear Transients', 'Rigid Ring'};
            app.TiresA2DynamicsDropDown.ItemsData = {'steady', 'lintra', 'nonlin', 'ring'};
            app.TiresA2DynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2DynamicsDropDownValueChanged, true);
            app.TiresA2DynamicsDropDown.Value = 'steady';
            app.SlipLabel_3 = uilabel(app.TiresAxle2Tab);
            app.SlipLabel_3.Text = 'Slip';
            app.TiresA2SlipDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2SlipDropDown.Items = {'Longitudinal', 'Lateral', 'Uncombined', 'Combined', 'Combined+Turnslip', 'None'};
            app.TiresA2SlipDropDown.ItemsData = {'longitudinal', 'lateral', 'uncombined', 'combined', 'combined+turnslip', 'none'};
            app.TiresA2SlipDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2SlipDropDownValueChanged, true);
            app.TiresA2SlipDropDown.Value = 'combined';
            app.ContactLabel_3 = uilabel(app.TiresAxle2Tab);
            app.ContactLabel_3.Text = 'Contact';
            app.TiresA2ContactDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2ContactDropDown.Items = {'Smooth', 'Enveloping'};
            app.TiresA2ContactDropDown.ItemsData = {'smooth', 'enveloping'};
            app.TiresA2ContactDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2ContactDropDownValueChanged, true);
            app.TiresA2ContactDropDown.Value = 'smooth';
            app.OverrideApplyButtonTiresA2 = uibutton(app.TiresAxle2Tab, 'push');
            app.OverrideApplyButtonTiresA2.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonTiresA2Pushed, true);
            app.OverrideApplyButtonTiresA2.Tooltip = {'Overwrite TireA2 portion of Vehicle data structure to match selections.'};
            app.OverrideApplyButtonTiresA2.Text = 'Apply';
            app.SizeLabel_2 = uilabel(app.TiresAxle2Tab);
            app.SizeLabel_2.Text = 'Size';
            app.TiresA2DataDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2DataDropDown.Items = {'213-40R21', '235-50R24', '270-70R22', '430-50R38'};
            app.TiresA2DataDropDown.ItemsData = {'213_40R21', '235_50R24', '270_70R22', '430_50R38'};
            app.TiresA2DataDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2DataDropDownValueChanged, true);
            app.TiresA2DataDropDown.Value = '213_40R21';
            app.BodyLabel_2 = uilabel(app.TiresAxle2Tab);
            app.BodyLabel_2.Text = 'Body';
            app.TiresA2BodyDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2BodyDropDown.Items = {'CAD', 'Generic'};
            app.TiresA2BodyDropDown.ItemsData = {'CAD', 'Generic'};
            app.TiresA2BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2BodyDropDownValueChanged, true);
            app.TiresA2BodyDropDown.Value = 'CAD';
            app.TireModelA1DropDownLabel_2 = uilabel(app.TiresAxle2Tab);
            app.TireModelA1DropDownLabel_2.Text = 'Model';
            app.TiresA2ModelDropDown = uidropdown(app.TiresAxle2Tab);
            app.TiresA2ModelDropDown.Items = {'MFeval', 'Delft', 'MF-Swift', 'MFMbody', 'MFeval 2x', 'Delft 2x', 'MF-Swift 2x', 'MFMbody 2x'};
            app.TiresA2ModelDropDown.ItemsData = {'MFEval', 'Delft', 'MFSwift', 'MFMbody', 'MFEval_2x', 'Delft_2x', 'MFSwift_2x', 'MFMbody_2x'};
            app.TiresA2ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA2ModelDropDownValueChanged, true);
            app.TiresA2ModelDropDown.Value = 'MFEval';
            app.TiresAxle3Tab = uitab(app.TiresTabGroup);
            app.TiresAxle3Tab.Title = 'Axle 3';
            app.DynamicsLabel_3 = uilabel(app.TiresAxle3Tab);
            app.DynamicsLabel_3.Text = 'Dynamics';
            app.TiresA3DynamicsDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3DynamicsDropDown.Items = {'Steady-State', 'Linear Transients', 'Nonlinear Transients', 'Rigid Ring'};
            app.TiresA3DynamicsDropDown.ItemsData = {'steady', 'lintra', 'nonlin', 'ring'};
            app.TiresA3DynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3DynamicsDropDownValueChanged, true);
            app.TiresA3DynamicsDropDown.Value = 'steady';
            app.SlipLabel_4 = uilabel(app.TiresAxle3Tab);
            app.SlipLabel_4.Text = 'Slip';
            app.TiresA3SlipDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3SlipDropDown.Items = {'Longitudinal', 'Lateral', 'Uncombined', 'Combined', 'Combined+Turnslip', 'None'};
            app.TiresA3SlipDropDown.ItemsData = {'longitudinal', 'lateral', 'uncombined', 'combined', 'combined+turnslip', 'none'};
            app.TiresA3SlipDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3SlipDropDownValueChanged, true);
            app.TiresA3SlipDropDown.Value = 'combined';
            app.ContactLabel_4 = uilabel(app.TiresAxle3Tab);
            app.ContactLabel_4.Text = 'Contact';
            app.TiresA3ContactDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3ContactDropDown.Items = {'Smooth', 'Enveloping'};
            app.TiresA3ContactDropDown.ItemsData = {'smooth', 'enveloping'};
            app.TiresA3ContactDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3ContactDropDownValueChanged, true);
            app.TiresA3ContactDropDown.Value = 'smooth';
            app.OverrideApplyButtonTiresA3 = uibutton(app.TiresAxle3Tab, 'push');
            app.OverrideApplyButtonTiresA3.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonTiresA3Pushed, true);
            app.OverrideApplyButtonTiresA3.Tooltip = {'Overwrite TireA3 portion of Vehicle data structure to match selections.'};
            app.OverrideApplyButtonTiresA3.Text = 'Apply';
            app.SizeLabel_3 = uilabel(app.TiresAxle3Tab);
            app.SizeLabel_3.Text = 'Size';
            app.TiresA3DataDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3DataDropDown.Items = {'213-40R21', '235-50R24', '270-70R22', '430-50R38'};
            app.TiresA3DataDropDown.ItemsData = {'213_40R21', '235_50R24', '270_70R22', '430_50R38'};
            app.TiresA3DataDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3DataDropDownValueChanged, true);
            app.TiresA3DataDropDown.Value = '213_40R21';
            app.BodyLabel_3 = uilabel(app.TiresAxle3Tab);
            app.BodyLabel_3.Text = 'Body';
            app.TiresA3BodyDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3BodyDropDown.Items = {'CAD', 'Generic'};
            app.TiresA3BodyDropDown.ItemsData = {'CAD', 'Generic'};
            app.TiresA3BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3BodyDropDownValueChanged, true);
            app.TiresA3BodyDropDown.Value = 'CAD';
            app.TireModelA1DropDownLabel_3 = uilabel(app.TiresAxle3Tab);
            app.TireModelA1DropDownLabel_3.Text = 'Model';
            app.TiresA3ModelDropDown = uidropdown(app.TiresAxle3Tab);
            app.TiresA3ModelDropDown.Items = {'MFeval', 'Delft', 'MF-Swift', 'MFMbody', 'MFeval 2x', 'Delft 2x', 'MF-Swift 2x', 'MFMbody 2x'};
            app.TiresA3ModelDropDown.ItemsData = {'MFEval', 'Delft', 'MFSwift', 'MFMbody', 'MFEval_2x', 'Delft_2x', 'MFSwift_2x', 'MFMbody_2x'};
            app.TiresA3ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @TiresA3ModelDropDownValueChanged, true);
            app.TiresA3ModelDropDown.Value = 'MFEval';
            app.OverrideApplyButtonSuspensionA3 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSuspensionA3.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSuspensionA3Pushed, true);
            app.OverrideApplyButtonSuspensionA3.Tooltip = {'Overwrite SuspA3 portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSuspensionA3.Text = 'Apply';
            app.SuspensionA3DropDown = uidropdown(app.ConfigureTab);
            app.SuspensionA3DropDown.Items = {'S Double Wishbone', 'L Double Wisbone (A)', 'L Split Lower Arm Shock to Front', 'L Split Lower Arm Shock to Rear', 'L Five Link Shock to Front', 'L Five Link Shock to Rear', 'L Five Link Shock to Front (Constraints)', 'H Simple 15DOF', 'L Simple 15DOF', 'B Double Wishbone', 'H Live Axle', 'None', 'B3 15DOF', 'T Simple 15DOF'};
            app.SuspensionA3DropDown.ItemsData = {'DoubleWishboneA_Sedan_Hamba_r', 'DoubleWishboneA_Sedan_HambaLG_r', 'SplitLowerArmShockFront_Sedan_HambaLG_r', 'SplitLowerArmShockRear_Sedan_HambaLG_r', 'FiveLinkShockFront_Sedan_HambaLG_r', 'FiveLinkShockRear_Sedan_HambaLG_r', '5CS2LAF_SHL_r', 'Simple15DOF_Sedan_Hamba_r', 'Simple15DOF_Sedan_HambaLG_r', 'DoubleWishboneA_Bus_Makhulu_r', 'LiveAxle_Sedan_Hamba_r', 'None', 'Simple15DOF_Bus_Makhulu_Axle3_A3', 'Simple15DOF_Truck_Amandla_A3'};
            app.SuspensionA3DropDown.ValueChangedFcn = createCallbackFcn(app, @SuspensionA3DropDownValueChanged, true);
            app.SuspensionA3DropDown.Value = 'None';
            app.SuspA3Label = uilabel(app.ConfigureTab);
            app.SuspA3Label.Tooltip = {'Suspension Axle 3 (Type and Data)'; 'B3: Bus (3 Axles)'; 'T: Truck (3Axle)'};
            app.SuspA3Label.Text = 'Susp. A3';
            app.OverrideApplyButtonSteerA3 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonSteerA3.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonSteerA3Pushed, true);
            app.OverrideApplyButtonSteerA3.Tooltip = {'Overwrite SuspA3.Linkage portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonSteerA3.Text = 'Apply';
            app.SteerA3Label = uilabel(app.ConfigureTab);
            app.SteerA3Label.Tooltip = {'Steering Mechanism Axle 3'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.SteerA3Label.Text = 'Steer A3';
            app.SteerA3DropDown = uidropdown(app.ConfigureTab);
            app.SteerA3DropDown.Items = {'L Rack (Table)', 'None'};
            app.SteerA3DropDown.ItemsData = {'Rack_Sedan_HambaLG_r', 'None_default'};
            app.SteerA3DropDown.ValueChangedFcn = createCallbackFcn(app, @SteerA3DropDownValueChanged, true);
            app.SteerA3DropDown.Value = 'None_default';
            app.OverrideApplyButtonAntiRollA3 = uibutton(app.ConfigureTab, 'push');
            app.OverrideApplyButtonAntiRollA3.ButtonPushedFcn = createCallbackFcn(app, @OverrideApplyButtonAntiRollA3Pushed, true);
            app.OverrideApplyButtonAntiRollA3.Tooltip = {'Overwrite SuspA3.AntiRollBar portion of Vehicle data structure to match selection.'};
            app.OverrideApplyButtonAntiRollA3.Text = 'Apply';
            app.AntiRollA3Label = uilabel(app.ConfigureTab);
            app.AntiRollA3Label.Tooltip = {'Anti-Roll System Axle 3'; 'S: Sedan'; 'L: Large Sedan'; 'B: Bus'};
            app.AntiRollA3Label.Text = 'Anti Roll A3';
            app.AntiRollBarA3DropDown = uidropdown(app.ConfigureTab);
            app.AntiRollBarA3DropDown.Items = {'S Droplink', 'L Droplink', 'L Simple', 'None', 'B Droplink'};
            app.AntiRollBarA3DropDown.ItemsData = {'Droplink_Sedan_Hamba_r', 'Droplink_Sedan_HambaLG_r', 'Simple_default_r', 'None', 'Droplink_Bus_Makhulu_r'};
            app.AntiRollBarA3DropDown.ValueChangedFcn = createCallbackFcn(app, @AntiRollBarA3DropDownValueChanged, true);
            app.AntiRollBarA3DropDown.Value = 'None';
            app.InboardBushingsLabel = uilabel(app.ConfigureTab);
            app.InboardBushingsLabel.Text = 'Inboard Bushings';
            app.SuspA1UACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA1UACheckBox.Tooltip = {'Enable bushings on upper arm(s) for axle 1.'};
            app.SuspA1UACheckBox.Text = 'UA';
            app.SuspA1LACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA1LACheckBox.Tooltip = {'Enable bushings on lower arm(s) for axle 1.'};
            app.SuspA1LACheckBox.Text = 'LA';
            app.SuspA2UACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA2UACheckBox.Tooltip = {'Enable bushings on upper arm(s) for axle 2.'};
            app.SuspA2UACheckBox.Text = 'UA';
            app.SuspA2LACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA2LACheckBox.Tooltip = {'Enable bushings on lower arm(s) for axle 2.'};
            app.SuspA2LACheckBox.Text = 'LA';
            app.SuspA3UACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA3UACheckBox.Tooltip = {'Enable bushings on upper arm(s) for axle 3.'};
            app.SuspA3UACheckBox.Text = 'UA';
            app.SuspA3LACheckBox = uicheckbox(app.ConfigureTab);
            app.SuspA3LACheckBox.Tooltip = {'Enable bushings on lower arm(s) for axle 3.'};
            app.SuspA3LACheckBox.Text = 'LA';
            app.ARBA2BushingsCheckBox = uicheckbox(app.ConfigureTab);
            app.ARBA2BushingsCheckBox.Text = 'Bushings';
            app.ARBA1BushingsCheckBox = uicheckbox(app.ConfigureTab);
            app.ARBA1BushingsCheckBox.Text = 'Bushings';
            app.MatrixTab = uitab(app.TabGroup);
            app.MatrixTab.Title = 'Matrix';
            app.ConfigurationsButtonGroup = uibuttongroup(app.MatrixTab);
            app.ConfigurationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MatrixConfigSelectionChanged2, true);
            app.ConfigurationsButtonGroup.Tooltip = {'Presets'};
            app.ConfigurationsButtonGroup.Title = 'Configurations';
            app.Button = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button.Text = '000';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_2.Text = '001';
            app.Button_3 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_3.Text = '002';
            app.Button_4 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_4.Text = '003';
            app.Button_5 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_5.Text = '004';
            app.Button_6 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_6.Text = '005';
            app.Button_7 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_7.Text = '006';
            app.Button_8 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_8.Text = '007';
            app.Button_9 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_9.Text = '008';
            app.Button_10 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_10.Text = '009';
            app.Button_11 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_11.Text = '010';
            app.Button_12 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_12.Text = '011';
            app.Button_13 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_13.Text = '012';
            app.Button_14 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_14.Text = '013';
            app.Button_15 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_15.Text = '014';
            app.Button_16 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_16.Text = '015';
            app.Button_17 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_17.Text = '016';
            app.Button_18 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_18.Text = '017';
            app.Button_19 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_19.Text = '018';
            app.Button_20 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_20.Text = '019';
            app.Button_21 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_21.Text = '020';
            app.Button_22 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_22.Text = '021';
            app.Button_23 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_23.Text = '022';
            app.Button_24 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_24.Text = '023';
            app.Button_25 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_25.Text = '024';
            app.Button_26 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_26.Text = '025';
            app.Button_27 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_27.Text = '026';
            app.Button_28 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_28.Text = '027';
            app.Button_29 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_29.Text = '028';
            app.Button_30 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_30.Text = '029';
            app.Button_31 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_31.Text = '030';
            app.Button_32 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_32.Text = '031';
            app.Button_33 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_33.Text = '032';
            app.Button_34 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_34.Text = '033';
            app.Button_35 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_35.Text = '034';
            app.Button_36 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_36.Text = '035';
            app.Button_37 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_37.Text = '036';
            app.Button_38 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_38.Text = '037';
            app.Button_39 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_39.Text = '038';
            app.Button_40 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_40.Text = '039';
            app.Button_41 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_41.Text = '040';
            app.Button_42 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_42.Text = '041';
            app.Button_43 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_43.Text = '042';
            app.Button_44 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_44.Text = '043';
            app.Button_45 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_45.Text = '044';
            app.Button_46 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_46.Text = '045';
            app.Button_47 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_47.Text = '046';
            app.Button_48 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_48.Text = '047';
            app.Button_49 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_49.Text = '048';
            app.Button_50 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_50.Text = '049';
            app.Button_51 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_51.Text = '050';
            app.Button_52 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_52.Text = '051';
            app.Button_53 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_53.Text = '052';
            app.Button_54 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_54.Text = '053';
            app.Button_55 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_55.Text = '054';
            app.Button_56 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_56.Text = '055';
            app.Button_57 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_57.Text = '056';
            app.Button_58 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_58.Text = '057';
            app.Button_59 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_59.Text = '058';
            app.Button_60 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_60.Text = '059';
            app.Button_61 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_61.Text = '060';
            app.Button_62 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_62.Text = '061';
            app.Button_63 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_63.Text = '062';
            app.Button_64 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_64.Text = '063';
            app.Button_65 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_65.Text = '064';
            app.Button_66 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_66.Text = '065';
            app.Button_67 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_67.Text = '066';
            app.Button_68 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_68.Text = '067';
            app.Button_69 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_69.Text = '068';
            app.Button_70 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_70.Text = '069';
            app.Button_71 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_71.Text = '070';
            app.Button_72 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_72.Text = '071';
            app.Button_73 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_73.Text = '072';
            app.Button_74 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_74.Text = '073';
            app.Button_75 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_75.Text = '074';
            app.Button_76 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_76.Text = '075';
            app.Button_77 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_77.Text = '076';
            app.Button_78 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_78.Text = '077';
            app.Button_79 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_79.Text = '078';
            app.Button_80 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_80.Text = '079';
            app.Button_81 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_81.Text = '080';
            app.Button_82 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_82.Text = '081';
            app.Button_83 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_83.Text = '082';
            app.Button_84 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_84.Text = '083';
            app.Button_85 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_85.Text = '084';
            app.Button_86 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_86.Text = '085';
            app.Button_87 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_87.Text = '086';
            app.Button_88 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_88.Text = '087';
            app.Button_89 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_89.Text = '088';
            app.Button_90 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_90.Text = '089';
            app.Button_91 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_91.Text = '090';
            app.Button_92 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_92.Text = '091';
            app.Button_93 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_93.Text = '092';
            app.Button_94 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_94.Text = '093';
            app.Button_95 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_95.Text = '094';
            app.Button_96 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_96.Text = '095';
            app.Button_97 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_97.Text = '096';
            app.Button_98 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_98.Text = '097';
            app.Button_99 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_99.Text = '098';
            app.Button_100 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_100.Text = '099';
            app.Button_101 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_101.Text = '100';
            app.Button_102 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_102.Text = '101';
            app.Button_103 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_103.Text = '102';
            app.Button_104 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_104.Text = '103';
            app.Button_105 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_105.Text = '104';
            app.Button_106 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_106.Text = '105';
            app.Button_107 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_107.Text = '106';
            app.Button_108 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_108.Text = '107';
            app.Button_109 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_109.Text = '108';
            app.Button_110 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_110.Text = '109';
            app.Button_111 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_111.Text = '110';
            app.Button_112 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_112.Text = '111';
            app.Button_113 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_113.Text = '112';
            app.Button_114 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_114.Text = '113';
            app.Button_115 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_115.Text = '114';
            app.Button_116 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_116.Text = '115';
            app.Button_117 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_117.Text = '116';
            app.Button_118 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_118.Text = '117';
            app.Button_119 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_119.Text = '118';
            app.Button_120 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_120.Text = '119';
            app.Button_121 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_121.Text = '120';
            app.Button_122 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_122.Text = '121';
            app.Button_123 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_123.Text = '122';
            app.Button_124 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_124.Text = '123';
            app.Button_125 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_125.Text = '124';
            app.Button_126 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_126.Text = '125';
            app.Button_127 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_127.Text = '126';
            app.Button_128 = uiradiobutton(app.ConfigurationsButtonGroup);
            app.Button_128.Text = '127';
            app.MS1Label = uilabel(app.MatrixTab);

            app.MS1Label.Text = 'MS1';
            app.MSDLabel = uilabel(app.MatrixTab);
            app.MSDLabel.Text = 'MSD';
            app.MS3Label = uilabel(app.MatrixTab);
            app.MS3Label.Text = 'MS3';
            app.MSFLabel = uilabel(app.MatrixTab);
            app.MSFLabel.Text = 'MSF';
            app.ML1Label = uilabel(app.MatrixTab);
            app.ML1Label.Text = 'ML1';
            app.MLDLabel = uilabel(app.MatrixTab);
            app.MLDLabel.Text = 'MLD';
            app.ML3Label = uilabel(app.MatrixTab);
            app.ML3Label.Text = 'ML3';
            app.MLFLabel = uilabel(app.MatrixTab);
            app.MLFLabel.Text = 'MLF';
            app.SS1Label = uilabel(app.MatrixTab);
            app.SS1Label.Text = 'SS1';
            app.SSDLabel = uilabel(app.MatrixTab);
            app.SSDLabel.Text = 'SSD';
            app.SS3Label = uilabel(app.MatrixTab);
            app.SS3Label.Text = 'SS3';
            app.SSFLabel = uilabel(app.MatrixTab);
            app.SSFLabel.Text = 'SSF';
            app.SL1Label = uilabel(app.MatrixTab);
            app.SL1Label.Text = 'SL1';
            app.SLDLabel = uilabel(app.MatrixTab);
            app.SLDLabel.Text = 'SLD';
            app.SL3Label = uilabel(app.MatrixTab);
            app.SL3Label.Text = 'SL3';
            app.SLFLabel = uilabel(app.MatrixTab);
            app.SLFLabel.Text = 'SLF';
            app.DWLabel = uilabel(app.MatrixTab);
            app.DWLabel.Text = 'DW';
            app.DWaLabel = uilabel(app.MatrixTab);
            app.DWaLabel.Tooltip = {'Double wishbone suspension with no steering possible (upright cannot rotate about its vertical axis).'};
            app.DWaLabel.Text = 'DWa';
            app.S2LAFLabel = uilabel(app.MatrixTab);
            app.S2LAFLabel.Tooltip = {'Split lower arm with shock attached to front lower arm.'};
            app.S2LAFLabel.Text = 'S2LAF';
            app.S2LARLabel = uilabel(app.MatrixTab);
            app.S2LARLabel.Tooltip = {'Split lower arm with shock attached to lower rear arm.'};
            app.S2LARLabel.Text = 'S2LAR';
            app.S25LAFLabel = uilabel(app.MatrixTab);
            app.S25LAFLabel.Tooltip = {'5 link suspension with shock attached to lower front arm.'};
            app.S25LAFLabel.Text = '5S2LAF';
            app.S25LARLabel = uilabel(app.MatrixTab);
            app.S25LARLabel.Tooltip = {'5 link suspension with shock attached to lower rear arm.'};
            app.S25LARLabel.Text = '5S2LAR';
            app.CS52LAFLabel = uilabel(app.MatrixTab);
            app.CS52LAFLabel.Tooltip = {'5 link suspension with shock attached to lower front arm.  Suspension implemented with constraints resulting in fewer states.'};
            app.CS52LAFLabel.Text = '5CS2LAF';
            app.DOFLabel = uilabel(app.MatrixTab);
            app.DOFLabel.Tooltip = {'Simple suspension resulting in a 15 degree of freedom model.  Axles have heave and roll degrees of freedom only.'};
            app.DOFLabel.Text = '15DOF';
            app.HelpLabelMatrix = uilabel(app.MatrixTab);
            app.HelpLabelMatrix.Tooltip = {'Selecting a preset below updates the Vehicle data structure in the MATLAB workspace and triggers variant selection in the model.  '; ''; 'Columns are for front suspension option. '; 'Rows are for tire model | tire options | driveline options.'};
            app.HelpLabelMatrix.Text = '(?)';
            app.SelectaradiobuttonbelowforaspecificdrivelineandtirepresetLabel = uilabel(app.MatrixTab);
            app.SelectaradiobuttonbelowforaspecificdrivelineandtirepresetLabel.Text = {'Select a radio button below for a specific driveline, tire,'; 'and suspension preset. For testing specific configurations.'};
            app.AbbreviationLabels = uilabel(app.MatrixTab);
            app.AbbreviationLabels.Text = 'MF / Swift      Steady State / Linear Transient    1D/1D3D/3D/3DFlex';
            app.RowabbreviationindicatestireanddrivelineconfigurationLabel = uilabel(app.MatrixTab);
            app.RowabbreviationindicatestireanddrivelineconfigurationLabel.Text = 'Row abbreviation indicates tire and driveline configuration';
            app.EventTab = uitab(app.TabGroup);
            app.EventTab.Title = 'Event';
            app.ManeuverLabel = uilabel(app.EventTab);
            app.ManeuverLabel.Text = 'Maneuver';
            app.ManeuverDropDown = uidropdown(app.EventTab);
            app.ManeuverDropDown.Items = {'WOT Braking', 'Low-Speed Steer', 'Turn', 'Ice Patch', 'RDF Rough Road', 'Rough Road Z Only', 'CRG Rough Road', 'GS Uneven Road', 'RDF Plateau', 'Plateau Z Only', 'CRG Plateau', 'Straight Constant Speed', 'Constant Radius', 'Trailer Disturbance', 'Double Lane Change', 'Double Lane Ch. ISO 3888', 'Slalom', 'Fishhook', 'Sine With Dwell', 'Ramp Steer', 'Skidpad', 'Testrig 4 Post', 'Kinematics and Compliance', 'Mallory Park', 'Mallory Park CCW', 'Mallory Park Obstacle', 'MCity', 'CRG Mallory Park', 'CRG Mallory Park (flat)', 'CRG Hockenheim', 'CRG Hockenheim (flat)', 'CRG Kyalami', 'CRG Kyalami (flat)', 'CRG Nurburgring N', 'CRG Nurburgring N (flat)', 'CRG Suzuka', 'CRG Suzuka (flat)', 'CRG Pikes Peak (up)', 'CRG Pikes Peak (down)', 'CRG Custom', 'CRG Custom (flat)', 'Drive Cycle FTP75', 'Drive Cycle UrbanCycle1'};
            app.ManeuverDropDown.ItemsData = {'WOT Braking', 'Low Speed Steer', 'Turn', 'Ice Patch', 'RDF Rough Road', 'Rough Road Z Only', 'CRG Rough Road', 'GS Uneven Road', 'RDF Plateau', 'Plateau Z Only', 'CRG Plateau', 'Straight Constant Speed', 'Constant Radius Closed-Loop', 'Trailer Disturbance', 'Double Lane Change', 'Double Lane Change ISO3888', 'Slalom', 'Fishhook', 'Sine With Dwell', 'Ramp Steer', 'Skidpad', 'Testrig 4 Post', 'KnC', 'Mallory Park', 'Mallory Park CCW', 'Mallory Park Obstacle', 'MCity', 'CRG Mallory Park', 'CRG Mallory Park F', 'CRG Hockenheim', 'CRG Hockenheim F', 'CRG Kyalami', 'CRG Kyalami F', 'CRG Nurburgring N', 'CRG Nurburgring N F', 'CRG Suzuka', 'CRG Suzuka F', 'CRG Pikes Peak', 'CRG Pikes Peak Down', 'CRG Custom', 'CRG Custom F', 'Drive Cycle FTP75', 'Drive Cycle UrbanCycle1'};
            app.ManeuverDropDown.ValueChangedFcn = createCallbackFcn(app, @ManeuverDropDownValueChanged, true);
            app.ManeuverDropDown.Tooltip = {'Sets surface.  Sets initial state for vehicle and trailer if present.  Sets driver type.'};
            app.ManeuverDropDown.Value = 'WOT Braking';
            app.SurfaceDropDownLabel = uilabel(app.EventTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.EventTab);
            app.SurfaceDropDown.Items = {'Plane Grid', 'Road Two Lane', 'Double Lane Change', 'Ice Patch', 'CRG Slope', 'Track Mallory Park'};
            app.SurfaceDropDown.ItemsData = {'Plane Grid', 'Road Two Lane', 'Double Lane Change', 'Ice Patch', 'CRG Slope', 'Track Mallory Park'};
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SurfaceDropDown.Tooltip = {'Selects visualized surface in the model.  Certain surfaces will modify tire parameters.'};
            app.SurfaceDropDown.Value = 'Plane Grid';
            app.SolverDropDownLabel = uilabel(app.EventTab);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.EventTab);
            app.SolverDropDown.Items = {'Variable Step', 'Fixed Step', 'Fixed Step (Simulink Real-Time)'};
            app.SolverDropDown.ItemsData = {'Variable Step', 'Fixed Step', 'Fixed Step slrt'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Tooltip = {'Configures solvers for variable/fixed step simulation.'};
            app.SolverDropDown.Value = 'Variable Step';
            app.PlotManeuverButton = uibutton(app.EventTab, 'push');
            app.PlotManeuverButton.ButtonPushedFcn = createCallbackFcn(app, @PlotManeuverButtonPushed, true);
            app.PlotManeuverButton.Tooltip = {'Plot inputs to driver (open loop commands or trajectory).'};
            app.PlotManeuverButton.Text = 'Plot Maneuver';
            app.PointLabelIntervalEditFieldLabel = uilabel(app.EventTab);
            app.PointLabelIntervalEditFieldLabel.Text = 'Point Label Interval';
            app.PointLabelIntervalEditField = uieditfield(app.EventTab, 'numeric');
            app.PointLabelIntervalEditField.Tooltip = {'Control number of labeled points on maneuver plot.'};
            app.DriverDropDownLabel = uilabel(app.EventTab);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.EventTab);
            app.DriverDropDown.Items = {'Open Loop', 'Closed Loop', 'Interactive'};
            app.DriverDropDown.ItemsData = {'Open Loop', 'Closed Loop', 'Interactive'};
            app.DriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DriverDropDownValueChanged, true);
            app.DriverDropDown.Tooltip = {'Configure driver for open or closed loop behavior.'};
            app.DriverDropDown.Value = 'Open Loop';
            app.ShowPaceCarInteractiveCheckBox = uicheckbox(app.EventTab);
            app.ShowPaceCarInteractiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPaceCarInteractiveCheckBoxValueChanged, true);
            app.ShowPaceCarInteractiveCheckBox.Tooltip = {'Enable pace car to be shown when interactive driving is selected.'};
            app.ShowPaceCarInteractiveCheckBox.Text = 'Show Pace Car (Interactive)';
            app.IndividualSettingsLabel = uilabel(app.EventTab);
            app.IndividualSettingsLabel.Text = 'Individual Settings';
            app.ManeuverCodeButton = uibutton(app.EventTab, 'push');
            app.ManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverCodeButtonPushed, true);
            app.ManeuverCodeButton.Tooltip = {'Edit code that configures workspace and model for maneuver.'};
            app.ManeuverCodeButton.Text = 'Code';
            app.PlotManeuverCodeButton = uibutton(app.EventTab, 'push');
            app.PlotManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotManeuverCodeButtonPushed, true);
            app.PlotManeuverCodeButton.Tooltip = {'Edit code that plots inputs for maneuver.'};
            app.PlotManeuverCodeButton.Text = 'Code';
            app.SurfaceCodeButton = uibutton(app.EventTab, 'push');
            app.SurfaceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceCodeButtonPushed, true);
            app.SurfaceCodeButton.Tooltip = {'Edit code that configures the scene.'};
            app.SurfaceCodeButton.Text = 'Code';
            app.SolverCodeButton = uibutton(app.EventTab, 'push');
            app.SolverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SolverCodeButtonPushed, true);
            app.SolverCodeButton.Tooltip = {'Edit code that configures solver settings.'};
            app.SolverCodeButton.Text = 'Code';
            app.WindLabel = uilabel(app.EventTab);
            app.WindLabel.Text = 'Wind';
            app.WindCarCheckBox = uicheckbox(app.EventTab);
            app.WindCarCheckBox.Tooltip = {'Enables gust of wind to car.  Press apply to configure the model.'};
            app.WindCarCheckBox.Text = 'Car';
            app.WindTrailerCheckBox = uicheckbox(app.EventTab);
            app.WindTrailerCheckBox.Tooltip = {'Enables gust of wind to trailer.  Press apply to configure the model.'};
            app.WindTrailerCheckBox.Text = 'Trailer';
            app.OverrideWindButton = uibutton(app.EventTab, 'push');
            app.OverrideWindButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideWindButtonPushed, true);
            app.OverrideWindButton.Tooltip = {'Enable/Disable gust of wind applied to trailer and car per checkboxes.'};
            app.OverrideWindButton.Text = 'Apply';
            app.WindCodeButton = uibutton(app.EventTab, 'push');
            app.WindCodeButton.ButtonPushedFcn = createCallbackFcn(app, @WindCodeButtonPushed, true);
            app.WindCodeButton.Tooltip = {'Edit code that configures settings for wind in the model.'};
            app.WindCodeButton.Text = 'Code';
            app.SelectManeuverLabel = uilabel(app.EventTab);
            app.SelectManeuverLabel.Text = 'Select Maneuver';
            app.DefineNewTrajectoryButton = uibutton(app.EventTab, 'push');
            app.DefineNewTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DefineNewTrajectoryButtonPushed, true);
            app.DefineNewTrajectoryButton.Enable = 'off';
            app.DefineNewTrajectoryButton.Tooltip = {'(MCity maneuver only R2020 and higher) Use UI to define new maneuver.'};
            app.DefineNewTrajectoryButton.Text = 'Define New Trajectory';
            app.UnrealEngineAnimationCheckBox = uicheckbox(app.EventTab);
            app.UnrealEngineAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnrealEngineAnimationCheckBoxValueChanged, true);
            app.UnrealEngineAnimationCheckBox.Tooltip = {'Enable animation in Unreal Engine during simulation of main model sm_car.'};
            app.UnrealEngineAnimationCheckBox.Text = 'Unreal Engine Animation';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PositionandSpeedButton = uibutton(app.ResultsTab, 'push');
            app.PositionandSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @PositionandSpeedButtonPushed, true);
            app.PositionandSpeedButton.Tooltip = {'Plots vehicle position and speed.'};
            app.PositionandSpeedButton.Text = 'Position and Speed';
            app.PlotsLabel = uilabel(app.ResultsTab);
            app.PlotsLabel.Text = 'Plots';
            app.WheelSpeedsButton = uibutton(app.ResultsTab, 'push');
            app.WheelSpeedsButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedsButtonPushed, true);
            app.WheelSpeedsButton.Tooltip = {'Plot of vehicle wheel speeds.'};
            app.WheelSpeedsButton.Text = 'Wheel Speeds';
            app.ManeuverButton = uibutton(app.ResultsTab, 'push');
            app.ManeuverButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverButtonPushed, true);
            app.ManeuverButton.Tooltip = {'Open-loop maneuver: plots pedal and steering measurements over commands.  '; 'Closed-loop maneuver: compares path velocity and yaw angle to desired trajectory'};
            app.ManeuverButton.Text = 'Maneuver';
            app.ReplayinUnrealButton = uibutton(app.ResultsTab, 'push');
            app.ReplayinUnrealButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayinUnrealButtonPushed, true);
            app.ReplayinUnrealButton.Tooltip = {'Open model for replaying simulation results in Unreal animation.'};
            app.ReplayinUnrealButton.Text = 'Replay in Unreal';
            app.AnimationLabel = uilabel(app.ResultsTab);
            app.AnimationLabel.Text = 'Animation';
            app.PositionandSpeedCodeButton = uibutton(app.ResultsTab, 'push');
            app.PositionandSpeedCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionandSpeedCodeButtonPushed, true);
            app.PositionandSpeedCodeButton.Tooltip = {'Edit code that plots position'; ' speed'; ' and other measures.'};
            app.PositionandSpeedCodeButton.Text = 'Code';
            app.WheelSpeedsCodeButton = uibutton(app.ResultsTab, 'push');
            app.WheelSpeedsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @WheelSpeedsCodeButtonPushed, true);
            app.WheelSpeedsCodeButton.Tooltip = {'Edit code that plots wheel speeds.'};
            app.WheelSpeedsCodeButton.Text = 'Code';
            app.ResultsManeuverCodeButton = uibutton(app.ResultsTab, 'push');
            app.ResultsManeuverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsManeuverCodeButtonPushed, true);
            app.ResultsManeuverCodeButton.Tooltip = {'Edit code that plots results of maneuver.'};
            app.ResultsManeuverCodeButton.Text = 'Code';
            app.BodyMeasurementsButton = uibutton(app.ResultsTab, 'push');
            app.BodyMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @BodyMeasurementsButtonPushed, true);
            app.BodyMeasurementsButton.Tooltip = {'Plots body roll pitch and yaw.'};
            app.BodyMeasurementsButton.Text = 'Body Measurements';
            app.BodyMeasurementsCodeButton = uibutton(app.ResultsTab, 'push');
            app.BodyMeasurementsCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BodyMeasurementsCodeButtonPushed, true);
            app.BodyMeasurementsCodeButton.Tooltip = {'Edit code that plots body measurements.'};
            app.BodyMeasurementsCodeButton.Text = 'Code';
            app.SimscapeResultsExplorerButton = uibutton(app.ResultsTab, 'push');
            app.SimscapeResultsExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @SimscapeResultsExplorerButtonPushed, true);
            app.SimscapeResultsExplorerButton.Tooltip = {'Open Simscape Results Explorer with results of last simulation.'};
            app.SimscapeResultsExplorerButton.Text = 'Simscape Results Explorer';
            app.SimulationDataInspectorButton = uibutton(app.ResultsTab, 'push');
            app.SimulationDataInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationDataInspectorButtonPushed, true);
            app.SimulationDataInspectorButton.Tooltip = {'Open Simulation Data Inspector.'};
            app.SimulationDataInspectorButton.Text = 'Simulation Data Inspector';
            app.PowertrainCurrentButton = uibutton(app.ResultsTab, 'push');
            app.PowertrainCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PowertrainCurrentButtonPushed, true);
            app.PowertrainCurrentButton.Tooltip = {'Plots currents from electrical power supplies.'};
            app.PowertrainCurrentButton.Text = 'Powertrain Current';
            app.PowertrainCurrentCodeButton = uibutton(app.ResultsTab, 'push');
            app.PowertrainCurrentCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PowertrainCurrentCodeButtonPushed, true);
            app.PowertrainCurrentCodeButton.Tooltip = {'Edit code that plots electric currents.'};
            app.PowertrainCurrentCodeButton.Text = 'Code';
            app.TiresForcesTorquesCodeButton = uibutton(app.ResultsTab, 'push');
            app.TiresForcesTorquesCodeButton.ButtonPushedFcn = createCallbackFcn(app, @TiresForcesTorquesCodeButtonPushed, true);
            app.TiresForcesTorquesCodeButton.Tooltip = {'Edit code that plots tire forces and torques.'};
            app.TiresForcesTorquesCodeButton.Text = 'Code';
            app.TireForcesTorquesButton = uibutton(app.ResultsTab, 'push');
            app.TireForcesTorquesButton.ButtonPushedFcn = createCallbackFcn(app, @TireForcesTorquesButtonPushed, true);
            app.TireForcesTorquesButton.Tooltip = {'Plot forces and torques at tire contact point with road.'};
            app.TireForcesTorquesButton.Text = 'Tire Forces & Torques';
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.RegenerateVehiclePresetsButton = uibutton(app.MainTab, 'push');
            app.RegenerateVehiclePresetsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateVehiclePresetsButtonPushed, true);
            app.RegenerateVehiclePresetsButton.Tooltip = {'Create Vehicle_###.mat files containing preset configurations.'};
            app.RegenerateVehiclePresetsButton.Text = 'Regenerate';
            app.RegenerateVehicleDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateVehicleDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateVehicleDatabaseButtonPushed, true);
            app.RegenerateVehicleDatabaseButton.Tooltip = {'Create VDatabase structure with all options for Vehicle model by reloading all Excel files (unchanged, modified, and new).'};
            app.RegenerateVehicleDatabaseButton.Text = 'Regenerate';
            app.RegenerateManueverDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateManueverDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateManueverDatabaseButtonPushed, true);
            app.RegenerateManueverDatabaseButton.Tooltip = {'Create MDatabase with settings for vehicle manuevers by running MATLAB code.'};
            app.RegenerateManueverDatabaseButton.Text = 'Regenerate';
            app.RegenerateDatabasesinMATLABWorkspaceLabel = uilabel(app.MainTab);
            app.RegenerateDatabasesinMATLABWorkspaceLabel.Text = 'Regenerate Databases in MATLAB Workspace';
            app.RegenerateInitDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateInitDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateInitDatabaseButtonPushed, true);
            app.RegenerateInitDatabaseButton.Tooltip = {'Create IDatabase structure with possible initial conditions for vehicle by running MATLAB code.'};
            app.RegenerateInitDatabaseButton.Text = 'Regenerate';
            app.RegenerateDriverDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateDriverDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateDriverDatabaseButtonPushed, true);
            app.RegenerateDriverDatabaseButton.Tooltip = {'Create DDatabase structure with parameters for driver models by running MATLAB code.'};
            app.RegenerateDriverDatabaseButton.Text = 'Regenerate';
            app.RegenerateSceneDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateSceneDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateSceneDatabaseButtonPushed, true);
            app.RegenerateSceneDatabaseButton.Tooltip = {'Create Scene structure with data for scene elements.'};
            app.RegenerateSceneDatabaseButton.Text = 'Regenerate';
            app.RegenerateCameraDatabaseButton = uibutton(app.MainTab, 'push');
            app.RegenerateCameraDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateCameraDatabaseButtonPushed, true);
            app.RegenerateCameraDatabaseButton.Tooltip = {'Create CDatabase structure with viewpoint locations in 3D animation by running MATLAB code.'};
            app.RegenerateCameraDatabaseButton.Text = 'Regenerate';
            app.VehicleDatabaseLabel = uilabel(app.MainTab);
            app.VehicleDatabaseLabel.Text = 'Vehicle Database';
            app.VehiclePresetsLabel = uilabel(app.MainTab);
            app.VehiclePresetsLabel.Text = 'Vehicle Presets';
            app.ManeuverDatabaseLabel = uilabel(app.MainTab);
            app.ManeuverDatabaseLabel.Text = 'Maneuver Database';
            app.InitDatabaseLabel = uilabel(app.MainTab);
            app.InitDatabaseLabel.Text = 'Init Database';
            app.DriverDatabaseLabel = uilabel(app.MainTab);
            app.DriverDatabaseLabel.Text = 'Driver Database';
            app.SceneDatabaseLabel = uilabel(app.MainTab);
            app.SceneDatabaseLabel.Text = 'Scene Database';
            app.CameraDatabaseLabel = uilabel(app.MainTab);
            app.CameraDatabaseLabel.Text = 'Camera Database';
            app.VehiclePresetsFileButton = uibutton(app.MainTab, 'push');
            app.VehiclePresetsFileButton.ButtonPushedFcn = createCallbackFcn(app, @VehiclePresetsFileButtonPushed, true);
            app.VehiclePresetsFileButton.Tooltip = {'Edit code that generates presets.'};
            app.VehiclePresetsFileButton.Text = 'Code';
            app.ManeuverDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.ManeuverDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ManeuverDatabaseFileButtonPushed, true);
            app.ManeuverDatabaseFileButton.Tooltip = {'Open Excel file with maneuver settings.'};
            app.ManeuverDatabaseFileButton.Text = 'Code';
            app.InitDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.InitDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @InitDatabaseFileButtonPushed, true);
            app.InitDatabaseFileButton.Tooltip = {'Open MATLAB code with initial vehicle conditions.'};
            app.InitDatabaseFileButton.Text = 'Code';
            app.DriverDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.DriverDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @DriverDatabaseFileButtonPushed, true);
            app.DriverDatabaseFileButton.Tooltip = {'Open MATLAB code with driver model options.'};
            app.DriverDatabaseFileButton.Text = 'Code';
            app.SceneDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.SceneDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @SceneDatabaseFileButtonPushed, true);
            app.SceneDatabaseFileButton.Tooltip = {'Edit code that loads scene data.'};
            app.SceneDatabaseFileButton.Text = 'Code';
            app.CameraDatabaseFileButton = uibutton(app.MainTab, 'push');
            app.CameraDatabaseFileButton.ButtonPushedFcn = createCallbackFcn(app, @CameraDatabaseFileButtonPushed, true);
            app.CameraDatabaseFileButton.Tooltip = {'Display list of MATLAB files with camera locations and orientations to MATLAB Command WIndow.'};
            app.CameraDatabaseFileButton.Text = 'Code';
            app.HelpLabelMain = uilabel(app.MainTab);
            app.HelpLabelMain.Tooltip = {'Buttons to reload databases from source (Excel or other).  '; ''; 'After retrieving a new update of files it is recommended to press the "Regenerate All" button to ensure the workspace data is up to date.'};
            app.HelpLabelMain.Text = '(?)';
            app.RegenerateAllDatabasesButton = uibutton(app.MainTab, 'push');
            app.RegenerateAllDatabasesButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateAllDatabasesButtonPushed, true);
            app.RegenerateAllDatabasesButton.Tooltip = {'Regenerate all databases from source (Excel or other) and update .mat files.'};
            app.RegenerateAllDatabasesButton.Text = 'Regenerate All';
            app.RegenerateAllDatabasesFileButton = uibutton(app.MainTab, 'push');
            app.RegenerateAllDatabasesFileButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateAllDatabasesFileButtonPushed, true);
            app.RegenerateAllDatabasesFileButton.Tooltip = {'Edit code that regenerates all databases.'};
            app.RegenerateAllDatabasesFileButton.Text = 'Code';
            app.TestModelsLabel = uilabel(app.MainTab);
            app.TestModelsLabel.Text = 'Test Models';
            app.FuelCellVehicle1DButton = uibutton(app.MainTab, 'push');
            app.FuelCellVehicle1DButton.ButtonPushedFcn = createCallbackFcn(app, @FuelCellVehicle1DButtonPushed, true);
            app.FuelCellVehicle1DButton.Tooltip = {'Opens test model ssc_car_FuelCell1Motor.slx'};
            app.FuelCellVehicle1DButton.Text = 'Fuel Cell Vehicle (1D)';
            app.VehicleDataCheckLabel = uilabel(app.MainTab);
            app.VehicleDataCheckLabel.Text = 'Vehicle Data Check';
            app.ConsistentHPsButton = uibutton(app.MainTab, 'push');
            app.ConsistentHPsButton.ButtonPushedFcn = createCallbackFcn(app, @ConsistentHPsButtonPushed, true);
            app.ConsistentHPsButton.Tooltip = {'Runs code to check consistency of critical parameters.'};
            app.ConsistentHPsButton.Text = 'Consistent HPs';
            app.ConsistentHPsFileButton = uibutton(app.MainTab, 'push');
            app.ConsistentHPsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ConsistentHPsFileButtonPushed, true);
            app.ConsistentHPsFileButton.Tooltip = {'Edit code that checks parameter consistency.'};
            app.ConsistentHPsFileButton.Text = 'Code';
            app.SimscapeVehicleTemplateConfigurationLabel = uilabel(app.SimscapeVehicleTemplatesUIFigure);
            app.SimscapeVehicleTemplateConfigurationLabel.Text = 'Simscape Vehicle Template Configuration';
            app.ConfigureModelButton = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.ConfigureModelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureModelButtonPushed, true);
            app.ConfigureModelButton.Tooltip = {'Trigger variant selection in model.'};
            app.ConfigureModelButton.Text = {'Configure'; 'Model'};
            app.LoadVDatabase = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.LoadVDatabase.ButtonPushedFcn = createCallbackFcn(app, @LoadVDatabaseButtonPushed, true);
            app.LoadVDatabase.Tooltip = {'Update VDatabase by loading only Excel files that have been modified since VDatabase was last created.'};
            app.LoadVDatabase.Text = {'Update Vehicle'; 'Database'};
            app.ListVehicleConfigurationButton = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.ListVehicleConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ListVehicleConfigurationButtonPushed, true);
            app.ListVehicleConfigurationButton.Tooltip = {'Display instances from Vehicle data structure to Command window.'};
            app.ListVehicleConfigurationButton.Text = {'List Vehicle'; 'Configuration'};
            app.OpenModelButton = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Tooltip = {'Open the main model.'};
            app.OpenModelButton.Text = {'Open'; 'Model'};
            app.MainModelEditFieldLabel = uilabel(app.SimscapeVehicleTemplatesUIFigure);
            app.MainModelEditFieldLabel.Text = 'Main Model';
            app.MainModelEditField = uieditfield(app.SimscapeVehicleTemplatesUIFigure, 'text');
            app.MainModelEditField.Value = 'sm_car';
            app.SelectMainModelButton = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.SelectMainModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainModelButtonPushed, true);
            app.SelectMainModelButton.Tooltip = {'Open dialog to select Main Model.'};
            app.SelectMainModelButton.Text = 'Select';
            app.OpenMainModelButton = uibutton(app.SimscapeVehicleTemplatesUIFigure, 'push');
            app.OpenMainModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMainModelButtonPushed, true);
            app.OpenMainModelButton.Tooltip = {'Open model named in Main Model field'};
            app.OpenMainModelButton.Text = 'Open';
            app.Label = uilabel(app.SimscapeVehicleTemplatesUIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.SimscapeVehicleTemplatesUIFigure);
            app.DropDown.Items = {'2 Axle', '3 Axle'};
            app.DropDown.ItemsData = {'Axle2', 'Axle3'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'Set Main Model field to name Simulink model file.'};
            app.DropDown.Value = 'Axle2';
            % Show the figure after all components are created
            app.SimscapeVehicleTemplatesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sm_car_vehcfg

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SimscapeVehicleTemplatesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SimscapeVehicleTemplatesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimscapeVehicleTemplatesUIFigure)
        end
    end
end