classdef sm_car_plot3d_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VehicleResults3DPlotterApp  matlab.ui.Figure
        HelpButton                  matlab.ui.control.Button
        BodyDropDown                matlab.ui.control.DropDown
        BodyDropDownLabel           matlab.ui.control.Label
        SaveGIFButton               matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        TimePanel                   matlab.ui.container.Panel
        ofEventSlider               matlab.ui.control.Slider
        ofEventSliderLabel          matlab.ui.control.Label
        StepSizeEditField           matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel      matlab.ui.control.Label
        InterpolateResultsCheckBox  matlab.ui.control.CheckBox
        StopsecEditField            matlab.ui.control.NumericEditField
        StopsecEditFieldLabel       matlab.ui.control.Label
        StartsecEditField           matlab.ui.control.NumericEditField
        StartsecEditFieldLabel      matlab.ui.control.Label
        PlotElementsPanel           matlab.ui.container.Panel
        ViewWidthmEditField         matlab.ui.control.NumericEditField
        ViewWidthmEditFieldLabel    matlab.ui.control.Label
        DViewCheckBox               matlab.ui.control.CheckBox
        TireForcesCheckBox          matlab.ui.control.CheckBox
        PatchCheckBox               matlab.ui.control.CheckBox
        VehicleBodyCheckBox         matlab.ui.control.CheckBox
        SelectButton                matlab.ui.control.Button
        ResultstoPlotButtonGroup    matlab.ui.container.ButtonGroup
        FileButton                  matlab.ui.control.RadioButton
        WorkspaceButton             matlab.ui.control.RadioButton
        ResultsDataEditField        matlab.ui.control.EditField
        VehicleSimulationResultsPlotterLabel  matlab.ui.control.Label
        ShowResultsButton           matlab.ui.control.Button
        SceneDropDown               matlab.ui.control.DropDown
        SceneDropDownLabel          matlab.ui.control.Label
        ResultsUIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Scene     = evalin('base','Scene');
            MDatabase = evalin('base','MDatabase');

            Scene3DPlot = sm_car_plot3d_get_scene(Scene,MDatabase);
            sceneNames = fieldnames(Scene3DPlot);
            sceneNames{end+1} = '(from workspace/file)';
            sceneNames = circshift(sceneNames,1);
            app.SceneDropDown.Items = sceneNames;
            app.SceneDropDown.ItemsData = sceneNames;
            %assignin('base','SDDItems',app.SceneDropDown.Items);
            %assignin('base','SDDItemsData',app.SceneDropDown.ItemsData);

        end

        % Button pushed function: ShowResultsButton
        function ShowResultsButtonPushed(app, event)
            resultsData = app.ResultsDataEditField.Value;
            plot3DView  = app.DViewCheckBox.Value;
            plotVehicleBody = app.VehicleBodyCheckBox.Value;
            plotPatch  = app.PatchCheckBox.Value;
            plotTireForces = app.TireForcesCheckBox.Value;
            timeStart   = app.StartsecEditField.Value;
            timeStop    = app.StopsecEditField.Value;
            sceneName   = char(app.SceneDropDown.Value);
            bodyName   = char(app.BodyDropDown.Value);
            boxWidth    = app.ViewWidthmEditField.Value; 
            if(app.InterpolateResultsCheckBox.Value)
                tSamp = app.StepSizeEditField.Value;
            else
                tSamp = 'none';
            end

            % Prepare strings for echoed command to workspace
            if ~ischar(tSamp)
                tSampStr = num2str(tSamp);
            else
                tSampStr = ['''' tSamp ''''];
            end

            if(~endsWith(app.ResultsDataEditField.Value,'.mat'))
                % Obtain data from workspace, pass data to function
                logsout = evalin('base',app.ResultsDataEditField.Value);
                resultsDataStr = app.ResultsDataEditField.Value;
            else
                % Pass name of file to function - must be string
                logsout = app.ResultsDataEditField.Value;
                resultsDataStr = ['''' app.ResultsDataEditField.Value ''''];
            end

            saveGIF = false;
            axH = app.ResultsUIAxes;
            if(isprop(event.Source,'Text'))
                if(strcmp(event.Source.Text,'Save GIF'))
                    saveGIF = true;
                    figure;
                    axH = gca;
                else
                    cla(app.ResultsUIAxes)
                end
            else
                cla(app.ResultsUIAxes)
            end
            
            % Echo command to workspace to show command line usage of code
            evalStr = ['figure(5);cla;axCurr = gca;sm_car_plot3d_create(' resultsDataStr ',''' bodyName ''',''' sceneName ''',' ...
                num2str(boxWidth) ',' char(plot3DView+'0') ',' char(plotVehicleBody+'0') ',' char(plotPatch+'0') ',' char(plotTireForces+'0') ',' ...
                tSampStr ',' num2str(timeStart) ',' num2str(timeStop) ', axCurr,' char(saveGIF+'0') ');'];
            disp(evalStr);
            sm_car_plot3d_create(logsout,bodyName,sceneName,boxWidth,plot3DView,...
               plotVehicleBody,plotPatch,plotTireForces,tSamp,timeStart,timeStop,axH,saveGIF)
        end

        % Value changed function: InterpolateResultsCheckBox
        function InterpolateResultsCheckBoxValueChanged(app, event)
            value = app.InterpolateResultsCheckBox.Value;
            if(value)
                app.StepSizeEditField.Enable = 'on';
            else
                app.StepSizeEditField.Enable = 'off';
            end
        end

        % Selection changed function: ResultstoPlotButtonGroup
        function ResultstoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResultstoPlotButtonGroup.SelectedObject;
            if(selectedButton == app.WorkspaceButton)
                app.ResultsDataEditField.Value = 'logsout_sm_car';
                app.SelectButton.Enable = false;
                app.SaveButton.Enable   = true;
            else
                app.ResultsDataEditField.Value = '';
                app.SelectButton.Enable = true;
                app.SaveButton.Enable   = false;
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [filename] = uigetfile('*.mat', 'Pick a simulation results file');
            app.ResultsDataEditField.Value = filename;
        end

        % Value changed function: ofEventSlider
        function ofEventSliderValueChanged(app, event)
            value = app.ofEventSlider.Value;
            if(~endsWith(app.ResultsDataEditField.Value,'.mat'))
                % Obtain data from workspace, pass data to function
                logsout = evalin('base',app.ResultsDataEditField.Value);
            else
                % Pass name of file to function - must be string
                logsout = app.ResultsDataEditField.Value;
            end
            if(ischar(logsout))
                if(endsWith(logsout,'.mat'))
                    load(logsout);
                end
            else
                %% Load simulation results to be visualized
                [simTime] = sm_car_plot3d_get_data(logsout);
            end
            app.StartsecEditField.Value = simTime(end)*value/100;
            app.StopsecEditField.Value  = simTime(end)*value/100;
            ShowResultsButtonPushed(app,event);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Maneuver = evalin('base','Maneuver');
            logsout = evalin('base',app.ResultsDataEditField.Value);
            [filename, pathname] = uiputfile('*.mat', 'Choose file name and location');
            sm_car_plot3d_save_data(logsout,Maneuver,[pathname filename]);
        end

        % Button pushed function: SaveGIFButton
        function SaveGIFButtonPushed(app, event)
            ShowResultsButtonPushed(app,event)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('sm_car_plot3d_overview.html')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VehicleResults3DPlotterApp = uifigure('Visible', 'off');
            app.VehicleResults3DPlotterApp.Name = 'Vehicle Results 3D Plotter';
            app.ResultsUIAxes = uiaxes(app.VehicleResults3DPlotterApp);
            title(app.ResultsUIAxes, 'Title')
            xlabel(app.ResultsUIAxes, 'X')
            ylabel(app.ResultsUIAxes, 'Y')
            zlabel(app.ResultsUIAxes, 'Z')
            app.SceneDropDownLabel = uilabel(app.VehicleResults3DPlotterApp);
            app.SceneDropDownLabel.Text = 'Scene';
            app.SceneDropDown = uidropdown(app.VehicleResults3DPlotterApp);
            app.SceneDropDown.ItemsData = {'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.SceneDropDown.Tooltip = {'Select scene or use option from workspace/file'};
            app.ShowResultsButton = uibutton(app.VehicleResults3DPlotterApp, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultsButtonPushed, true);
            app.ShowResultsButton.Tooltip = {'Plot/animate results'};
            app.ShowResultsButton.Text = 'Show Results';
            app.VehicleSimulationResultsPlotterLabel = uilabel(app.VehicleResults3DPlotterApp);
            app.VehicleSimulationResultsPlotterLabel.Text = 'Vehicle Simulation Results Plotter';
            app.ResultsDataEditField = uieditfield(app.VehicleResults3DPlotterApp, 'text');
            app.ResultsDataEditField.Tooltip = {'Specify variable name or .mat file name'};
            app.ResultsDataEditField.Value = 'logsout_sm_car';
            app.ResultstoPlotButtonGroup = uibuttongroup(app.VehicleResults3DPlotterApp);
            app.ResultstoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultstoPlotButtonGroupSelectionChanged, true);
            app.ResultstoPlotButtonGroup.Tooltip = {'Load results from MATLAB workspace variable or .mat file'};
            app.ResultstoPlotButtonGroup.Title = 'Results to Plot';
            app.WorkspaceButton = uiradiobutton(app.ResultstoPlotButtonGroup);
            app.WorkspaceButton.Text = 'Workspace';
            app.WorkspaceButton.Value = true;
            app.FileButton = uiradiobutton(app.ResultstoPlotButtonGroup);
            app.FileButton.Text = 'File';
            app.SelectButton = uibutton(app.VehicleResults3DPlotterApp, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Tooltip = {'Select file containing simulation results for plotting'};
            app.SelectButton.Text = 'Select';
            app.PlotElementsPanel = uipanel(app.VehicleResults3DPlotterApp);
            app.PlotElementsPanel.Title = 'Plot Elements';
            app.VehicleBodyCheckBox = uicheckbox(app.PlotElementsPanel);
            app.VehicleBodyCheckBox.Tooltip = {'Show/Hide vehicle body'};
            app.VehicleBodyCheckBox.Text = 'Vehicle Body';
            app.PatchCheckBox = uicheckbox(app.PlotElementsPanel);
            app.PatchCheckBox.Tooltip = {'Show/Hide patch showing swept path of vehicle chassis'};
            app.PatchCheckBox.Text = 'Patch';
            app.TireForcesCheckBox = uicheckbox(app.PlotElementsPanel);
            app.TireForcesCheckBox.Tooltip = {'Show/Hide arrows indicating tire forces'};
            app.TireForcesCheckBox.Text = 'Tire Forces';
            app.DViewCheckBox = uicheckbox(app.PlotElementsPanel);
            app.DViewCheckBox.Tooltip = {'Show 3D view/2D view'};
            app.DViewCheckBox.Text = '3D View';
            app.ViewWidthmEditFieldLabel = uilabel(app.PlotElementsPanel);
            app.ViewWidthmEditFieldLabel.Text = 'View Width (m)';
            app.ViewWidthmEditField = uieditfield(app.PlotElementsPanel, 'numeric');
            app.ViewWidthmEditField.Tooltip = {'Length of x and y axes in figure'};
            app.ViewWidthmEditField.Value = 16;
            app.TimePanel = uipanel(app.VehicleResults3DPlotterApp);
            app.TimePanel.Title = 'Time';
            app.StartsecEditFieldLabel = uilabel(app.TimePanel);
            app.StartsecEditFieldLabel.Text = 'Start (sec)';
            app.StartsecEditField = uieditfield(app.TimePanel, 'numeric');
            app.StartsecEditField.Tooltip = {'Start time of results to be plotted'};
            app.StopsecEditFieldLabel = uilabel(app.TimePanel);
            app.StopsecEditFieldLabel.Text = 'Stop (sec)';
            app.StopsecEditField = uieditfield(app.TimePanel, 'numeric');
            app.StopsecEditField.Tooltip = {'Stop time of results to be plotted'};
            app.StopsecEditField.Value = 10;
            app.InterpolateResultsCheckBox = uicheckbox(app.TimePanel);
            app.InterpolateResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolateResultsCheckBoxValueChanged, true);
            app.InterpolateResultsCheckBox.Tooltip = {'Interpolate results according to step size'};
            app.InterpolateResultsCheckBox.Text = 'Interpolate Results';
            app.InterpolateResultsCheckBox.Value = true;
            app.StepSizeEditFieldLabel = uilabel(app.TimePanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.TimePanel, 'numeric');
            app.StepSizeEditField.Tooltip = {'Step size for interpolation'};
            app.StepSizeEditField.Value = 0.1;
            app.ofEventSliderLabel = uilabel(app.TimePanel);
            app.ofEventSliderLabel.Text = {'% of'; 'Event'};
            app.ofEventSlider = uislider(app.TimePanel);
            app.ofEventSlider.ValueChangedFcn = createCallbackFcn(app, @ofEventSliderValueChanged, true);
            app.ofEventSlider.Tooltip = {'Use slider to select time to plot from simulation results.'};
            app.SaveButton = uibutton(app.VehicleResults3DPlotterApp, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save only results for plotting to .mat file'};
            app.SaveButton.Text = 'Save';
            app.SaveGIFButton = uibutton(app.VehicleResults3DPlotterApp, 'push');
            app.SaveGIFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGIFButtonPushed, true);
            app.SaveGIFButton.Tooltip = {'Save animation to file'};
            app.SaveGIFButton.Text = 'Save GIF';
            app.BodyDropDownLabel = uilabel(app.VehicleResults3DPlotterApp);
            app.BodyDropDownLabel.Text = 'Body';
            app.BodyDropDown = uidropdown(app.VehicleResults3DPlotterApp);
            app.BodyDropDown.Items = {'Sedan', 'FSAE', 'SUV Landy'};
            app.BodyDropDown.ItemsData = {'Sedan', 'FSAE', 'SUV Landy'};
            app.BodyDropDown.Tooltip = {'Select vehicle body shown in plot'};
            app.BodyDropDown.Value = 'Sedan';
            app.HelpButton = uibutton(app.VehicleResults3DPlotterApp, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Documentation for App and code'};
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.VehicleResults3DPlotterApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sm_car_plot3d_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VehicleResults3DPlotterApp)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VehicleResults3DPlotterApp)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VehicleResults3DPlotterApp)
        end
    end
end