classdef testrig_quarter_car_mlapp_sweep_optim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimscapeSuspensionTesterUIFigure  matlab.ui.Figure
        ReboundEditField                matlab.ui.control.NumericEditField
        ReboundEditFieldLabel           matlab.ui.control.Label
        JounceEditField                 matlab.ui.control.NumericEditField
        JounceEditFieldLabel            matlab.ui.control.Label
        SetAllUseCheckBox               matlab.ui.control.CheckBox
        TargetValueUnitsLabel           matlab.ui.control.Label
        TargetValueEditField            matlab.ui.control.NumericEditField
        TargetValueEditFieldLabel       matlab.ui.control.Label
        OpenMainModelButton             matlab.ui.control.Button
        SelectMainModelButton           matlab.ui.control.Button
        TrackRodHardpointsPanel         matlab.ui.container.Panel
        TrackRodsInboardZEditField      matlab.ui.control.NumericEditField
        TrackRodsInboardYEditField      matlab.ui.control.NumericEditField
        TrackRodsInboardXEditField      matlab.ui.control.NumericEditField
        TrackRodsOutboardXEditField     matlab.ui.control.NumericEditField
        FrontInboardXmLabel_8           matlab.ui.control.Label
        TrackRodsOutboardYEditField     matlab.ui.control.NumericEditField
        FrontInboardYmLabel_8           matlab.ui.control.Label
        TrackRodsOutboardZEditField     matlab.ui.control.NumericEditField
        FrontInboardZLabel_10           matlab.ui.control.Label
        TrackRodsInboardZMinEditField   matlab.ui.control.NumericEditField
        TrackRodsInboardYMinEditField   matlab.ui.control.NumericEditField
        TrackRodsInboardXMinEditField   matlab.ui.control.NumericEditField
        TrackRodsOutboardXMinEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardYMinEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardZMinEditField  matlab.ui.control.NumericEditField
        TrackRodsInboardZMaxEditField   matlab.ui.control.NumericEditField
        TrackRodsInboardYMaxEditField   matlab.ui.control.NumericEditField
        TrackRodsInboardXMaxEditField   matlab.ui.control.NumericEditField
        TrackRodsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        TrackRodsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        FrontInboardZLabel_9            matlab.ui.control.Label
        FrontInboardYmLabel_7           matlab.ui.control.Label
        FrontInboardXmLabel_7           matlab.ui.control.Label
        TrackRodsInboardZIncrEditField  matlab.ui.control.NumericEditField
        TrackRodsInboardYIncrEditField  matlab.ui.control.NumericEditField
        TrackRodsInboardXIncrEditField  matlab.ui.control.NumericEditField
        TrackRodsInboardZEnableCheckBox  matlab.ui.control.CheckBox
        TrackRodsInboardYEnableCheckBox  matlab.ui.control.CheckBox
        TrackRodsInboardXEnableCheckBox  matlab.ui.control.CheckBox
        TrackRodsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        TrackRodsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        TrackRodsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        ReloadValuesButton              matlab.ui.control.Button
        LowerWishboneHardpointsPanel    matlab.ui.container.Panel
        LowerWishsInboardRXEditField    matlab.ui.control.NumericEditField
        LowerWishsInboardRYEditField    matlab.ui.control.NumericEditField
        LowerWishsInboardRZEditField    matlab.ui.control.NumericEditField
        LowerWishsInboardFZEditField    matlab.ui.control.NumericEditField
        LowerWishsInboardFYEditField    matlab.ui.control.NumericEditField
        LowerWishsInboardFXEditField    matlab.ui.control.NumericEditField
        LowerWishsOutboardXEditField    matlab.ui.control.NumericEditField
        FrontInboardXmLabel_6           matlab.ui.control.Label
        LowerWishsOutboardYEditField    matlab.ui.control.NumericEditField
        FrontInboardYmLabel_6           matlab.ui.control.Label
        LowerWishsOutboardZEditField    matlab.ui.control.NumericEditField
        FrontInboardZLabel_8            matlab.ui.control.Label
        LowerWishsInboardRXMinEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRYMinEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRZMinEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFZMinEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFYMinEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFXMinEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardXMinEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardYMinEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardZMinEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRXMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRYMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRZMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFZMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFYMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFXMaxEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRXIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRYIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardRZIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFZIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFYIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsInboardFXIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        LowerWishsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        RearInboardXLabel_6             matlab.ui.control.Label
        RearInboardYLabel_6             matlab.ui.control.Label
        RearInboardZLabel_6             matlab.ui.control.Label
        FrontInboardZLabel_7            matlab.ui.control.Label
        FrontInboardYmLabel_5           matlab.ui.control.Label
        FrontInboardXmLabel_5           matlab.ui.control.Label
        LowerWishsInboardRXEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsInboardRYEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsInboardRZEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsInboardFZEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsInboardFYEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsInboardFXEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        LowerWishsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishboneHardpointsPanel    matlab.ui.container.Panel
        UpperWishsOutboardXEditField    matlab.ui.control.NumericEditField
        FrontInboardXmLabel_4           matlab.ui.control.Label
        UpperWishsOutboardYEditField    matlab.ui.control.NumericEditField
        FrontInboardYmLabel_4           matlab.ui.control.Label
        UpperWishsOutboardZEditField    matlab.ui.control.NumericEditField
        FrontInboardZLabel_6            matlab.ui.control.Label
        UpperWishsInboardRXEditField    matlab.ui.control.NumericEditField
        UpperWishsInboardRYEditField    matlab.ui.control.NumericEditField
        UpperWishsInboardRZEditField    matlab.ui.control.NumericEditField
        UpperWishsInboardFZEditField    matlab.ui.control.NumericEditField
        UpperWishsInboardFYEditField    matlab.ui.control.NumericEditField
        UpperWishsInboardFXEditField    matlab.ui.control.NumericEditField
        UpperWishsOutboardXMinEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardYMinEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardZMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRXMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRYMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRZMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFZMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFYMinEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFXMinEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRXMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRYMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRZMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFZMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFYMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFXMaxEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        RearInboardXLabel_5             matlab.ui.control.Label
        RearInboardYLabel_5             matlab.ui.control.Label
        RearInboardZLabel_5             matlab.ui.control.Label
        FrontInboardZLabel_5            matlab.ui.control.Label
        FrontInboardYmLabel_3           matlab.ui.control.Label
        FrontInboardXmLabel_3           matlab.ui.control.Label
        UpperWishsInboardRXIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRYIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardRZIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFZIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFYIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsInboardFXIncrEditField  matlab.ui.control.NumericEditField
        UpperWishsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardRXEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardRYEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardRZEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardFZEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardFYEnableCheckBox  matlab.ui.control.CheckBox
        UpperWishsInboardFXEnableCheckBox  matlab.ui.control.CheckBox
        UseLabel_2                      matlab.ui.control.Label
        IncrementLabel_2                matlab.ui.control.Label
        MaxmLabel_2                     matlab.ui.control.Label
        MinmLabel_2                     matlab.ui.control.Label
        ValuemLabel_2                   matlab.ui.control.Label
        SplitLowerArmHardpointsPanel    matlab.ui.container.Panel
        LowerArmFsOutboardXEditField    matlab.ui.control.NumericEditField
        FrontInboardXLabel_2            matlab.ui.control.Label
        LowerArmFsOutboardYEditField    matlab.ui.control.NumericEditField
        LowerArmFsOutboardZEditField    matlab.ui.control.NumericEditField
        LowerArmRsOutboardXEditField    matlab.ui.control.NumericEditField
        LowerArmRsOutboardYEditField    matlab.ui.control.NumericEditField
        LowerArmRsOutboardZEditField    matlab.ui.control.NumericEditField
        LowerArmFsOutboardXMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardYMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardZMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardXMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardYMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardZMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        FrontInboardYLabel_2            matlab.ui.control.Label
        FrontInboardZLabel_4            matlab.ui.control.Label
        RearInboardXLabel_4             matlab.ui.control.Label
        RearInboardYLabel_4             matlab.ui.control.Label
        RearInboardZLabel_4             matlab.ui.control.Label
        LowerArmFsInboardXEditField     matlab.ui.control.NumericEditField
        LowerArmFsInboardYEditField     matlab.ui.control.NumericEditField
        LowerArmFsInboardZEditField     matlab.ui.control.NumericEditField
        LowerArmRsInboardXEditField     matlab.ui.control.NumericEditField
        RearInboardXLabel_3             matlab.ui.control.Label
        LowerArmRsInboardYEditField     matlab.ui.control.NumericEditField
        RearInboardYLabel_3             matlab.ui.control.Label
        LowerArmRsInboardZEditField     matlab.ui.control.NumericEditField
        RearInboardZLabel_3             matlab.ui.control.Label
        FrontInboardZLabel_3            matlab.ui.control.Label
        FrontInboardYLabel              matlab.ui.control.Label
        FrontInboardXLabel              matlab.ui.control.Label
        LowerArmFsInboardXMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardYMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardZMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardXMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardYMinEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardZMinEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardXMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardYMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardZMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardXMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardYMaxEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardZMaxEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardXIncrEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardYIncrEditField  matlab.ui.control.NumericEditField
        LowerArmFsInboardZIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardXIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardYIncrEditField  matlab.ui.control.NumericEditField
        LowerArmRsInboardZIncrEditField  matlab.ui.control.NumericEditField
        LowerArmFsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmFsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmFsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmFsInboardZEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmFsInboardYEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmFsInboardXEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsInboardXEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsInboardYEnableCheckBox  matlab.ui.control.CheckBox
        LowerArmRsInboardZEnableCheckBox  matlab.ui.control.CheckBox
        SplitUpperArmHardpointsPanel    matlab.ui.container.Panel
        UpperArmFsOutboardXEditField    matlab.ui.control.NumericEditField
        FrontInboardXmLabel_2           matlab.ui.control.Label
        UpperArmFsOutboardYEditField    matlab.ui.control.NumericEditField
        FrontInboardYmLabel_2           matlab.ui.control.Label
        UpperArmFsOutboardZEditField    matlab.ui.control.NumericEditField
        FrontInboardZLabel_2            matlab.ui.control.Label
        RearInboardZLabel_2             matlab.ui.control.Label
        UpperArmRsOutboardXEditField    matlab.ui.control.NumericEditField
        RearInboardXLabel_2             matlab.ui.control.Label
        UpperArmRsOutboardYEditField    matlab.ui.control.NumericEditField
        RearInboardYLabel_2             matlab.ui.control.Label
        UpperArmRsOutboardZEditField    matlab.ui.control.NumericEditField
        UpperArmFsOutboardXMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardYMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardZMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardXMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardYMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardZMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardXMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardYMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardZMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardXIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardYIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsOutboardZIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardXEditField     matlab.ui.control.NumericEditField
        RearInboardXLabel               matlab.ui.control.Label
        UpperArmRsInboardYEditField     matlab.ui.control.NumericEditField
        RearInboardYLabel               matlab.ui.control.Label
        UpperArmRsInboardZEditField     matlab.ui.control.NumericEditField
        RearInboardZLabel               matlab.ui.control.Label
        UpperArmFsInboardZEditField     matlab.ui.control.NumericEditField
        FrontInboardZLabel              matlab.ui.control.Label
        UpperArmFsInboardYEditField     matlab.ui.control.NumericEditField
        FrontInboardYmLabel             matlab.ui.control.Label
        UpperArmFsInboardXEditField     matlab.ui.control.NumericEditField
        FrontInboardXmLabel             matlab.ui.control.Label
        UpperArmRsInboardXMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardXMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardXIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardYMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardYMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardYIncrEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardZMinEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardZMaxEditField  matlab.ui.control.NumericEditField
        UpperArmRsInboardZIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardZIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardYIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardXIncrEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardZMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardYMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardXMaxEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardZMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardYMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsInboardXMinEditField  matlab.ui.control.NumericEditField
        UpperArmFsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmFsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmFsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsOutboardXEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsOutboardYEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsOutboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsInboardXEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsInboardYEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmRsInboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmFsInboardZEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmFsInboardYEnableCheckBox  matlab.ui.control.CheckBox
        UpperArmFsInboardXEnableCheckBox  matlab.ui.control.CheckBox
        SuspensionTesterLabel           matlab.ui.control.Label
        MainModelEditField              matlab.ui.control.EditField
        ModelEditFieldLabel             matlab.ui.control.Label
        MetricDropDown                  matlab.ui.control.DropDown
        MetricDropDownLabel             matlab.ui.control.Label
        ValuemLabel                     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UseLabel                        matlab.ui.control.Label
        IncrementLabel                  matlab.ui.control.Label
        TestDropDown                    matlab.ui.control.DropDown
        TestDropDownLabel               matlab.ui.control.Label
        MaxmLabel                       matlab.ui.control.Label
        MinmLabel                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get data from workspace to populate edit fields
            try
                Vehicle = evalin('base','Vehicle');
            catch
                Vehicle = [];
            end
            % If suitable data exists in workspace
            if(~isempty(Vehicle))
                if(isfield(Vehicle.Chassis.SuspA1,'Linkage'))

                    Linkage = Vehicle.Chassis.SuspA1.Linkage;

                    switch(Linkage.class.Value)
                        case {'FiveLinkShockFront','FiveLinkConstraintShockFront','FiveLinkShockRear'}
                            UAtype = 'split';
                        otherwise
                            UAtype = 'wish';
                    end
                    switch(Linkage.class.Value)
                        case {'FiveLinkShockFront','FiveLinkConstraintShockFront','FiveLinkShockRear',...
                                'SplitLowerArmShockFront','SplitLowerArmShockRear'}
                            LAtype = 'split';
                        otherwise
                            LAtype = 'wish';
                    end

                    default_range = 0.04;
                    default_incr  = 0.02;

                    if(strcmp(UAtype,'split'))
                        app.SplitUpperArmHardpointsPanel.Visible = 'on';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','SUAUse');
                        %set(h,'Value',true)
                        app.UpperWishboneHardpointsPanel.Visible = 'off';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','WUAUse');
                        set(h,'Value',false)
                        %% Split Arm Upper Front Inboard
                        app.UpperArmFsInboardXEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(1);
                        app.UpperArmFsInboardXMinEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(1)-default_range;
                        app.UpperArmFsInboardXMaxEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(1)+default_range;
                        app.UpperArmFsInboardXIncrEditField.Value = default_incr;

                        app.UpperArmFsInboardYEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(2);
                        app.UpperArmFsInboardYMinEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(2)-default_range;
                        app.UpperArmFsInboardYMaxEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(2)+default_range;
                        app.UpperArmFsInboardYIncrEditField.Value = default_incr;

                        app.UpperArmFsInboardZEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(3);
                        app.UpperArmFsInboardZMinEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(3)-default_range;
                        app.UpperArmFsInboardZMaxEditField.Value = ...
                            Linkage.UpperArmF.sInboard.Value(3)+default_range;
                        app.UpperArmFsInboardZIncrEditField.Value = default_incr;

                        %% Split Arm Upper Rear Inboard
                        app.UpperArmRsInboardXEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(1);
                        app.UpperArmRsInboardXMinEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(1)-default_range;
                        app.UpperArmRsInboardXMaxEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(1)+default_range;
                        app.UpperArmRsInboardXIncrEditField.Value = default_incr;

                        app.UpperArmRsInboardYEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(2);
                        app.UpperArmRsInboardYMinEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(2)-default_range;
                        app.UpperArmRsInboardYMaxEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(2)+default_range;
                        app.UpperArmRsInboardYIncrEditField.Value = default_incr;

                        app.UpperArmRsInboardZEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(3);
                        app.UpperArmRsInboardZMinEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(3)-default_range;
                        app.UpperArmRsInboardZMaxEditField.Value = ...
                            Linkage.UpperArmR.sInboard.Value(3)+default_range;
                        app.UpperArmRsInboardZIncrEditField.Value = default_incr;

                        %% Split Arm Upper Front OUTBOARD
                        app.UpperArmFsOutboardXEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(1);
                        app.UpperArmFsOutboardXMinEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(1)-default_range;
                        app.UpperArmFsOutboardXMaxEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(1)+default_range;
                        app.UpperArmFsOutboardXIncrEditField.Value = default_incr;

                        app.UpperArmFsOutboardYEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(2);
                        app.UpperArmFsOutboardYMinEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(2)-default_range;
                        app.UpperArmFsOutboardYMaxEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(2)+default_range;
                        app.UpperArmFsOutboardYIncrEditField.Value = default_incr;

                        app.UpperArmFsOutboardZEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(3);
                        app.UpperArmFsOutboardZMinEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(3)-default_range;
                        app.UpperArmFsOutboardZMaxEditField.Value = ...
                            Linkage.UpperArmF.sOutboard.Value(3)+default_range;
                        app.UpperArmFsOutboardZIncrEditField.Value = default_incr;

                        %% Split Arm Upper Rear OUTBOARD
                        app.UpperArmRsOutboardXEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(1);
                        app.UpperArmRsOutboardXMinEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(1)-default_range;
                        app.UpperArmRsOutboardXMaxEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(1)+default_range;
                        app.UpperArmRsOutboardXIncrEditField.Value = default_incr;

                        app.UpperArmRsOutboardYEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(2);
                        app.UpperArmRsOutboardYMinEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(2)-default_range;
                        app.UpperArmRsOutboardYMaxEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(2)+default_range;
                        app.UpperArmRsOutboardYIncrEditField.Value = default_incr;

                        app.UpperArmRsOutboardZEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(3);
                        app.UpperArmRsOutboardZMinEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(3)-default_range;
                        app.UpperArmRsOutboardZMaxEditField.Value = ...
                            Linkage.UpperArmR.sOutboard.Value(3)+default_range;
                        app.UpperArmRsOutboardZIncrEditField.Value = default_incr;


                    else
                        app.UpperWishboneHardpointsPanel.Visible = 'on';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','WUAUse');
                        %set(h,'Value',true)
                        app.SplitUpperArmHardpointsPanel.Visible = 'off';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','SUAUse');
                        set(h,'Value',false)

                        %% Wishbone Upper Front Inboard
                        app.UpperWishsInboardFXEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(1);
                        app.UpperWishsInboardFXMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(1)-default_range;
                        app.UpperWishsInboardFXMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(1)+default_range;
                        app.UpperWishsInboardFXIncrEditField.Value = default_incr;

                        app.UpperWishsInboardFYEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(2);
                        app.UpperWishsInboardFYMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(2)-default_range;
                        app.UpperWishsInboardFYMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(2)+default_range;
                        app.UpperWishsInboardFYIncrEditField.Value = default_incr;

                        app.UpperWishsInboardFZEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(3);
                        app.UpperWishsInboardFZMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(3)-default_range;
                        app.UpperWishsInboardFZMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardF.Value(3)+default_range;
                        app.UpperWishsInboardFZIncrEditField.Value = default_incr;

                        %% Wishbone Upper Rear Inboard
                        app.UpperWishsInboardRXEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(1);
                        app.UpperWishsInboardRXMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(1)-default_range;
                        app.UpperWishsInboardRXMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(1)+default_range;
                        app.UpperWishsInboardRXIncrEditField.Value = default_incr;

                        app.UpperWishsInboardRYEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(2);
                        app.UpperWishsInboardRYMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(2)-default_range;
                        app.UpperWishsInboardRYMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(2)+default_range;
                        app.UpperWishsInboardRYIncrEditField.Value = default_incr;

                        app.UpperWishsInboardRZEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(3);
                        app.UpperWishsInboardRZMinEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(3)-default_range;
                        app.UpperWishsInboardRZMaxEditField.Value = ...
                            Linkage.UpperWishbone.sInboardR.Value(3)+default_range;
                        app.UpperWishsInboardRZIncrEditField.Value = default_incr;

                        %% Wishbone Upper OUTBOARD
                        app.UpperWishsOutboardXEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(1);
                        app.UpperWishsOutboardXMinEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(1)-default_range;
                        app.UpperWishsOutboardXMaxEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(1)+default_range;
                        app.UpperWishsOutboardXIncrEditField.Value = default_incr;

                        app.UpperWishsOutboardYEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(2);
                        app.UpperWishsOutboardYMinEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(2)-default_range;
                        app.UpperWishsOutboardYMaxEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(2)+default_range;
                        app.UpperWishsOutboardYIncrEditField.Value = default_incr;

                        app.UpperWishsOutboardZEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(3);
                        app.UpperWishsOutboardZMinEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(3)-default_range;
                        app.UpperWishsOutboardZMaxEditField.Value = ...
                            Linkage.UpperWishbone.sOutboard.Value(3)+default_range;
                        app.UpperWishsOutboardZIncrEditField.Value = default_incr;


                    end


                    if(strcmp(LAtype,'split'))
                        app.SplitLowerArmHardpointsPanel.Visible = 'on';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','SLAUse');
                        %set(h,'Value',true)
                        app.LowerWishboneHardpointsPanel.Visible = 'off';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','WLAUse');
                        set(h,'Value',false)

                        %% Split Arm Lower Front Inboard
                        app.LowerArmFsInboardXEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(1);
                        app.LowerArmFsInboardXMinEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(1)-default_range;
                        app.LowerArmFsInboardXMaxEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(1)+default_range;
                        app.LowerArmFsInboardXIncrEditField.Value = default_incr;

                        app.LowerArmFsInboardYEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(2);
                        app.LowerArmFsInboardYMinEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(2)-default_range;
                        app.LowerArmFsInboardYMaxEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(2)+default_range;
                        app.LowerArmFsInboardYIncrEditField.Value = default_incr;

                        app.LowerArmFsInboardZEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(3);
                        app.LowerArmFsInboardZMinEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(3)-default_range;
                        app.LowerArmFsInboardZMaxEditField.Value = ...
                            Linkage.LowerArmF.sInboard.Value(3)+default_range;
                        app.LowerArmFsInboardZIncrEditField.Value = default_incr;

                        %% Split Arm Lower Rear Inboard
                        app.LowerArmRsInboardXEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(1);
                        app.LowerArmRsInboardXMinEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(1)-default_range;
                        app.LowerArmRsInboardXMaxEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(1)+default_range;
                        app.LowerArmRsInboardXIncrEditField.Value = default_incr;

                        app.LowerArmRsInboardYEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(2);
                        app.LowerArmRsInboardYMinEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(2)-default_range;
                        app.LowerArmRsInboardYMaxEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(2)+default_range;
                        app.LowerArmRsInboardYIncrEditField.Value = default_incr;

                        app.LowerArmRsInboardZEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(3);
                        app.LowerArmRsInboardZMinEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(3)-default_range;
                        app.LowerArmRsInboardZMaxEditField.Value = ...
                            Linkage.LowerArmR.sInboard.Value(3)+default_range;
                        app.LowerArmRsInboardZIncrEditField.Value = default_incr;

                        %% Split Arm Lower Front OUTBOARD
                        app.LowerArmFsOutboardXEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(1);
                        app.LowerArmFsOutboardXMinEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(1)-default_range;
                        app.LowerArmFsOutboardXMaxEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(1)+default_range;
                        app.LowerArmFsOutboardXIncrEditField.Value = default_incr;

                        app.LowerArmFsOutboardYEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(2);
                        app.LowerArmFsOutboardYMinEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(2)-default_range;
                        app.LowerArmFsOutboardYMaxEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(2)+default_range;
                        app.LowerArmFsOutboardYIncrEditField.Value = default_incr;

                        app.LowerArmFsOutboardZEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(3);
                        app.LowerArmFsOutboardZMinEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(3)-default_range;
                        app.LowerArmFsOutboardZMaxEditField.Value = ...
                            Linkage.LowerArmF.sOutboard.Value(3)+default_range;
                        app.LowerArmFsOutboardZIncrEditField.Value = default_incr;

                        %% Split Arm Lower Rear OUTBOARD
                        app.LowerArmRsOutboardXEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(1);
                        app.LowerArmRsOutboardXMinEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(1)-default_range;
                        app.LowerArmRsOutboardXMaxEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(1)+default_range;
                        app.LowerArmRsOutboardXIncrEditField.Value = default_incr;

                        app.LowerArmRsOutboardYEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(2);
                        app.LowerArmRsOutboardYMinEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(2)-default_range;
                        app.LowerArmRsOutboardYMaxEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(2)+default_range;
                        app.LowerArmRsOutboardYIncrEditField.Value = default_incr;

                        app.LowerArmRsOutboardZEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(3);
                        app.LowerArmRsOutboardZMinEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(3)-default_range;
                        app.LowerArmRsOutboardZMaxEditField.Value = ...
                            Linkage.LowerArmR.sOutboard.Value(3)+default_range;
                        app.LowerArmRsOutboardZIncrEditField.Value = default_incr;

                    else
                        app.LowerWishboneHardpointsPanel.Visible = 'on';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','WLAUse');
                        %set(h,'Value',true)
                        app.SplitLowerArmHardpointsPanel.Visible = 'off';
                        h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','SLAUse');
                        set(h,'Value',false)

                        % -------------
                        %% Wishbone Lower Front Inboard
                        app.LowerWishsInboardFXEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(1);
                        app.LowerWishsInboardFXMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(1)-default_range;
                        app.LowerWishsInboardFXMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(1)+default_range;
                        app.LowerWishsInboardFXIncrEditField.Value = default_incr;

                        app.LowerWishsInboardFYEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(2);
                        app.LowerWishsInboardFYMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(2)-default_range;
                        app.LowerWishsInboardFYMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(2)+default_range;
                        app.LowerWishsInboardFYIncrEditField.Value = default_incr;

                        app.LowerWishsInboardFZEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(3);
                        app.LowerWishsInboardFZMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(3)-default_range;
                        app.LowerWishsInboardFZMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardF.Value(3)+default_range;
                        app.LowerWishsInboardFZIncrEditField.Value = default_incr;

                        %% Wishbone Lower Rear Inboard
                        app.LowerWishsInboardRXEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(1);
                        app.LowerWishsInboardRXMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(1)-default_range;
                        app.LowerWishsInboardRXMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(1)+default_range;
                        app.LowerWishsInboardRXIncrEditField.Value = default_incr;

                        app.LowerWishsInboardRYEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(2);
                        app.LowerWishsInboardRYMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(2)-default_range;
                        app.LowerWishsInboardRYMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(2)+default_range;
                        app.LowerWishsInboardRYIncrEditField.Value = default_incr;

                        app.LowerWishsInboardRZEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(3);
                        app.LowerWishsInboardRZMinEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(3)-default_range;
                        app.LowerWishsInboardRZMaxEditField.Value = ...
                            Linkage.LowerWishbone.sInboardR.Value(3)+default_range;
                        app.LowerWishsInboardRZIncrEditField.Value = default_incr;

                        %% Wishbone Lower OUTBOARD
                        app.LowerWishsOutboardXEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(1);
                        app.LowerWishsOutboardXMinEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(1)-default_range;
                        app.LowerWishsOutboardXMaxEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(1)+default_range;
                        app.LowerWishsOutboardXIncrEditField.Value = default_incr;

                        app.LowerWishsOutboardYEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(2);
                        app.LowerWishsOutboardYMinEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(2)-default_range;
                        app.LowerWishsOutboardYMaxEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(2)+default_range;
                        app.LowerWishsOutboardYIncrEditField.Value = default_incr;

                        app.LowerWishsOutboardZEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(3);
                        app.LowerWishsOutboardZMinEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(3)-default_range;
                        app.LowerWishsOutboardZMaxEditField.Value = ...
                            Linkage.LowerWishbone.sOutboard.Value(3)+default_range;
                        app.LowerWishsOutboardZIncrEditField.Value = default_incr;
                    end

                    %% Track Rod Inboard
                    app.TrackRodsInboardXEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(1);
                    app.TrackRodsInboardXMinEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(1)-default_range;
                    app.TrackRodsInboardXMaxEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(1)+default_range;
                    app.TrackRodsInboardXIncrEditField.Value = default_incr;

                    app.TrackRodsInboardYEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(2);
                    app.TrackRodsInboardYMinEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(2)-default_range;
                    app.TrackRodsInboardYMaxEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(2)+default_range;
                    app.TrackRodsInboardYIncrEditField.Value = default_incr;

                    app.TrackRodsInboardZEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(3);
                    app.TrackRodsInboardZMinEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(3)-default_range;
                    app.TrackRodsInboardZMaxEditField.Value = ...
                        Linkage.TrackRod.sInboard.Value(3)+default_range;
                    app.TrackRodsInboardZIncrEditField.Value = default_incr;

                    %% Track Rod Outboard
                    app.TrackRodsOutboardXEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(1);
                    app.TrackRodsOutboardXMinEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(1)-default_range;
                    app.TrackRodsOutboardXMaxEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(1)+default_range;
                    app.TrackRodsOutboardXIncrEditField.Value = default_incr;

                    app.TrackRodsOutboardYEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(2);
                    app.TrackRodsOutboardYMinEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(2)-default_range;
                    app.TrackRodsOutboardYMaxEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(2)+default_range;
                    app.TrackRodsOutboardYIncrEditField.Value = default_incr;

                    app.TrackRodsOutboardZEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(3);
                    app.TrackRodsOutboardZMinEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(3)-default_range;
                    app.TrackRodsOutboardZMaxEditField.Value = ...
                        Linkage.TrackRod.sOutboard.Value(3)+default_range;
                    app.TrackRodsOutboardZIncrEditField.Value = default_incr;
                end
            end
        end

        % Value changed function: UpperArmFsInboardXEnableCheckBox
        function UpperArmFsInboardXEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsInboardXEnableCheckBox.Value;
            app.UpperArmFsInboardXEditField.Enable = value;
            app.UpperArmFsInboardXMinEditField.Enable = value;
            app.UpperArmFsInboardXMaxEditField.Enable = value;
            app.UpperArmFsInboardXIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmFsInboardYEnableCheckBox
        function UpperArmFsInboardYEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsInboardYEnableCheckBox.Value;
            app.UpperArmFsInboardYEditField.Enable = value;
            app.UpperArmFsInboardYMinEditField.Enable = value;
            app.UpperArmFsInboardYMaxEditField.Enable = value;
            app.UpperArmFsInboardYIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmFsInboardZEnableCheckBox
        function UpperArmFsInboardZEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsInboardZEnableCheckBox.Value;
            app.UpperArmFsInboardZEditField.Enable = value;
            app.UpperArmFsInboardZMinEditField.Enable = value;
            app.UpperArmFsInboardZMaxEditField.Enable = value;
            app.UpperArmFsInboardZIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmRsInboardXEnableCheckBox
        function UpperArmRsInboardXEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsInboardXEnableCheckBox.Value;
            app.UpperArmRsInboardXEditField.Enable = value;
            app.UpperArmRsInboardXMinEditField.Enable = value;
            app.UpperArmRsInboardXMaxEditField.Enable = value;
            app.UpperArmRsInboardXIncrEditField.Enable = value;

        end

        % Value changed function: UpperArmRsInboardYEnableCheckBox
        function UpperArmRsInboardYEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsInboardYEnableCheckBox.Value;
            app.UpperArmRsInboardYEditField.Enable = value;
            app.UpperArmRsInboardYMinEditField.Enable = value;
            app.UpperArmRsInboardYMaxEditField.Enable = value;
            app.UpperArmRsInboardYIncrEditField.Enable = value;

        end

        % Value changed function: UpperArmRsInboardZEnableCheckBox
        function UpperArmRsInboardZEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsInboardZEnableCheckBox.Value;
            app.UpperArmRsInboardZEditField.Enable = value;
            app.UpperArmRsInboardZMinEditField.Enable = value;
            app.UpperArmRsInboardZMaxEditField.Enable = value;
            app.UpperArmRsInboardZIncrEditField.Enable = value;

        end

        % Value changed function: LowerArmFsInboardXEnableCheckBox
        function LowerArmFsInboardXEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsInboardXEnableCheckBox.Value;
            app.LowerArmFsInboardXEditField.Enable = value;
            app.LowerArmFsInboardXMinEditField.Enable = value;
            app.LowerArmFsInboardXMaxEditField.Enable = value;
            app.LowerArmFsInboardXIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmFsInboardYEnableCheckBox
        function LowerArmFsInboardYEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsInboardYEnableCheckBox.Value;
            app.LowerArmFsInboardYEditField.Enable = value;
            app.LowerArmFsInboardYMinEditField.Enable = value;
            app.LowerArmFsInboardYMaxEditField.Enable = value;
            app.LowerArmFsInboardYIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmFsInboardZEnableCheckBox
        function LowerArmFsInboardZEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsInboardZEnableCheckBox.Value;
            app.LowerArmFsInboardZEditField.Enable = value;
            app.LowerArmFsInboardZMinEditField.Enable = value;
            app.LowerArmFsInboardZMaxEditField.Enable = value;
            app.LowerArmFsInboardZIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmRsInboardXEnableCheckBox
        function LowerArmRsInboardXEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsInboardXEnableCheckBox.Value;
            app.LowerArmRsInboardXEditField.Enable = value;
            app.LowerArmRsInboardXMinEditField.Enable = value;
            app.LowerArmRsInboardXMaxEditField.Enable = value;
            app.LowerArmRsInboardXIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmRsInboardYEnableCheckBox
        function LowerArmRsInboardYEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsInboardYEnableCheckBox.Value;
            app.LowerArmRsInboardYEditField.Enable = value;
            app.LowerArmRsInboardYMinEditField.Enable = value;
            app.LowerArmRsInboardYMaxEditField.Enable = value;
            app.LowerArmRsInboardYIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmRsInboardZEnableCheckBox
        function LowerArmRsInboardZEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsInboardZEnableCheckBox.Value;
            app.LowerArmRsInboardZEditField.Enable = value;
            app.LowerArmRsInboardZMinEditField.Enable = value;
            app.LowerArmRsInboardZMaxEditField.Enable = value;
            app.LowerArmRsInboardZIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmFsOutboardXEnableCheckBox
        function UpperArmFsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsOutboardXEnableCheckBox.Value;
            app.UpperArmFsOutboardXEditField.Enable = value;
            app.UpperArmFsOutboardXMinEditField.Enable = value;
            app.UpperArmFsOutboardXMaxEditField.Enable = value;
            app.UpperArmFsOutboardXIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmFsOutboardYEnableCheckBox
        function UpperArmFsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsOutboardYEnableCheckBox.Value;
            app.UpperArmFsOutboardYEditField.Enable = value;
            app.UpperArmFsOutboardYMinEditField.Enable = value;
            app.UpperArmFsOutboardYMaxEditField.Enable = value;
            app.UpperArmFsOutboardYIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmFsOutboardZEnableCheckBox
        function UpperArmFsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmFsOutboardZEnableCheckBox.Value;
            app.UpperArmFsOutboardZEditField.Enable = value;
            app.UpperArmFsOutboardZMinEditField.Enable = value;
            app.UpperArmFsOutboardZMaxEditField.Enable = value;
            app.UpperArmFsOutboardZIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmRsOutboardXEnableCheckBox
        function UpperArmRsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsOutboardXEnableCheckBox.Value;
            app.UpperArmRsOutboardXEditField.Enable = value;
            app.UpperArmRsOutboardXMinEditField.Enable = value;
            app.UpperArmRsOutboardXMaxEditField.Enable = value;
            app.UpperArmRsOutboardXIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmRsOutboardYEnableCheckBox
        function UpperArmRsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsOutboardYEnableCheckBox.Value;
            app.UpperArmRsOutboardYEditField.Enable = value;
            app.UpperArmRsOutboardYMinEditField.Enable = value;
            app.UpperArmRsOutboardYMaxEditField.Enable = value;
            app.UpperArmRsOutboardYIncrEditField.Enable = value;
        end

        % Value changed function: UpperArmRsOutboardZEnableCheckBox
        function UpperArmRsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.UpperArmRsOutboardZEnableCheckBox.Value;
            app.UpperArmRsOutboardZEditField.Enable = value;
            app.UpperArmRsOutboardZMinEditField.Enable = value;
            app.UpperArmRsOutboardZMaxEditField.Enable = value;
            app.UpperArmRsOutboardZIncrEditField.Enable = value;

        end

        % Value changed function: LowerArmFsOutboardXEnableCheckBox
        function LowerArmFsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsOutboardXEnableCheckBox.Value;
            app.LowerArmFsOutboardXEditField.Enable = value;
            app.LowerArmFsOutboardXMinEditField.Enable = value;
            app.LowerArmFsOutboardXMaxEditField.Enable = value;
            app.LowerArmFsOutboardXIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmFsOutboardYEnableCheckBox
        function LowerArmFsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsOutboardYEnableCheckBox.Value;
            app.LowerArmFsOutboardYEditField.Enable = value;
            app.LowerArmFsOutboardYMinEditField.Enable = value;
            app.LowerArmFsOutboardYMaxEditField.Enable = value;
            app.LowerArmFsOutboardYIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmFsOutboardZEnableCheckBox
        function LowerArmFsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmFsOutboardZEnableCheckBox.Value;
            app.LowerArmFsOutboardZEditField.Enable = value;
            app.LowerArmFsOutboardZMinEditField.Enable = value;
            app.LowerArmFsOutboardZMaxEditField.Enable = value;
            app.LowerArmFsOutboardZIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmRsOutboardXEnableCheckBox
        function LowerArmRsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsOutboardXEnableCheckBox.Value;
            app.LowerArmRsOutboardXEditField.Enable = value;
            app.LowerArmRsOutboardXMinEditField.Enable = value;
            app.LowerArmRsOutboardXMaxEditField.Enable = value;
            app.LowerArmRsOutboardXIncrEditField.Enable = value;
        end

        % Value changed function: LowerArmRsOutboardYEnableCheckBox
        function LowerArmRsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsOutboardYEnableCheckBox.Value;
            app.LowerArmRsOutboardYEditField.Enable = value;
            app.LowerArmRsOutboardYMinEditField.Enable = value;
            app.LowerArmRsOutboardYMaxEditField.Enable = value;
            app.LowerArmRsOutboardYIncrEditField.Enable = value;

        end

        % Value changed function: LowerArmRsOutboardZEnableCheckBox
        function LowerArmRsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.LowerArmRsOutboardZEnableCheckBox.Value;
            app.LowerArmRsOutboardZEditField.Enable = value;
            app.LowerArmRsOutboardZMinEditField.Enable = value;
            app.LowerArmRsOutboardZMaxEditField.Enable = value;
            app.LowerArmRsOutboardZIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsInboardFXEnableCheckBox
        function UpperWishsInboardFXEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardFXEnableCheckBox.Value;
            app.UpperWishsInboardFXEditField.Enable = value;
            app.UpperWishsInboardFXMinEditField.Enable = value;
            app.UpperWishsInboardFXMaxEditField.Enable = value;
            app.UpperWishsInboardFXIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsInboardFYEnableCheckBox
        function UpperWishsInboardFYEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardFYEnableCheckBox.Value;
            app.UpperWishsInboardFYEditField.Enable = value;
            app.UpperWishsInboardFYMinEditField.Enable = value;
            app.UpperWishsInboardFYMaxEditField.Enable = value;
            app.UpperWishsInboardFYIncrEditField.Enable = value;
        end

        % Value changed function: UpperWishsInboardFZEnableCheckBox
        function UpperWishsInboardFZEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardFZEnableCheckBox.Value;
            app.UpperWishsInboardFZEditField.Enable = value;
            app.UpperWishsInboardFZMinEditField.Enable = value;
            app.UpperWishsInboardFZMaxEditField.Enable = value;
            app.UpperWishsInboardFZIncrEditField.Enable = value;
        end

        % Value changed function: UpperWishsInboardRXEnableCheckBox
        function UpperWishsInboardRXEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardRXEnableCheckBox.Value;
            app.UpperWishsInboardRXEditField.Enable = value;
            app.UpperWishsInboardRXMinEditField.Enable = value;
            app.UpperWishsInboardRXMaxEditField.Enable = value;
            app.UpperWishsInboardRXIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsInboardRYEnableCheckBox
        function UpperWishsInboardRYEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardRYEnableCheckBox.Value;
            app.UpperWishsInboardRYEditField.Enable = value;
            app.UpperWishsInboardRYMinEditField.Enable = value;
            app.UpperWishsInboardRYMaxEditField.Enable = value;
            app.UpperWishsInboardRYIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsInboardRZEnableCheckBox
        function UpperWishsInboardRZEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsInboardRZEnableCheckBox.Value;
            app.UpperWishsInboardRZEditField.Enable = value;
            app.UpperWishsInboardRZMinEditField.Enable = value;
            app.UpperWishsInboardRZMaxEditField.Enable = value;
            app.UpperWishsInboardRZIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsOutboardXEnableCheckBox
        function UpperWishsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsOutboardXEnableCheckBox.Value;
            app.UpperWishsOutboardXEditField.Enable = value;
            app.UpperWishsOutboardXMinEditField.Enable = value;
            app.UpperWishsOutboardXMaxEditField.Enable = value;
            app.UpperWishsOutboardXIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsOutboardYEnableCheckBox
        function UpperWishsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsOutboardYEnableCheckBox.Value;
            app.UpperWishsOutboardYEditField.Enable = value;
            app.UpperWishsOutboardYMinEditField.Enable = value;
            app.UpperWishsOutboardYMaxEditField.Enable = value;
            app.UpperWishsOutboardYIncrEditField.Enable = value;

        end

        % Value changed function: UpperWishsOutboardZEnableCheckBox
        function UpperWishsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.UpperWishsOutboardZEnableCheckBox.Value;
            app.UpperWishsOutboardZEditField.Enable = value;
            app.UpperWishsOutboardZMinEditField.Enable = value;
            app.UpperWishsOutboardZMaxEditField.Enable = value;
            app.UpperWishsOutboardZIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardFXEnableCheckBox
        function LowerWishsInboardFXEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardFXEnableCheckBox.Value;
            app.LowerWishsInboardFXEditField.Enable = value;
            app.LowerWishsInboardFXMinEditField.Enable = value;
            app.LowerWishsInboardFXMaxEditField.Enable = value;
            app.LowerWishsInboardFXIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardFYEnableCheckBox
        function LowerWishsInboardFYEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardFYEnableCheckBox.Value;
            app.LowerWishsInboardFYEditField.Enable = value;
            app.LowerWishsInboardFYMinEditField.Enable = value;
            app.LowerWishsInboardFYMaxEditField.Enable = value;
            app.LowerWishsInboardFYIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardFZEnableCheckBox
        function LowerWishsInboardFZEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardFZEnableCheckBox.Value;
            app.LowerWishsInboardFZEditField.Enable = value;
            app.LowerWishsInboardFZMinEditField.Enable = value;
            app.LowerWishsInboardFZMaxEditField.Enable = value;
            app.LowerWishsInboardFZIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardRXEnableCheckBox
        function LowerWishsInboardRXEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardRXEnableCheckBox.Value;
            app.LowerWishsInboardRXEditField.Enable = value;
            app.LowerWishsInboardRXMinEditField.Enable = value;
            app.LowerWishsInboardRXMaxEditField.Enable = value;
            app.LowerWishsInboardRXIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardRYEnableCheckBox
        function LowerWishsInboardRYEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardRYEnableCheckBox.Value;
            app.LowerWishsInboardRYEditField.Enable = value;
            app.LowerWishsInboardRYMinEditField.Enable = value;
            app.LowerWishsInboardRYMaxEditField.Enable = value;
            app.LowerWishsInboardRYIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsInboardRZEnableCheckBox
        function LowerWishsInboardRZEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsInboardRZEnableCheckBox.Value;
            app.LowerWishsInboardRZEditField.Enable = value;
            app.LowerWishsInboardRZMinEditField.Enable = value;
            app.LowerWishsInboardRZMaxEditField.Enable = value;
            app.LowerWishsInboardRZIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsOutboardXEnableCheckBox
        function LowerWishsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsOutboardXEnableCheckBox.Value;
            app.LowerWishsOutboardXEditField.Enable = value;
            app.LowerWishsOutboardXMinEditField.Enable = value;
            app.LowerWishsOutboardXMaxEditField.Enable = value;
            app.LowerWishsOutboardXIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsOutboardYEnableCheckBox
        function LowerWishsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsOutboardYEnableCheckBox.Value;
            app.LowerWishsOutboardYEditField.Enable = value;
            app.LowerWishsOutboardYMinEditField.Enable = value;
            app.LowerWishsOutboardYMaxEditField.Enable = value;
            app.LowerWishsOutboardYIncrEditField.Enable = value;

        end

        % Value changed function: LowerWishsOutboardZEnableCheckBox
        function LowerWishsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.LowerWishsOutboardZEnableCheckBox.Value;
            app.LowerWishsOutboardZEditField.Enable = value;
            app.LowerWishsOutboardZMinEditField.Enable = value;
            app.LowerWishsOutboardZMaxEditField.Enable = value;
            app.LowerWishsOutboardZIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsInboardXEnableCheckBox
        function TrackRodsInboardXEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsInboardXEnableCheckBox.Value;
            app.TrackRodsInboardXEditField.Enable = value;
            app.TrackRodsInboardXMinEditField.Enable = value;
            app.TrackRodsInboardXMaxEditField.Enable = value;
            app.TrackRodsInboardXIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsInboardYEnableCheckBox
        function TrackRodsInboardYEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsInboardYEnableCheckBox.Value;
            app.TrackRodsInboardYEditField.Enable = value;
            app.TrackRodsInboardYMinEditField.Enable = value;
            app.TrackRodsInboardYMaxEditField.Enable = value;
            app.TrackRodsInboardYIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsInboardZEnableCheckBox
        function TrackRodsInboardZEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsInboardZEnableCheckBox.Value;
            app.TrackRodsInboardZEditField.Enable = value;
            app.TrackRodsInboardZMinEditField.Enable = value;
            app.TrackRodsInboardZMaxEditField.Enable = value;
            app.TrackRodsInboardZIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsOutboardXEnableCheckBox
        function TrackRodsOutboardXEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsOutboardXEnableCheckBox.Value;
            app.TrackRodsOutboardXEditField.Enable = value;
            app.TrackRodsOutboardXMinEditField.Enable = value;
            app.TrackRodsOutboardXMaxEditField.Enable = value;
            app.TrackRodsOutboardXIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsOutboardYEnableCheckBox
        function TrackRodsOutboardYEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsOutboardYEnableCheckBox.Value;
            app.TrackRodsOutboardYEditField.Enable = value;
            app.TrackRodsOutboardYMinEditField.Enable = value;
            app.TrackRodsOutboardYMaxEditField.Enable = value;
            app.TrackRodsOutboardYIncrEditField.Enable = value;

        end

        % Value changed function: TrackRodsOutboardZEnableCheckBox
        function TrackRodsOutboardZEnableCheckBoxValueChanged(app, event)
            value = app.TrackRodsOutboardZEnableCheckBox.Value;
            app.TrackRodsOutboardZEditField.Enable = value;
            app.TrackRodsOutboardZMinEditField.Enable = value;
            app.TrackRodsOutboardZMaxEditField.Enable = value;
            app.TrackRodsOutboardZIncrEditField.Enable = value;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            hp_count = 0;
            jnc = app.JounceEditField.Value;
            rbd = app.ReboundEditField.Value;
            Maneuver = sm_car_maneuverdata_knc(jnc,rbd,0.01,1.3,0.1,500,1200,1200);
            assignin('base','Maneuver',Maneuver);
            %% Split Upper Arm Front Inboard
            if(app.UpperArmFsInboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsInboardXMinEditField.Value: ...
                    app.UpperArmFsInboardXIncrEditField.Value: ...
                    app.UpperArmFsInboardXMaxEditField.Value;
            end
            if(app.UpperArmFsInboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsInboardYMinEditField.Value: ...
                    app.UpperArmFsInboardYIncrEditField.Value: ...
                    app.UpperArmFsInboardYMaxEditField.Value;
            end
            if(app.UpperArmFsInboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsInboardZMinEditField.Value: ...
                    app.UpperArmFsInboardZIncrEditField.Value: ...
                    app.UpperArmFsInboardZMaxEditField.Value;
            end
            %% Split Upper Arm Rear Inboard
            if(app.UpperArmRsInboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsInboardXMinEditField.Value: ...
                    app.UpperArmRsInboardXIncrEditField.Value: ...
                    app.UpperArmRsInboardXMaxEditField.Value;
            end
            if(app.UpperArmRsInboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsInboardYMinEditField.Value: ...
                    app.UpperArmRsInboardYIncrEditField.Value: ...
                    app.UpperArmRsInboardYMaxEditField.Value;
            end
            if(app.UpperArmRsInboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsInboardZMinEditField.Value: ...
                    app.UpperArmRsInboardZIncrEditField.Value: ...
                    app.UpperArmRsInboardZMaxEditField.Value;
            end
            %% Split Lower Arm Front Inboard
            if(app.LowerArmFsInboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsInboardXMinEditField.Value: ...
                    app.LowerArmFsInboardXIncrEditField.Value: ...
                    app.LowerArmFsInboardXMaxEditField.Value;
            end
            if(app.LowerArmFsInboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsInboardYMinEditField.Value: ...
                    app.LowerArmFsInboardYIncrEditField.Value: ...
                    app.LowerArmFsInboardYMaxEditField.Value;
            end
            if(app.LowerArmFsInboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsInboardZMinEditField.Value: ...
                    app.LowerArmFsInboardZIncrEditField.Value: ...
                    app.LowerArmFsInboardZMaxEditField.Value;
            end
            %% Split Lower Arm Rear Inboard
            if(app.LowerArmRsInboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsInboardXMinEditField.Value: ...
                    app.LowerArmRsInboardXIncrEditField.Value: ...
                    app.LowerArmRsInboardXMaxEditField.Value;
            end
            if(app.LowerArmRsInboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsInboardYMinEditField.Value: ...
                    app.LowerArmRsInboardYIncrEditField.Value: ...
                    app.LowerArmRsInboardYMaxEditField.Value;
            end
            if(app.LowerArmRsInboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsInboardZMinEditField.Value: ...
                    app.LowerArmRsInboardZIncrEditField.Value: ...
                    app.LowerArmRsInboardZMaxEditField.Value;
            end

            %% Split Upper Arm Front Outboard
            if(app.UpperArmFsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsOutboardXMinEditField.Value: ...
                    app.UpperArmFsOutboardXIncrEditField.Value: ...
                    app.UpperArmFsOutboardXMaxEditField.Value;
            end
            if(app.UpperArmFsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsOutboardYMinEditField.Value: ...
                    app.UpperArmFsOutboardYIncrEditField.Value: ...
                    app.UpperArmFsOutboardYMaxEditField.Value;
            end
            if(app.UpperArmFsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmFsOutboardZMinEditField.Value: ...
                    app.UpperArmFsOutboardZIncrEditField.Value: ...
                    app.UpperArmFsOutboardZMaxEditField.Value;
            end
            %% Split Upper Arm Rear Outboard
            if(app.UpperArmRsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsOutboardXMinEditField.Value: ...
                    app.UpperArmRsOutboardXIncrEditField.Value: ...
                    app.UpperArmRsOutboardXMaxEditField.Value;
            end
            if(app.UpperArmRsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsOutboardYMinEditField.Value: ...
                    app.UpperArmRsOutboardYIncrEditField.Value: ...
                    app.UpperArmRsOutboardYMaxEditField.Value;
            end
            if(app.UpperArmRsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperArmRsOutboardZMinEditField.Value: ...
                    app.UpperArmRsOutboardZIncrEditField.Value: ...
                    app.UpperArmRsOutboardZMaxEditField.Value;
            end
            %% Split Lower Arm Front Outboard
            if(app.LowerArmFsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsOutboardXMinEditField.Value: ...
                    app.LowerArmFsOutboardXIncrEditField.Value: ...
                    app.LowerArmFsOutboardXMaxEditField.Value;
            end
            if(app.LowerArmFsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsOutboardYMinEditField.Value: ...
                    app.LowerArmFsOutboardYIncrEditField.Value: ...
                    app.LowerArmFsOutboardYMaxEditField.Value;
            end
            if(app.LowerArmFsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmF';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmFsOutboardZMinEditField.Value: ...
                    app.LowerArmFsOutboardZIncrEditField.Value: ...
                    app.LowerArmFsOutboardZMaxEditField.Value;
            end
            %% Split Lower Arm Rear Outboard
            if(app.LowerArmRsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsOutboardXMinEditField.Value: ...
                    app.LowerArmRsOutboardXIncrEditField.Value: ...
                    app.LowerArmRsOutboardXMaxEditField.Value;
            end
            if(app.LowerArmRsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsOutboardYMinEditField.Value: ...
                    app.LowerArmRsOutboardYIncrEditField.Value: ...
                    app.LowerArmRsOutboardYMaxEditField.Value;
            end
            if(app.LowerArmRsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerArmR';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerArmRsOutboardZMinEditField.Value: ...
                    app.LowerArmRsOutboardZIncrEditField.Value: ...
                    app.LowerArmRsOutboardZMaxEditField.Value;
            end

            %% -- -- -- Wishbone Upper Front Inboard
            if(app.UpperWishsInboardFXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardFXMinEditField.Value: ...
                    app.UpperWishsInboardFXIncrEditField.Value: ...
                    app.UpperWishsInboardFXMaxEditField.Value;
            end
            if(app.UpperWishsInboardFYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardFYMinEditField.Value: ...
                    app.UpperWishsInboardFYIncrEditField.Value: ...
                    app.UpperWishsInboardFYMaxEditField.Value;
            end
            if(app.UpperWishsInboardFZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardFZMinEditField.Value: ...
                    app.UpperWishsInboardFZIncrEditField.Value: ...
                    app.UpperWishsInboardFZMaxEditField.Value;
            end

            %% Wishbone Upper Rear Inboard
            if(app.UpperWishsInboardRXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardRXMinEditField.Value: ...
                    app.UpperWishsInboardRXIncrEditField.Value: ...
                    app.UpperWishsInboardRXMaxEditField.Value;
            end
            if(app.UpperWishsInboardRYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardRYMinEditField.Value: ...
                    app.UpperWishsInboardRYIncrEditField.Value: ...
                    app.UpperWishsInboardRYMaxEditField.Value;
            end
            if(app.UpperWishsInboardRZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsInboardRZMinEditField.Value: ...
                    app.UpperWishsInboardRZIncrEditField.Value: ...
                    app.UpperWishsInboardRZMaxEditField.Value;
            end

            %% Wishbone Upper Outboard
            if(app.UpperWishsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsOutboardXMinEditField.Value: ...
                    app.UpperWishsOutboardXIncrEditField.Value: ...
                    app.UpperWishsOutboardXMaxEditField.Value;
            end
            if(app.UpperWishsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsOutboardYMinEditField.Value: ...
                    app.UpperWishsOutboardYIncrEditField.Value: ...
                    app.UpperWishsOutboardYMaxEditField.Value;
            end
            if(app.UpperWishsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'UpperWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.UpperWishsOutboardZMinEditField.Value: ...
                    app.UpperWishsOutboardZIncrEditField.Value: ...
                    app.UpperWishsOutboardZMaxEditField.Value;
            end
            %% -- -- -- Wishbone Lower Front Inboard
            if(app.LowerWishsInboardFXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardFXMinEditField.Value: ...
                    app.LowerWishsInboardFXIncrEditField.Value: ...
                    app.LowerWishsInboardFXMaxEditField.Value;
            end
            if(app.LowerWishsInboardFYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardFYMinEditField.Value: ...
                    app.LowerWishsInboardFYIncrEditField.Value: ...
                    app.LowerWishsInboardFYMaxEditField.Value;
            end
            if(app.LowerWishsInboardFZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardF';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardFZMinEditField.Value: ...
                    app.LowerWishsInboardFZIncrEditField.Value: ...
                    app.LowerWishsInboardFZMaxEditField.Value;
            end

            %% Wishbone Lower Rear Inboard
            if(app.LowerWishsInboardRXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardRXMinEditField.Value: ...
                    app.LowerWishsInboardRXIncrEditField.Value: ...
                    app.LowerWishsInboardRXMaxEditField.Value;
            end
            if(app.LowerWishsInboardRYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardRYMinEditField.Value: ...
                    app.LowerWishsInboardRYIncrEditField.Value: ...
                    app.LowerWishsInboardRYMaxEditField.Value;
            end
            if(app.LowerWishsInboardRZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sInboardR';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsInboardRZMinEditField.Value: ...
                    app.LowerWishsInboardRZIncrEditField.Value: ...
                    app.LowerWishsInboardRZMaxEditField.Value;
            end

            %% Wishbone Lower Outboard
            if(app.LowerWishsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsOutboardXMinEditField.Value: ...
                    app.LowerWishsOutboardXIncrEditField.Value: ...
                    app.LowerWishsOutboardXMaxEditField.Value;
            end
            if(app.LowerWishsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsOutboardYMinEditField.Value: ...
                    app.LowerWishsOutboardYIncrEditField.Value: ...
                    app.LowerWishsOutboardYMaxEditField.Value;
            end
            if(app.LowerWishsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'LowerWishbone';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.LowerWishsOutboardZMinEditField.Value: ...
                    app.LowerWishsOutboardZIncrEditField.Value: ...
                    app.LowerWishsOutboardZMaxEditField.Value;
            end

            %% Track Rod Inboard
            if(app.TrackRodsInboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsInboardXMinEditField.Value: ...
                    app.TrackRodsInboardXIncrEditField.Value: ...
                    app.TrackRodsInboardXMaxEditField.Value;
            end
            if(app.TrackRodsInboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsInboardYMinEditField.Value: ...
                    app.TrackRodsInboardYIncrEditField.Value: ...
                    app.TrackRodsInboardYMaxEditField.Value;
            end
            if(app.TrackRodsInboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sInboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsInboardZMinEditField.Value: ...
                    app.TrackRodsInboardZIncrEditField.Value: ...
                    app.TrackRodsInboardZMaxEditField.Value;
            end

            %% Track Rod Outboard
            if(app.TrackRodsOutboardXEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 1;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsOutboardXMinEditField.Value: ...
                    app.TrackRodsOutboardXIncrEditField.Value: ...
                    app.TrackRodsOutboardXMaxEditField.Value;
            end
            if(app.TrackRodsOutboardYEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 2;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsOutboardYMinEditField.Value: ...
                    app.TrackRodsOutboardYIncrEditField.Value: ...
                    app.TrackRodsOutboardYMaxEditField.Value;
            end
            if(app.TrackRodsOutboardZEnableCheckBox.Value)
                hp_count = hp_count+1;
                hp_list(hp_count).part  = 'TrackRod';
                hp_list(hp_count).point = 'sOutboard';
                hp_list(hp_count).index = 3;
                hp_list(hp_count).valueSet =  ...
                    app.TrackRodsOutboardZMinEditField.Value: ...
                    app.TrackRodsOutboardZIncrEditField.Value: ...
                    app.TrackRodsOutboardZMaxEditField.Value;
            end

            assignin('base','hp_list',hp_list);
            Vehicle = evalin('base','Vehicle');
            mdl = app.MainModelEditField.Value;
            mdl_name = strrep(mdl,'.slx','');
            metricFieldName = app.MetricDropDown.Value;
            tgtValue = app.TargetValueEditField.Value;
            assignin('base','tgtValue',tgtValue)
            % Run analysis
            if(strcmp(app.TestDropDown.Value,'Sweep'))
                evalstr = ['[simInput, simOut, TSuspMetricsSet] = testrig_quarter_car_sweep(''' mdl_name ''',Vehicle,hp_list,Maneuver);'];
            else
                evalstr = ['[xFinal,fval,TSuspMetrics] = testrig_quarter_car_optim(''' mdl_name ''',Vehicle,hp_list,''' metricFieldName ''',tgtValue,Maneuver)'];
            end
            disp(evalstr)
            evalin('base',evalstr);

            % Plot results
            if(strcmp(app.TestDropDown.Value,'Sweep'))
                evalstr = ['TSuspMetricsReq = testrig_quarter_car_sweep_plot(hp_list,TSuspMetricsSet,''' metricFieldName ''')'];
                disp(evalstr)
                evalin('base',evalstr);
            end

        end

        % Value changed function: TestDropDown
        function TestDropDownValueChanged(app, event)
            value = app.TestDropDown.Value;
            h= findobj(app.SimscapeSuspensionTesterUIFigure,'Tag','Incr');
            if(strcmp(value,'Sweep'))
                set(h,'Enable','on')
                set(app.TargetValueEditField,'Enable','off')
            else
                set(h,'Enable','off')
                set(app.TargetValueEditField,'Enable','on')
            end
        end

        % Button pushed function: ReloadValuesButton
        function ReloadValuesButtonPushed(app, event)
            startupFcn(app)
        end

        % Button pushed function: SelectMainModelButton
        function SelectMainModelButtonPushed(app, event)
            curr_proj = simulinkproject;
            projdir = curr_proj.RootFolder;
            [model_name] = uigetfile([fileparts(which('testrig_quarter_car_doublewishbone.slx')) filesep '*.slx'],'Select Simscape Vehicle Templates model');
            if(model_name)
                app.MainModelEditField.Value = model_name(1:end-4);
            end
        end

        % Button pushed function: OpenMainModelButton
        function OpenMainModelButtonPushed(app, event)
            model_name = app.MainModelEditField.Value;
            str = ['open_system(''' model_name ''')'];
            disp(str)
            evalin('base',str);

        end

        % Value changed function: SetAllUseCheckBox
        function SetAllUseCheckBoxValueChanged(app, event)
            value = app.SetAllUseCheckBox.Value;
            if(strcmp(app.UpperWishboneHardpointsPanel.Enable,'on'))
                app.UpperWishsInboardFXEnableCheckBox.Value = value;
                app.UpperWishsInboardFXEnableCheckBoxValueChanged
                app.UpperWishsInboardFYEnableCheckBox.Value = value;
                app.UpperWishsInboardFYEnableCheckBoxValueChanged
                app.UpperWishsInboardFZEnableCheckBox.Value = value;
                app.UpperWishsInboardFZEnableCheckBoxValueChanged
                app.UpperWishsInboardRXEnableCheckBox.Value = value;
                app.UpperWishsInboardRXEnableCheckBoxValueChanged
                app.UpperWishsInboardRYEnableCheckBox.Value = value;
                app.UpperWishsInboardRYEnableCheckBoxValueChanged
                app.UpperWishsInboardRZEnableCheckBox.Value = value;
                app.UpperWishsInboardRZEnableCheckBoxValueChanged
                app.UpperWishsOutboardXEnableCheckBox.Value = value;
                app.UpperWishsOutboardXEnableCheckBoxValueChanged
                app.UpperWishsOutboardYEnableCheckBox.Value = value;
                app.UpperWishsOutboardYEnableCheckBoxValueChanged
                app.UpperWishsOutboardZEnableCheckBox.Value = value;
                app.UpperWishsOutboardZEnableCheckBoxValueChanged
            end
            if(strcmp(app.LowerWishboneHardpointsPanel.Enable,'on'))
                app.LowerWishsInboardFXEnableCheckBox.Value = value;
                app.LowerWishsInboardFXEnableCheckBoxValueChanged
                app.LowerWishsInboardFYEnableCheckBox.Value = value;
                app.LowerWishsInboardFYEnableCheckBoxValueChanged
                app.LowerWishsInboardFZEnableCheckBox.Value = value;
                app.LowerWishsInboardFZEnableCheckBoxValueChanged
                app.LowerWishsInboardRXEnableCheckBox.Value = value;
                app.LowerWishsInboardRXEnableCheckBoxValueChanged
                app.LowerWishsInboardRYEnableCheckBox.Value = value;
                app.LowerWishsInboardRYEnableCheckBoxValueChanged
                app.LowerWishsInboardRZEnableCheckBox.Value = value;
                app.LowerWishsInboardRZEnableCheckBoxValueChanged
                app.LowerWishsOutboardXEnableCheckBox.Value = value;
                app.LowerWishsOutboardXEnableCheckBoxValueChanged
                app.LowerWishsOutboardYEnableCheckBox.Value = value;
                app.LowerWishsOutboardYEnableCheckBoxValueChanged
                app.LowerWishsOutboardZEnableCheckBox.Value = value;
                app.LowerWishsOutboardZEnableCheckBoxValueChanged
            end
            if(strcmp(app.SplitUpperArmHardpointsPanel.Enable,'on'))
                app.UpperArmFsInboardXEnableCheckBox.Value = value;
                app.UpperArmFsInboardXEnableCheckBoxValueChanged
                app.UpperArmFsInboardYEnableCheckBox.Value = value;
                app.UpperArmFsInboardYEnableCheckBoxValueChanged
                app.UpperArmFsInboardZEnableCheckBox.Value = value;
                app.UpperArmFsInboardZEnableCheckBoxValueChanged
                app.UpperArmRsInboardXEnableCheckBox.Value = value;
                app.UpperArmRsInboardXEnableCheckBoxValueChanged
                app.UpperArmRsInboardYEnableCheckBox.Value = value;
                app.UpperArmRsInboardYEnableCheckBoxValueChanged
                app.UpperArmRsInboardZEnableCheckBox.Value = value;
                app.UpperArmRsInboardZEnableCheckBoxValueChanged
                app.UpperArmFsOutboardXEnableCheckBox.Value = value;
                app.UpperArmFsOutboardXEnableCheckBoxValueChanged
                app.UpperArmFsOutboardYEnableCheckBox.Value = value;
                app.UpperArmFsOutboardYEnableCheckBoxValueChanged
                app.UpperArmFsOutboardZEnableCheckBox.Value = value;
                app.UpperArmFsOutboardZEnableCheckBoxValueChanged
                app.UpperArmRsOutboardXEnableCheckBox.Value = value;
                app.UpperArmRsOutboardXEnableCheckBoxValueChanged
                app.UpperArmRsOutboardYEnableCheckBox.Value = value;
                app.UpperArmRsOutboardYEnableCheckBoxValueChanged
                app.UpperArmRsOutboardZEnableCheckBox.Value = value;
                app.UpperArmRsOutboardZEnableCheckBoxValueChanged
            end
            if(strcmp(app.SplitLowerArmHardpointsPanel.Enable,'on'))
                app.LowerArmFsInboardXEnableCheckBox.Value = value;
                app.LowerArmFsInboardXEnableCheckBoxValueChanged
                app.LowerArmFsInboardYEnableCheckBox.Value = value;
                app.LowerArmFsInboardYEnableCheckBoxValueChanged
                app.LowerArmFsInboardZEnableCheckBox.Value = value;
                app.LowerArmFsInboardZEnableCheckBoxValueChanged
                app.LowerArmRsInboardXEnableCheckBox.Value = value;
                app.LowerArmRsInboardXEnableCheckBoxValueChanged
                app.LowerArmRsInboardYEnableCheckBox.Value = value;
                app.LowerArmRsInboardYEnableCheckBoxValueChanged
                app.LowerArmRsInboardZEnableCheckBox.Value = value;
                app.LowerArmRsInboardZEnableCheckBoxValueChanged
                app.LowerArmFsOutboardXEnableCheckBox.Value = value;
                app.LowerArmFsOutboardXEnableCheckBoxValueChanged
                app.LowerArmFsOutboardYEnableCheckBox.Value = value;
                app.LowerArmFsOutboardYEnableCheckBoxValueChanged
                app.LowerArmFsOutboardZEnableCheckBox.Value = value;
                app.LowerArmFsOutboardZEnableCheckBoxValueChanged
                app.LowerArmRsOutboardXEnableCheckBox.Value = value;
                app.LowerArmRsOutboardXEnableCheckBoxValueChanged
                app.LowerArmRsOutboardYEnableCheckBox.Value = value;
                app.LowerArmRsOutboardYEnableCheckBoxValueChanged
                app.LowerArmRsOutboardZEnableCheckBox.Value = value;
                app.LowerArmRsOutboardZEnableCheckBoxValueChanged
            end
            app.TrackRodsInboardXEnableCheckBox.Value = value;
            app.TrackRodsInboardXEnableCheckBoxValueChanged
            app.TrackRodsInboardYEnableCheckBox.Value = value;
            app.TrackRodsInboardYEnableCheckBoxValueChanged
            app.TrackRodsInboardZEnableCheckBox.Value = value;
            app.TrackRodsInboardZEnableCheckBoxValueChanged
            app.TrackRodsOutboardXEnableCheckBox.Value = value;
            app.TrackRodsOutboardXEnableCheckBoxValueChanged
            app.TrackRodsOutboardYEnableCheckBox.Value = value;
            app.TrackRodsOutboardYEnableCheckBoxValueChanged
            app.TrackRodsOutboardZEnableCheckBox.Value = value;
            app.TrackRodsOutboardZEnableCheckBoxValueChanged

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimscapeSuspensionTesterUIFigure = uifigure('Visible', 'off');
            app.SimscapeSuspensionTesterUIFigure.Name = 'Simscape Suspension Tester';
            app.MinmLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.MinmLabel.Tooltip = {'Min value for sweep or optimization'};
            app.MinmLabel.Text = 'Min (m)';
            app.MaxmLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.MaxmLabel.Tooltip = {'Max value for sweep or optimization'};
            app.MaxmLabel.Text = 'Max (m)';
            app.TestDropDownLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.TestDropDownLabel.Text = 'Test';
            app.TestDropDown = uidropdown(app.SimscapeSuspensionTesterUIFigure);
            app.TestDropDown.Items = {'Sweep', 'Optim'};
            app.TestDropDown.ValueChangedFcn = createCallbackFcn(app, @TestDropDownValueChanged, true);
            app.TestDropDown.Tooltip = {'Select type of test to run.'};
            app.TestDropDown.Value = 'Sweep';
            app.IncrementLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.IncrementLabel.Tooltip = {'Steps for sweep.'};
            app.IncrementLabel.Text = 'Increment';
            app.UseLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.UseLabel.Tooltip = {'Tune or sweep this coordinate.'};
            app.UseLabel.Text = 'Use';
            app.RunButton = uibutton(app.SimscapeSuspensionTesterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run selected sweep or optimization.'};
            app.RunButton.Text = 'Run';
            app.ValuemLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.ValuemLabel.Tooltip = {'Value loaded from MATLAB workspace.'};
            app.ValuemLabel.Text = 'Value (m)';
            app.MetricDropDownLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.SimscapeSuspensionTesterUIFigure);
            app.MetricDropDown.Items = {'Bump Steer', 'Bump Camber'};
            app.MetricDropDown.Tooltip = {'Select metric to view in sweep or optimize. '};
            app.MetricDropDown.Value = 'Bump Steer';
            app.ModelEditFieldLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.ModelEditFieldLabel.Text = 'Model';
            app.MainModelEditField = uieditfield(app.SimscapeSuspensionTesterUIFigure, 'text');
            app.MainModelEditField.Value = 'testrig_quarter_car';
            app.SuspensionTesterLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.SuspensionTesterLabel.Text = 'Suspension Tester';
            app.SplitUpperArmHardpointsPanel = uipanel(app.SimscapeSuspensionTesterUIFigure);
            app.SplitUpperArmHardpointsPanel.Title = 'Split Upper Arm Hardpoints';
            app.UpperArmFsInboardXEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsInboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsInboardXEnableCheckBoxValueChanged, true);
            app.UpperArmFsInboardXEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsInboardXEnableCheckBox.Text = '';
            app.UpperArmFsInboardYEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsInboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsInboardYEnableCheckBoxValueChanged, true);
            app.UpperArmFsInboardYEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsInboardYEnableCheckBox.Text = '';
            app.UpperArmFsInboardZEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsInboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsInboardZEnableCheckBoxValueChanged, true);
            app.UpperArmFsInboardZEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsInboardZEnableCheckBox.Text = '';
            app.UpperArmRsInboardZEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsInboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsInboardZEnableCheckBoxValueChanged, true);
            app.UpperArmRsInboardZEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsInboardZEnableCheckBox.Text = '';
            app.UpperArmRsInboardYEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsInboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsInboardYEnableCheckBoxValueChanged, true);
            app.UpperArmRsInboardYEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsInboardYEnableCheckBox.Text = '';
            app.UpperArmRsInboardXEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsInboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsInboardXEnableCheckBoxValueChanged, true);
            app.UpperArmRsInboardXEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsInboardXEnableCheckBox.Text = '';
            app.UpperArmRsOutboardZEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsOutboardZEnableCheckBoxValueChanged, true);
            app.UpperArmRsOutboardZEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsOutboardZEnableCheckBox.Text = '';
            app.UpperArmRsOutboardYEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsOutboardYEnableCheckBoxValueChanged, true);
            app.UpperArmRsOutboardYEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsOutboardYEnableCheckBox.Text = '';
            app.UpperArmRsOutboardXEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmRsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmRsOutboardXEnableCheckBoxValueChanged, true);
            app.UpperArmRsOutboardXEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmRsOutboardXEnableCheckBox.Text = '';
            app.UpperArmFsOutboardZEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsOutboardZEnableCheckBoxValueChanged, true);
            app.UpperArmFsOutboardZEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsOutboardZEnableCheckBox.Text = '';
            app.UpperArmFsOutboardYEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsOutboardYEnableCheckBoxValueChanged, true);
            app.UpperArmFsOutboardYEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsOutboardYEnableCheckBox.Text = '';
            app.UpperArmFsOutboardXEnableCheckBox = uicheckbox(app.SplitUpperArmHardpointsPanel);
            app.UpperArmFsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperArmFsOutboardXEnableCheckBoxValueChanged, true);
            app.UpperArmFsOutboardXEnableCheckBox.Tag = 'SUAUse';
            app.UpperArmFsOutboardXEnableCheckBox.Text = '';
            app.UpperArmFsInboardXMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardXMinEditField.Enable = 'off';
            app.UpperArmFsInboardYMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardYMinEditField.Enable = 'off';
            app.UpperArmFsInboardZMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardZMinEditField.Enable = 'off';
            app.UpperArmFsInboardXMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardXMaxEditField.Enable = 'off';
            app.UpperArmFsInboardYMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardYMaxEditField.Enable = 'off';
            app.UpperArmFsInboardZMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardZMaxEditField.Enable = 'off';
            app.UpperArmFsInboardXIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardXIncrEditField.Tag = 'Incr';
            app.UpperArmFsInboardXIncrEditField.Enable = 'off';
            app.UpperArmFsInboardYIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardYIncrEditField.Tag = 'Incr';
            app.UpperArmFsInboardYIncrEditField.Enable = 'off';
            app.UpperArmFsInboardZIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardZIncrEditField.Tag = 'Incr';
            app.UpperArmFsInboardZIncrEditField.Enable = 'off';
            app.UpperArmRsInboardZIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardZIncrEditField.Tag = 'Incr';
            app.UpperArmRsInboardZIncrEditField.Enable = 'off';
            app.UpperArmRsInboardZMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardZMaxEditField.Enable = 'off';
            app.UpperArmRsInboardZMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardZMinEditField.Enable = 'off';
            app.UpperArmRsInboardYIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardYIncrEditField.Tag = 'Incr';
            app.UpperArmRsInboardYIncrEditField.Enable = 'off';
            app.UpperArmRsInboardYMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardYMaxEditField.Enable = 'off';
            app.UpperArmRsInboardYMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardYMinEditField.Enable = 'off';
            app.UpperArmRsInboardXIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardXIncrEditField.Tag = 'Incr';
            app.UpperArmRsInboardXIncrEditField.Enable = 'off';
            app.UpperArmRsInboardXMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardXMaxEditField.Enable = 'off';
            app.UpperArmRsInboardXMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardXMinEditField.Enable = 'off';
            app.FrontInboardXmLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardXmLabel.Text = 'Front Inboard X';
            app.UpperArmFsInboardXEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardXEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardXEditField.Editable = 'off';
            app.UpperArmFsInboardXEditField.Enable = 'off';
            app.FrontInboardYmLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardYmLabel.Text = 'Front Inboard Y';
            app.UpperArmFsInboardYEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardYEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardYEditField.Editable = 'off';
            app.UpperArmFsInboardYEditField.Enable = 'off';
            app.FrontInboardZLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardZLabel.Text = 'Front Inboard Z';
            app.UpperArmFsInboardZEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsInboardZEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsInboardZEditField.Editable = 'off';
            app.UpperArmFsInboardZEditField.Enable = 'off';
            app.RearInboardZLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardZLabel.Text = 'Rear Inboard Z';
            app.UpperArmRsInboardZEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardZEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardZEditField.Editable = 'off';
            app.UpperArmRsInboardZEditField.Enable = 'off';
            app.RearInboardYLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardYLabel.Text = 'Rear Inboard Y';
            app.UpperArmRsInboardYEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardYEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardYEditField.Editable = 'off';
            app.UpperArmRsInboardYEditField.Enable = 'off';
            app.RearInboardXLabel = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardXLabel.Text = 'Rear Inboard X';
            app.UpperArmRsInboardXEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsInboardXEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsInboardXEditField.Editable = 'off';
            app.UpperArmRsInboardXEditField.Enable = 'off';
            app.UpperArmRsOutboardZIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardZIncrEditField.Tag = 'Incr';
            app.UpperArmRsOutboardZIncrEditField.Enable = 'off';
            app.UpperArmRsOutboardYIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardYIncrEditField.Tag = 'Incr';
            app.UpperArmRsOutboardYIncrEditField.Enable = 'off';
            app.UpperArmRsOutboardXIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardXIncrEditField.Tag = 'Incr';
            app.UpperArmRsOutboardXIncrEditField.Enable = 'off';
            app.UpperArmFsOutboardZIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardZIncrEditField.Tag = 'Incr';
            app.UpperArmFsOutboardZIncrEditField.Enable = 'off';
            app.UpperArmFsOutboardYIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardYIncrEditField.Tag = 'Incr';
            app.UpperArmFsOutboardYIncrEditField.Enable = 'off';
            app.UpperArmFsOutboardXIncrEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardXIncrEditField.Tag = 'Incr';
            app.UpperArmFsOutboardXIncrEditField.Enable = 'off';
            app.UpperArmRsOutboardZMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardZMaxEditField.Enable = 'off';
            app.UpperArmRsOutboardYMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardYMaxEditField.Enable = 'off';
            app.UpperArmRsOutboardXMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardXMaxEditField.Enable = 'off';
            app.UpperArmFsOutboardZMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardZMaxEditField.Enable = 'off';
            app.UpperArmFsOutboardYMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardYMaxEditField.Enable = 'off';
            app.UpperArmFsOutboardXMaxEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardXMaxEditField.Enable = 'off';
            app.UpperArmRsOutboardZMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardZMinEditField.Enable = 'off';
            app.UpperArmRsOutboardYMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardYMinEditField.Enable = 'off';
            app.UpperArmRsOutboardXMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardXMinEditField.Enable = 'off';
            app.UpperArmFsOutboardZMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardZMinEditField.Enable = 'off';
            app.UpperArmFsOutboardYMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardYMinEditField.Enable = 'off';
            app.UpperArmFsOutboardXMinEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardXMinEditField.Enable = 'off';
            app.UpperArmRsOutboardZEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardZEditField.Editable = 'off';
            app.UpperArmRsOutboardZEditField.Enable = 'off';
            app.RearInboardYLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardYLabel_2.Text = 'Rear Outboard Y';
            app.UpperArmRsOutboardYEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardYEditField.Editable = 'off';
            app.UpperArmRsOutboardYEditField.Enable = 'off';
            app.RearInboardXLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardXLabel_2.Text = 'Rear Outboard X';
            app.UpperArmRsOutboardXEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmRsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmRsOutboardXEditField.Editable = 'off';
            app.UpperArmRsOutboardXEditField.Enable = 'off';
            app.RearInboardZLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.RearInboardZLabel_2.Text = 'Rear Outboard Z';
            app.FrontInboardZLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardZLabel_2.Text = 'Front Outboard Z';
            app.UpperArmFsOutboardZEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardZEditField.Editable = 'off';
            app.UpperArmFsOutboardZEditField.Enable = 'off';
            app.FrontInboardYmLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardYmLabel_2.Text = 'Front Outboard Y';
            app.UpperArmFsOutboardYEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardYEditField.Editable = 'off';
            app.UpperArmFsOutboardYEditField.Enable = 'off';
            app.FrontInboardXmLabel_2 = uilabel(app.SplitUpperArmHardpointsPanel);
            app.FrontInboardXmLabel_2.Text = 'Front Outboard X';
            app.UpperArmFsOutboardXEditField = uieditfield(app.SplitUpperArmHardpointsPanel, 'numeric');
            app.UpperArmFsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.UpperArmFsOutboardXEditField.Editable = 'off';
            app.UpperArmFsOutboardXEditField.Enable = 'off';
            app.SplitLowerArmHardpointsPanel = uipanel(app.SimscapeSuspensionTesterUIFigure);
            app.SplitLowerArmHardpointsPanel.Title = 'Split Lower Arm Hardpoints';
            app.LowerArmRsInboardZEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsInboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsInboardZEnableCheckBoxValueChanged, true);
            app.LowerArmRsInboardZEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsInboardZEnableCheckBox.Text = '';
            app.LowerArmRsInboardYEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsInboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsInboardYEnableCheckBoxValueChanged, true);
            app.LowerArmRsInboardYEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsInboardYEnableCheckBox.Text = '';
            app.LowerArmRsInboardXEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsInboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsInboardXEnableCheckBoxValueChanged, true);
            app.LowerArmRsInboardXEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsInboardXEnableCheckBox.Text = '';
            app.LowerArmFsInboardXEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsInboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsInboardXEnableCheckBoxValueChanged, true);
            app.LowerArmFsInboardXEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsInboardXEnableCheckBox.Text = '';
            app.LowerArmFsInboardYEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsInboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsInboardYEnableCheckBoxValueChanged, true);
            app.LowerArmFsInboardYEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsInboardYEnableCheckBox.Text = '';
            app.LowerArmFsInboardZEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsInboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsInboardZEnableCheckBoxValueChanged, true);
            app.LowerArmFsInboardZEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsInboardZEnableCheckBox.Text = '';
            app.LowerArmRsOutboardZEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsOutboardZEnableCheckBoxValueChanged, true);
            app.LowerArmRsOutboardZEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsOutboardZEnableCheckBox.Text = '';
            app.LowerArmRsOutboardYEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsOutboardYEnableCheckBoxValueChanged, true);
            app.LowerArmRsOutboardYEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsOutboardYEnableCheckBox.Text = '';
            app.LowerArmRsOutboardXEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmRsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmRsOutboardXEnableCheckBoxValueChanged, true);
            app.LowerArmRsOutboardXEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmRsOutboardXEnableCheckBox.Text = '';
            app.LowerArmFsOutboardZEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsOutboardZEnableCheckBoxValueChanged, true);
            app.LowerArmFsOutboardZEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsOutboardZEnableCheckBox.Text = '';
            app.LowerArmFsOutboardYEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsOutboardYEnableCheckBoxValueChanged, true);
            app.LowerArmFsOutboardYEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsOutboardYEnableCheckBox.Text = '';
            app.LowerArmFsOutboardXEnableCheckBox = uicheckbox(app.SplitLowerArmHardpointsPanel);
            app.LowerArmFsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerArmFsOutboardXEnableCheckBoxValueChanged, true);
            app.LowerArmFsOutboardXEnableCheckBox.Tag = 'SLAUse';
            app.LowerArmFsOutboardXEnableCheckBox.Text = '';
            app.LowerArmRsInboardZIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardZIncrEditField.Tag = 'Incr';
            app.LowerArmRsInboardZIncrEditField.Enable = 'off';
            app.LowerArmRsInboardYIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardYIncrEditField.Tag = 'Incr';
            app.LowerArmRsInboardYIncrEditField.Enable = 'off';
            app.LowerArmRsInboardXIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardXIncrEditField.Tag = 'Incr';
            app.LowerArmRsInboardXIncrEditField.Enable = 'off';
            app.LowerArmFsInboardZIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardZIncrEditField.Tag = 'Incr';
            app.LowerArmFsInboardZIncrEditField.Enable = 'off';
            app.LowerArmFsInboardYIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardYIncrEditField.Tag = 'Incr';
            app.LowerArmFsInboardYIncrEditField.Enable = 'off';
            app.LowerArmFsInboardXIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardXIncrEditField.Tag = 'Incr';
            app.LowerArmFsInboardXIncrEditField.Enable = 'off';
            app.LowerArmRsInboardZMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardZMaxEditField.Enable = 'off';
            app.LowerArmRsInboardYMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardYMaxEditField.Enable = 'off';
            app.LowerArmRsInboardXMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardXMaxEditField.Enable = 'off';
            app.LowerArmFsInboardZMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardZMaxEditField.Enable = 'off';
            app.LowerArmFsInboardYMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardYMaxEditField.Enable = 'off';
            app.LowerArmFsInboardXMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardXMaxEditField.Enable = 'off';
            app.LowerArmRsInboardZMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardZMinEditField.Enable = 'off';
            app.LowerArmRsInboardYMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardYMinEditField.Enable = 'off';
            app.LowerArmRsInboardXMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardXMinEditField.Enable = 'off';
            app.LowerArmFsInboardZMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardZMinEditField.Enable = 'off';
            app.LowerArmFsInboardYMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardYMinEditField.Enable = 'off';
            app.LowerArmFsInboardXMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardXMinEditField.Enable = 'off';
            app.FrontInboardXLabel = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardXLabel.Text = 'Front Inboard X';
            app.FrontInboardYLabel = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardYLabel.Text = 'Front Inboard Y';
            app.FrontInboardZLabel_3 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardZLabel_3.Text = 'Front Inboard Z';
            app.RearInboardZLabel_3 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardZLabel_3.Text = 'Rear Inboard Z';
            app.LowerArmRsInboardZEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardZEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardZEditField.Editable = 'off';
            app.LowerArmRsInboardZEditField.Enable = 'off';
            app.RearInboardYLabel_3 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardYLabel_3.Text = 'Rear Inboard Y';
            app.LowerArmRsInboardYEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardYEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardYEditField.Editable = 'off';
            app.LowerArmRsInboardYEditField.Enable = 'off';
            app.RearInboardXLabel_3 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardXLabel_3.Text = 'Rear Inboard X';
            app.LowerArmRsInboardXEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsInboardXEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsInboardXEditField.Editable = 'off';
            app.LowerArmRsInboardXEditField.Enable = 'off';
            app.LowerArmFsInboardZEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardZEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardZEditField.Editable = 'off';
            app.LowerArmFsInboardZEditField.Enable = 'off';
            app.LowerArmFsInboardYEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardYEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardYEditField.Editable = 'off';
            app.LowerArmFsInboardYEditField.Enable = 'off';
            app.LowerArmFsInboardXEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsInboardXEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsInboardXEditField.Editable = 'off';
            app.LowerArmFsInboardXEditField.Enable = 'off';
            app.RearInboardZLabel_4 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardZLabel_4.Text = 'Rear Outboard Z';
            app.RearInboardYLabel_4 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardYLabel_4.Text = 'Rear Outboard Y';
            app.RearInboardXLabel_4 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.RearInboardXLabel_4.Text = 'Rear Outboard X';
            app.FrontInboardZLabel_4 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardZLabel_4.Text = 'Front Outboard Z';
            app.FrontInboardYLabel_2 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardYLabel_2.Text = 'Front Outboard Y';
            app.LowerArmRsOutboardZIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardZIncrEditField.Tag = 'Incr';
            app.LowerArmRsOutboardZIncrEditField.Enable = 'off';
            app.LowerArmRsOutboardYIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardYIncrEditField.Tag = 'Incr';
            app.LowerArmRsOutboardYIncrEditField.Enable = 'off';
            app.LowerArmRsOutboardXIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardXIncrEditField.Tag = 'Incr';
            app.LowerArmRsOutboardXIncrEditField.Enable = 'off';
            app.LowerArmFsOutboardZIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardZIncrEditField.Tag = 'Incr';
            app.LowerArmFsOutboardZIncrEditField.Enable = 'off';
            app.LowerArmFsOutboardYIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardYIncrEditField.Tag = 'Incr';
            app.LowerArmFsOutboardYIncrEditField.Enable = 'off';
            app.LowerArmFsOutboardXIncrEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardXIncrEditField.Tag = 'Incr';
            app.LowerArmFsOutboardXIncrEditField.Enable = 'off';
            app.LowerArmRsOutboardZMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardZMaxEditField.Enable = 'off';
            app.LowerArmRsOutboardYMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardYMaxEditField.Enable = 'off';
            app.LowerArmRsOutboardXMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardXMaxEditField.Enable = 'off';
            app.LowerArmFsOutboardZMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardZMaxEditField.Enable = 'off';
            app.LowerArmFsOutboardYMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardYMaxEditField.Enable = 'off';
            app.LowerArmFsOutboardXMaxEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardXMaxEditField.Enable = 'off';
            app.LowerArmRsOutboardZMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardZMinEditField.Enable = 'off';
            app.LowerArmRsOutboardYMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardYMinEditField.Enable = 'off';
            app.LowerArmRsOutboardXMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardXMinEditField.Enable = 'off';
            app.LowerArmFsOutboardZMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardZMinEditField.Enable = 'off';
            app.LowerArmFsOutboardYMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardYMinEditField.Enable = 'off';
            app.LowerArmFsOutboardXMinEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardXMinEditField.Enable = 'off';
            app.LowerArmRsOutboardZEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardZEditField.Editable = 'off';
            app.LowerArmRsOutboardZEditField.Enable = 'off';
            app.LowerArmRsOutboardYEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardYEditField.Editable = 'off';
            app.LowerArmRsOutboardYEditField.Enable = 'off';
            app.LowerArmRsOutboardXEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmRsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmRsOutboardXEditField.Editable = 'off';
            app.LowerArmRsOutboardXEditField.Enable = 'off';
            app.LowerArmFsOutboardZEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardZEditField.Editable = 'off';
            app.LowerArmFsOutboardZEditField.Enable = 'off';
            app.LowerArmFsOutboardYEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardYEditField.Editable = 'off';
            app.LowerArmFsOutboardYEditField.Enable = 'off';
            app.FrontInboardXLabel_2 = uilabel(app.SplitLowerArmHardpointsPanel);
            app.FrontInboardXLabel_2.Text = 'Front Outboard X';
            app.LowerArmFsOutboardXEditField = uieditfield(app.SplitLowerArmHardpointsPanel, 'numeric');
            app.LowerArmFsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.LowerArmFsOutboardXEditField.Editable = 'off';
            app.LowerArmFsOutboardXEditField.Enable = 'off';
            app.ValuemLabel_2 = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.ValuemLabel_2.Tooltip = {'Value loaded from MATLAB workspace.'};
            app.ValuemLabel_2.Text = 'Value (m)';
            app.MinmLabel_2 = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.MinmLabel_2.Tooltip = {'Min value for sweep or optimization'};
            app.MinmLabel_2.Text = 'Min (m)';
            app.MaxmLabel_2 = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.MaxmLabel_2.Tooltip = {'Max value for sweep or optimization'};
            app.MaxmLabel_2.Text = 'Max (m)';
            app.IncrementLabel_2 = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.IncrementLabel_2.Text = 'Increment';
            app.UseLabel_2 = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.UseLabel_2.Tooltip = {'Tune or sweep this coordinate.'};
            app.UseLabel_2.Text = 'Use';
            app.UpperWishboneHardpointsPanel = uipanel(app.SimscapeSuspensionTesterUIFigure);
            app.UpperWishboneHardpointsPanel.Title = 'Upper Wishbone Hardpoints';
            app.UpperWishsInboardFXEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardFXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardFXEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardFXEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardFXEnableCheckBox.Text = '';
            app.UpperWishsInboardFYEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardFYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardFYEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardFYEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardFYEnableCheckBox.Text = '';
            app.UpperWishsInboardFZEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardFZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardFZEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardFZEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardFZEnableCheckBox.Text = '';
            app.UpperWishsInboardRZEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardRZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardRZEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardRZEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardRZEnableCheckBox.Text = '';
            app.UpperWishsInboardRYEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardRYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardRYEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardRYEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardRYEnableCheckBox.Text = '';
            app.UpperWishsInboardRXEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsInboardRXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsInboardRXEnableCheckBoxValueChanged, true);
            app.UpperWishsInboardRXEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsInboardRXEnableCheckBox.Text = '';
            app.UpperWishsOutboardZEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsOutboardZEnableCheckBoxValueChanged, true);
            app.UpperWishsOutboardZEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsOutboardZEnableCheckBox.Text = '';
            app.UpperWishsOutboardYEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsOutboardYEnableCheckBoxValueChanged, true);
            app.UpperWishsOutboardYEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsOutboardYEnableCheckBox.Text = '';
            app.UpperWishsOutboardXEnableCheckBox = uicheckbox(app.UpperWishboneHardpointsPanel);
            app.UpperWishsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpperWishsOutboardXEnableCheckBoxValueChanged, true);
            app.UpperWishsOutboardXEnableCheckBox.Tag = 'WUAUse';
            app.UpperWishsOutboardXEnableCheckBox.Text = '';
            app.UpperWishsInboardFXIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFXIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardFXIncrEditField.Enable = 'off';
            app.UpperWishsInboardFYIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFYIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardFYIncrEditField.Enable = 'off';
            app.UpperWishsInboardFZIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFZIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardFZIncrEditField.Enable = 'off';
            app.UpperWishsInboardRZIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRZIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardRZIncrEditField.Enable = 'off';
            app.UpperWishsInboardRYIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRYIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardRYIncrEditField.Enable = 'off';
            app.UpperWishsInboardRXIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRXIncrEditField.Tag = 'Incr';
            app.UpperWishsInboardRXIncrEditField.Enable = 'off';
            app.FrontInboardXmLabel_3 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardXmLabel_3.Text = 'Front Inboard X';
            app.FrontInboardYmLabel_3 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardYmLabel_3.Text = 'Front Inboard Y';
            app.FrontInboardZLabel_5 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardZLabel_5.Text = 'Front Inboard Z';
            app.RearInboardZLabel_5 = uilabel(app.UpperWishboneHardpointsPanel);
            app.RearInboardZLabel_5.Text = 'Rear Inboard Z';
            app.RearInboardYLabel_5 = uilabel(app.UpperWishboneHardpointsPanel);
            app.RearInboardYLabel_5.Text = 'Rear Inboard Y';
            app.RearInboardXLabel_5 = uilabel(app.UpperWishboneHardpointsPanel);
            app.RearInboardXLabel_5.Text = 'Rear Inboard X';
            app.UpperWishsOutboardZIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardZIncrEditField.Tag = 'Incr';
            app.UpperWishsOutboardZIncrEditField.Enable = 'off';
            app.UpperWishsOutboardYIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardYIncrEditField.Tag = 'Incr';
            app.UpperWishsOutboardYIncrEditField.Enable = 'off';
            app.UpperWishsOutboardXIncrEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardXIncrEditField.Tag = 'Incr';
            app.UpperWishsOutboardXIncrEditField.Enable = 'off';
            app.UpperWishsInboardFXMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFXMaxEditField.Enable = 'off';
            app.UpperWishsInboardFYMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFYMaxEditField.Enable = 'off';
            app.UpperWishsInboardFZMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFZMaxEditField.Enable = 'off';
            app.UpperWishsInboardRZMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRZMaxEditField.Enable = 'off';
            app.UpperWishsInboardRYMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRYMaxEditField.Enable = 'off';
            app.UpperWishsInboardRXMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRXMaxEditField.Enable = 'off';
            app.UpperWishsOutboardZMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardZMaxEditField.Enable = 'off';
            app.UpperWishsOutboardYMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardYMaxEditField.Enable = 'off';
            app.UpperWishsOutboardXMaxEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardXMaxEditField.Enable = 'off';
            app.UpperWishsInboardFXMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFXMinEditField.Enable = 'off';
            app.UpperWishsInboardFYMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFYMinEditField.Enable = 'off';
            app.UpperWishsInboardFZMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFZMinEditField.Enable = 'off';
            app.UpperWishsInboardRZMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRZMinEditField.Enable = 'off';
            app.UpperWishsInboardRYMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRYMinEditField.Enable = 'off';
            app.UpperWishsInboardRXMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRXMinEditField.Enable = 'off';
            app.UpperWishsOutboardZMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardZMinEditField.Enable = 'off';
            app.UpperWishsOutboardYMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardYMinEditField.Enable = 'off';
            app.UpperWishsOutboardXMinEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardXMinEditField.Enable = 'off';
            app.UpperWishsInboardFXEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFXEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFXEditField.Editable = 'off';
            app.UpperWishsInboardFXEditField.Enable = 'off';
            app.UpperWishsInboardFYEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFYEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFYEditField.Editable = 'off';
            app.UpperWishsInboardFYEditField.Enable = 'off';
            app.UpperWishsInboardFZEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardFZEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardFZEditField.Editable = 'off';
            app.UpperWishsInboardFZEditField.Enable = 'off';
            app.UpperWishsInboardRZEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRZEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRZEditField.Editable = 'off';
            app.UpperWishsInboardRZEditField.Enable = 'off';
            app.UpperWishsInboardRYEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRYEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRYEditField.Editable = 'off';
            app.UpperWishsInboardRYEditField.Enable = 'off';
            app.UpperWishsInboardRXEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsInboardRXEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsInboardRXEditField.Editable = 'off';
            app.UpperWishsInboardRXEditField.Enable = 'off';
            app.FrontInboardZLabel_6 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardZLabel_6.Text = 'Outboard Z';
            app.UpperWishsOutboardZEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardZEditField.Editable = 'off';
            app.UpperWishsOutboardZEditField.Enable = 'off';
            app.FrontInboardYmLabel_4 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardYmLabel_4.Text = 'Outboard Y';
            app.UpperWishsOutboardYEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardYEditField.Editable = 'off';
            app.UpperWishsOutboardYEditField.Enable = 'off';
            app.FrontInboardXmLabel_4 = uilabel(app.UpperWishboneHardpointsPanel);
            app.FrontInboardXmLabel_4.Text = 'Outboard X';
            app.UpperWishsOutboardXEditField = uieditfield(app.UpperWishboneHardpointsPanel, 'numeric');
            app.UpperWishsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.UpperWishsOutboardXEditField.Editable = 'off';
            app.UpperWishsOutboardXEditField.Enable = 'off';
            app.LowerWishboneHardpointsPanel = uipanel(app.SimscapeSuspensionTesterUIFigure);
            app.LowerWishboneHardpointsPanel.Title = 'Lower Wishbone Hardpoints';
            app.LowerWishsOutboardZEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsOutboardZEnableCheckBoxValueChanged, true);
            app.LowerWishsOutboardZEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsOutboardZEnableCheckBox.Text = '';
            app.LowerWishsOutboardYEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsOutboardYEnableCheckBoxValueChanged, true);
            app.LowerWishsOutboardYEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsOutboardYEnableCheckBox.Text = '';
            app.LowerWishsOutboardXEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsOutboardXEnableCheckBoxValueChanged, true);
            app.LowerWishsOutboardXEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsOutboardXEnableCheckBox.Text = '';
            app.LowerWishsInboardFXEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardFXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardFXEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardFXEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardFXEnableCheckBox.Text = '';
            app.LowerWishsInboardFYEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardFYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardFYEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardFYEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardFYEnableCheckBox.Text = '';
            app.LowerWishsInboardFZEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardFZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardFZEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardFZEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardFZEnableCheckBox.Text = '';
            app.LowerWishsInboardRZEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardRZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardRZEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardRZEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardRZEnableCheckBox.Text = '';
            app.LowerWishsInboardRYEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardRYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardRYEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardRYEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardRYEnableCheckBox.Text = '';
            app.LowerWishsInboardRXEnableCheckBox = uicheckbox(app.LowerWishboneHardpointsPanel);
            app.LowerWishsInboardRXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerWishsInboardRXEnableCheckBoxValueChanged, true);
            app.LowerWishsInboardRXEnableCheckBox.Tag = 'WLAUse';
            app.LowerWishsInboardRXEnableCheckBox.Text = '';
            app.FrontInboardXmLabel_5 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardXmLabel_5.Text = 'Front Inboard X';
            app.FrontInboardYmLabel_5 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardYmLabel_5.Text = 'Front Inboard Y';
            app.FrontInboardZLabel_7 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardZLabel_7.Text = 'Front Inboard Z';
            app.RearInboardZLabel_6 = uilabel(app.LowerWishboneHardpointsPanel);
            app.RearInboardZLabel_6.Text = 'Rear Inboard Z';
            app.RearInboardYLabel_6 = uilabel(app.LowerWishboneHardpointsPanel);
            app.RearInboardYLabel_6.Text = 'Rear Inboard Y';
            app.RearInboardXLabel_6 = uilabel(app.LowerWishboneHardpointsPanel);
            app.RearInboardXLabel_6.Text = 'Rear Inboard X';
            app.LowerWishsOutboardZIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardZIncrEditField.Tag = 'Incr';
            app.LowerWishsOutboardZIncrEditField.Enable = 'off';
            app.LowerWishsOutboardYIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardYIncrEditField.Tag = 'Incr';
            app.LowerWishsOutboardYIncrEditField.Enable = 'off';
            app.LowerWishsOutboardXIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardXIncrEditField.Tag = 'Incr';
            app.LowerWishsOutboardXIncrEditField.Enable = 'off';
            app.LowerWishsInboardFXIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFXIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardFXIncrEditField.Enable = 'off';
            app.LowerWishsInboardFYIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFYIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardFYIncrEditField.Enable = 'off';
            app.LowerWishsInboardFZIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFZIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardFZIncrEditField.Enable = 'off';
            app.LowerWishsInboardRZIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRZIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRZIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardRZIncrEditField.Enable = 'off';

            app.LowerWishsInboardRYIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRYIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRYIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardRYIncrEditField.Enable = 'off';
            app.LowerWishsInboardRXIncrEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRXIncrEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRXIncrEditField.Tag = 'Incr';
            app.LowerWishsInboardRXIncrEditField.Enable = 'off';
            app.LowerWishsInboardFXMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFXMaxEditField.Enable = 'off';
            app.LowerWishsInboardFYMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFYMaxEditField.Enable = 'off';
            app.LowerWishsInboardFZMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFZMaxEditField.Enable = 'off';
            app.LowerWishsInboardRZMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRZMaxEditField.Enable = 'off';
            app.LowerWishsInboardRYMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRYMaxEditField.Enable = 'off';
            app.LowerWishsInboardRXMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRXMaxEditField.Enable = 'off';
            app.LowerWishsOutboardZMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardZMaxEditField.Enable = 'off';
            app.LowerWishsOutboardYMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardYMaxEditField.Enable = 'off';
            app.LowerWishsOutboardXMaxEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardXMaxEditField.Enable = 'off';
            app.LowerWishsOutboardZMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardZMinEditField.Enable = 'off';
            app.LowerWishsOutboardYMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardYMinEditField.Enable = 'off';
            app.LowerWishsOutboardXMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardXMinEditField.Enable = 'off';
            app.LowerWishsInboardFXMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFXMinEditField.Enable = 'off';
            app.LowerWishsInboardFYMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFYMinEditField.Enable = 'off';
            app.LowerWishsInboardFZMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFZMinEditField.Enable = 'off';
            app.LowerWishsInboardRZMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRZMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRZMinEditField.Enable = 'off';
            app.LowerWishsInboardRYMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRYMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRYMinEditField.Enable = 'off';
            app.LowerWishsInboardRXMinEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRXMinEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRXMinEditField.Enable = 'off';
            app.FrontInboardZLabel_8 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardZLabel_8.Text = 'Outboard Z';
            app.LowerWishsOutboardZEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardZEditField.Editable = 'off';
            app.LowerWishsOutboardZEditField.Enable = 'off';
            app.FrontInboardYmLabel_6 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardYmLabel_6.Text = 'Outboard Y';
            app.LowerWishsOutboardYEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardYEditField.Editable = 'off';
            app.LowerWishsOutboardYEditField.Enable = 'off';
            app.FrontInboardXmLabel_6 = uilabel(app.LowerWishboneHardpointsPanel);
            app.FrontInboardXmLabel_6.Text = 'Outboard X';
            app.LowerWishsOutboardXEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsOutboardXEditField.Editable = 'off';
            app.LowerWishsOutboardXEditField.Enable = 'off';
            app.LowerWishsInboardFXEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFXEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFXEditField.Editable = 'off';
            app.LowerWishsInboardFXEditField.Enable = 'off';
            app.LowerWishsInboardFYEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFYEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFYEditField.Editable = 'off';
            app.LowerWishsInboardFYEditField.Enable = 'off';
            app.LowerWishsInboardFZEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardFZEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardFZEditField.Editable = 'off';
            app.LowerWishsInboardFZEditField.Enable = 'off';
            app.LowerWishsInboardRZEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRZEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRZEditField.Editable = 'off';
            app.LowerWishsInboardRZEditField.Enable = 'off';
            app.LowerWishsInboardRYEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRYEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRYEditField.Editable = 'off';
            app.LowerWishsInboardRYEditField.Enable = 'off';
            app.LowerWishsInboardRXEditField = uieditfield(app.LowerWishboneHardpointsPanel, 'numeric');
            app.LowerWishsInboardRXEditField.ValueDisplayFormat = '%.4f';
            app.LowerWishsInboardRXEditField.Editable = 'off';
            app.LowerWishsInboardRXEditField.Enable = 'off';
            app.ReloadValuesButton = uibutton(app.SimscapeSuspensionTesterUIFigure, 'push');
            app.ReloadValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadValuesButtonPushed, true);
            app.ReloadValuesButton.Tooltip = {'Reload values from MATLAB workspace.'};
            app.ReloadValuesButton.Text = 'Reload Values';
            app.TrackRodHardpointsPanel = uipanel(app.SimscapeSuspensionTesterUIFigure);
            app.TrackRodHardpointsPanel.Title = 'Track Rod Hardpoints';
            app.TrackRodsOutboardZEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsOutboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsOutboardZEnableCheckBoxValueChanged, true);
            app.TrackRodsOutboardZEnableCheckBox.Text = '';
            app.TrackRodsOutboardZEnableCheckBox.Value = true;
            app.TrackRodsOutboardYEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsOutboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsOutboardYEnableCheckBoxValueChanged, true);
            app.TrackRodsOutboardYEnableCheckBox.Tag = 'WLAUse';
            app.TrackRodsOutboardYEnableCheckBox.Text = '';
            app.TrackRodsOutboardXEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsOutboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsOutboardXEnableCheckBoxValueChanged, true);
            app.TrackRodsOutboardXEnableCheckBox.Tag = 'WLAUse';
            app.TrackRodsOutboardXEnableCheckBox.Text = '';
            app.TrackRodsInboardXEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsInboardXEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsInboardXEnableCheckBoxValueChanged, true);
            app.TrackRodsInboardXEnableCheckBox.Tag = 'WLAUse';
            app.TrackRodsInboardXEnableCheckBox.Text = '';
            app.TrackRodsInboardYEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsInboardYEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsInboardYEnableCheckBoxValueChanged, true);
            app.TrackRodsInboardYEnableCheckBox.Tag = 'WLAUse';
            app.TrackRodsInboardYEnableCheckBox.Text = '';
            app.TrackRodsInboardZEnableCheckBox = uicheckbox(app.TrackRodHardpointsPanel);
            app.TrackRodsInboardZEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackRodsInboardZEnableCheckBoxValueChanged, true);
            app.TrackRodsInboardZEnableCheckBox.Text = '';
            app.TrackRodsInboardZEnableCheckBox.Value = true;
            app.TrackRodsInboardXIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardXIncrEditField.Tag = 'Incr';
            app.TrackRodsInboardXIncrEditField.Enable = 'off';
            app.TrackRodsInboardYIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardYIncrEditField.Tag = 'Incr';
            app.TrackRodsInboardYIncrEditField.Enable = 'off';
            app.TrackRodsInboardZIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardZIncrEditField.Tag = 'Incr';
            app.FrontInboardXmLabel_7 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardXmLabel_7.Text = 'Inboard X';
            app.FrontInboardYmLabel_7 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardYmLabel_7.Text = 'Inboard Y';
            app.FrontInboardZLabel_9 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardZLabel_9.Text = 'Inboard Z';
            app.TrackRodsOutboardZIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardZIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardZIncrEditField.Tag = 'Incr';
            app.TrackRodsOutboardYIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardYIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYIncrEditField.Tag = 'Incr';
            app.TrackRodsOutboardYIncrEditField.Enable = 'off';
            app.TrackRodsOutboardXIncrEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardXIncrEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardXIncrEditField.Tag = 'Incr';
            app.TrackRodsOutboardXIncrEditField.Enable = 'off';
            app.TrackRodsOutboardZMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYMaxEditField.Enable = 'off';
            app.TrackRodsOutboardXMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardXMaxEditField.Enable = 'off';
            app.TrackRodsInboardXMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardXMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardXMaxEditField.Enable = 'off';
            app.TrackRodsInboardYMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardYMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardYMaxEditField.Enable = 'off';
            app.TrackRodsInboardZMaxEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardZMaxEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardZMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYMinEditField.Enable = 'off';
            app.TrackRodsOutboardXMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardXMinEditField.Enable = 'off';
            app.TrackRodsInboardXMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardXMinEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardXMinEditField.Enable = 'off';
            app.TrackRodsInboardYMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardYMinEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardYMinEditField.Enable = 'off';
            app.TrackRodsInboardZMinEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardZMinEditField.ValueDisplayFormat = '%.4f';
            app.FrontInboardZLabel_10 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardZLabel_10.Text = 'Outboard Z';
            app.TrackRodsOutboardZEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardZEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardZEditField.Editable = 'off';
            app.FrontInboardYmLabel_8 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardYmLabel_8.Text = 'Outboard Y';
            app.TrackRodsOutboardYEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardYEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardYEditField.Editable = 'off';
            app.TrackRodsOutboardYEditField.Enable = 'off';
            app.FrontInboardXmLabel_8 = uilabel(app.TrackRodHardpointsPanel);
            app.FrontInboardXmLabel_8.Text = 'Outboard X';
            app.TrackRodsOutboardXEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsOutboardXEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsOutboardXEditField.Editable = 'off';
            app.TrackRodsOutboardXEditField.Enable = 'off';
            app.TrackRodsInboardXEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardXEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardXEditField.Editable = 'off';
            app.TrackRodsInboardXEditField.Enable = 'off';
            app.TrackRodsInboardYEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardYEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardYEditField.Editable = 'off';
            app.TrackRodsInboardYEditField.Enable = 'off';
            app.TrackRodsInboardZEditField = uieditfield(app.TrackRodHardpointsPanel, 'numeric');
            app.TrackRodsInboardZEditField.ValueDisplayFormat = '%.4f';
            app.TrackRodsInboardZEditField.Editable = 'off';
            app.SelectMainModelButton = uibutton(app.SimscapeSuspensionTesterUIFigure, 'push');
            app.SelectMainModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainModelButtonPushed, true);
            app.SelectMainModelButton.Tooltip = {'Select Simulink quarter car model.'};
            app.SelectMainModelButton.Text = 'Select';
            app.OpenMainModelButton = uibutton(app.SimscapeSuspensionTesterUIFigure, 'push');
            app.OpenMainModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMainModelButtonPushed, true);
            app.OpenMainModelButton.Tooltip = {'Open Simulink quarter car model.'};
            app.OpenMainModelButton.Text = 'Open';
            app.TargetValueEditFieldLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.TargetValueEditFieldLabel.Text = 'Target Value';
            app.TargetValueEditField = uieditfield(app.SimscapeSuspensionTesterUIFigure, 'numeric');
            app.TargetValueEditField.Enable = 'off';
            app.TargetValueEditField.Tooltip = {'Target value for metric in optimization.'};
            app.TargetValueUnitsLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.TargetValueUnitsLabel.Text = 'deg/m';
            app.SetAllUseCheckBox = uicheckbox(app.SimscapeSuspensionTesterUIFigure);
            app.SetAllUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetAllUseCheckBoxValueChanged, true);
            app.SetAllUseCheckBox.Tooltip = {'Set/clear all Use checkboxes.'};
            app.SetAllUseCheckBox.Text = 'Set All Use';
            app.JounceEditFieldLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.JounceEditFieldLabel.Text = 'Jounce';
            app.JounceEditField = uieditfield(app.SimscapeSuspensionTesterUIFigure, 'numeric');
            app.JounceEditField.Value = 0.1;
            app.ReboundEditFieldLabel = uilabel(app.SimscapeSuspensionTesterUIFigure);
            app.ReboundEditFieldLabel.Text = 'Rebound';
            app.ReboundEditField = uieditfield(app.SimscapeSuspensionTesterUIFigure, 'numeric');
            app.ReboundEditField.Value = -0.1;
            % Show the figure after all components are created
            app.SimscapeSuspensionTesterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testrig_quarter_car_mlapp_sweep_optim

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SimscapeSuspensionTesterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SimscapeSuspensionTesterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimscapeSuspensionTesterUIFigure)
        end
    end
end